classdef DeepEdge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Mn_File                         matlab.ui.container.Menu
        Mn_LoadModel                    matlab.ui.container.Menu
        Mn_LoadVideo                    matlab.ui.container.Menu
        Mn_LoadContours                 matlab.ui.container.Menu
        Mn_LoadTextGrid                 matlab.ui.container.Menu
        Mn_LoadVideoAndContours         matlab.ui.container.Menu
        Mn_LoadVideoAndTextGrid         matlab.ui.container.Menu
        Mn_LoadVideoAndContoursAndTextGrid  matlab.ui.container.Menu
        Mn_LoadAudio                    matlab.ui.container.Menu
        Mn_ReselectTextGridTier         matlab.ui.container.Menu
        Mn_ShowSelectFromFileList       matlab.ui.container.Menu
        Mn_ImportCropPara               matlab.ui.container.Menu
        LoadpalataltraceMenu            matlab.ui.container.Menu
        Mn_Export                       matlab.ui.container.Menu
        Mn_ExportCurrentFrame           matlab.ui.container.Menu
        Mn_ExpImgRaw                    matlab.ui.container.Menu
        Mn_ExpImgCrop                   matlab.ui.container.Menu
        Mn_Anchors2TSV                  matlab.ui.container.Menu
        Mn_Anchors2TSV_Ori              matlab.ui.container.Menu
        Mn_Anchors2TSV_Crop             matlab.ui.container.Menu
        Mn_SaveContours                 matlab.ui.container.Menu
        Mn_QuickSaveContours            matlab.ui.container.Menu
        Mn_ExportCropPara               matlab.ui.container.Menu
        Mn_ExportOrigin                 matlab.ui.container.Menu
        Mn_ExportMPP                    matlab.ui.container.Menu
        Mn_ExportCurrentAnchors         matlab.ui.container.Menu
        Mn_ExtractSelectedVideo_Uncropped  matlab.ui.container.Menu
        Mn_ExtractSelectedVideo_cropped  matlab.ui.container.Menu
        Mn_Command                      matlab.ui.container.Menu
        Mn_EnterCropRect                matlab.ui.container.Menu
        Mn_PreviousFrame                matlab.ui.container.Menu
        Mn_NextFrame                    matlab.ui.container.Menu
        Mn_ApplyCurrentFrame            matlab.ui.container.Menu
        Mn_Track                        matlab.ui.container.Menu
        Mn_StopTracking                 matlab.ui.container.Menu
        Mn_DeleteAllAnchorsCurrentFrame  matlab.ui.container.Menu
        Mn_TrackNextFrame               matlab.ui.container.Menu
        Mn_TrackNFrames                 matlab.ui.container.Menu
        Mn_ApplySnake                   matlab.ui.container.Menu
        Mn_DeleteAllAnchorsinAllFrames  matlab.ui.container.Menu
        Mn_FindMPP                      matlab.ui.container.Menu
        Mn_FitCircleFindOrigin          matlab.ui.container.Menu
        Mn_Audio                        matlab.ui.container.Menu
        Mn_AddAudioOffset               matlab.ui.container.Menu
        Mn_ViewAll                      matlab.ui.container.Menu
        Mn_ZoomIn                       matlab.ui.container.Menu
        Mn_ZoomOut                      matlab.ui.container.Menu
        Mn_ScrollPageBack               matlab.ui.container.Menu
        Mn_ScrollPageForward            matlab.ui.container.Menu
        Mn_ZoomToAudioCursor            matlab.ui.container.Menu
        Mn_ZoomToSelection              matlab.ui.container.Menu
        Mn_PlaySelectedAudio            matlab.ui.container.Menu
        Mn_Info                         matlab.ui.container.Menu
        Mn_VideoInfo                    matlab.ui.container.Menu
        Mn_ModelInfo                    matlab.ui.container.Menu
        Mn_ContourInfo                  matlab.ui.container.Menu
        Mn_EnterCode                    matlab.ui.container.Menu
        Mn_AboutDeepEdge                matlab.ui.container.Menu
        AdminMenu                       matlab.ui.container.Menu
        Mn_SetStatusReady               matlab.ui.container.Menu
        Mn_SetStatusBusy                matlab.ui.container.Menu
        Mn_AppVariables                 matlab.ui.container.Menu
        Bn_PlaySelected                 matlab.ui.control.Button
        ViewSelectAllAudioButton        matlab.ui.control.Button
        Msg1                            matlab.ui.control.TextArea
        Ed_CursorLoc                    matlab.ui.control.EditField
        Bn_NextFile                     matlab.ui.control.Button
        Pn_FrameControl                 matlab.ui.container.Panel
        Ed_GoToN                        matlab.ui.control.NumericEditField
        Bn_GoTo                         matlab.ui.control.Button
        Bn_LastFrame                    matlab.ui.control.Button
        Ed_NextN                        matlab.ui.control.NumericEditField
        Bn_NextNFrame                   matlab.ui.control.Button
        Bn_NextFrame                    matlab.ui.control.Button
        Bn_PreviousFrame                matlab.ui.control.Button
        Bn_PreviousNFrame               matlab.ui.control.Button
        Ed_PreviousN                    matlab.ui.control.NumericEditField
        Bn_FirstFrame                   matlab.ui.control.Button
        Bn_PreviousFile                 matlab.ui.control.Button
        Tabs                            matlab.ui.container.TabGroup
        Tab_Config                      matlab.ui.container.Tab
        Ck_FlipY                        matlab.ui.control.CheckBox
        BnG_EnhanceContrast             matlab.ui.container.ButtonGroup
        Bn_AdaptHistEq                  matlab.ui.control.RadioButton
        Bn_HistEq                       matlab.ui.control.RadioButton
        Bn_Adjust                       matlab.ui.control.RadioButton
        Bn_NoEnhance                    matlab.ui.control.RadioButton
        Txt_tracked_frames              matlab.ui.control.Label
        Ck_Autosave                     matlab.ui.control.CheckBox
        Ed_savingFreq                   matlab.ui.control.NumericEditField
        everyEditFieldLabel             matlab.ui.control.Label
        Pn_SnakeParas                   matlab.ui.container.Panel
        Bn_SnakeDefault                 matlab.ui.control.Button
        Ed_SnakeLambda1                 matlab.ui.control.NumericEditField
        Slid_SnakeLambda1               matlab.ui.control.Slider
        Lambda1Label                    matlab.ui.control.Label
        Ed_SnakeAlpha                   matlab.ui.control.NumericEditField
        Slid_SnakeAlpha                 matlab.ui.control.Slider
        Lambda1Label_2                  matlab.ui.control.Label
        Ed_SnakeBandPenalty             matlab.ui.control.NumericEditField
        BandPenaltyLabel                matlab.ui.control.Label
        Ed_SnakeDelta                   matlab.ui.control.NumericEditField
        DeltaLabel                      matlab.ui.control.Label
        Ed_SnakeSigma                   matlab.ui.control.NumericEditField
        SigmaLabel                      matlab.ui.control.Label
        Sw_ifInvert                     matlab.ui.control.Switch
        CropParaHelpMsg                 matlab.ui.control.EditField
        Ed_CropRect                     matlab.ui.control.EditField
        Bn_Uncrop                       matlab.ui.control.Button
        Bn_CropButton                   matlab.ui.control.StateButton
        AnchorTab                       matlab.ui.container.Tab
        Spn_PalLineWidth                matlab.ui.control.Spinner
        linewidthSpinnerLabel           matlab.ui.control.Label
        Spn_TextGridFontSize            matlab.ui.control.Spinner
        TextGridfontsizeLabel           matlab.ui.control.Label
        Slid_EraserWidth                matlab.ui.control.Slider
        EraserwidthLabel                matlab.ui.control.Label
        Bn_SelectColorPal               matlab.ui.control.Button
        Ck_ShowPal                      matlab.ui.control.CheckBox
        Spn_EMANameFontSize             matlab.ui.control.Spinner
        fontsizeLabel                   matlab.ui.control.Label
        Ck_ShowEMANAME                  matlab.ui.control.CheckBox
        Bn_SelectColorEMA               matlab.ui.control.Button
        Ck_ShowEMA                      matlab.ui.control.CheckBox
        Spn_AnchorOrderFontSize         matlab.ui.control.Spinner
        AnchororderfontsizeSpinnerLabel  matlab.ui.control.Label
        Ck_ShowAnchorOrder              matlab.ui.control.CheckBox
        UIAnchorTable                   matlab.ui.control.Table
        Sw_ContourVisible               matlab.ui.control.Switch
        ContourvisibleSwitchLabel       matlab.ui.control.Label
        Bn_DelAnchors                   matlab.ui.control.Button
        Slid_AnchorAlpha                matlab.ui.control.Slider
        OpacityLabel                    matlab.ui.control.Label
        Slid_AnchorSize                 matlab.ui.control.Slider
        AnchorsizeSliderLabel           matlab.ui.control.Label
        Sw_ClickAnchor                  matlab.ui.control.Switch
        ClicktoaddanchorsLabel          matlab.ui.control.Label
        DeepEdgeTab                     matlab.ui.container.Tab
        TrackingPanel                   matlab.ui.container.Panel
        Txt_atEdge                      matlab.ui.control.Label
        Ed_PlusMsAtEdge                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Ck_TrackOnlyNonEmptyTG          matlab.ui.control.CheckBox
        Ed_TrackingDelayMs              matlab.ui.control.NumericEditField
        DelaymsecLabel                  matlab.ui.control.Label
        Bn_StopTrack                    matlab.ui.control.StateButton
        Bn_TrackFiles                   matlab.ui.control.StateButton
        Bn_TrackFrames                  matlab.ui.control.StateButton
        Spn_NNProbThresh                matlab.ui.control.Spinner
        NNProbThreshSpinnerLabel        matlab.ui.control.Label
        Ck_ShowNNMask                   matlab.ui.control.CheckBox
        Bn_ApplyCurrentFrame            matlab.ui.control.Button
        DetectMethodGroup               matlab.ui.container.ButtonGroup
        Ck_None                         matlab.ui.control.RadioButton
        Ck_NNSnake                      matlab.ui.control.RadioButton
        Ck_Snake                        matlab.ui.control.RadioButton
        Ck_NN                           matlab.ui.control.RadioButton
        DiagnosticTab                   matlab.ui.container.Tab
        Ed_MedianLength                 matlab.ui.control.EditField
        SmoothnessLabel_3               matlab.ui.control.Label
        Ck_ShowDiagOnAudio              matlab.ui.control.CheckBox
        UIBadContourTable               matlab.ui.control.Table
        Pn_DiagnoseFilter               matlab.ui.container.Panel
        Ck_IFDistFilter                 matlab.ui.control.CheckBox
        Txt_IFDist                      matlab.ui.control.Label
        Ed_IFDistFilter                 matlab.ui.control.NumericEditField
        PercentLabel_3                  matlab.ui.control.Label
        Ed_LengthMinFilter              matlab.ui.control.NumericEditField
        Bn_Diagnose                     matlab.ui.control.Button
        PercentLabel_2                  matlab.ui.control.Label
        Ed_LengthMaxFilter              matlab.ui.control.NumericEditField
        PercentLabel                    matlab.ui.control.Label
        Txt_Length                      matlab.ui.control.Label
        Ck_LengthFilter                 matlab.ui.control.CheckBox
        Ed_RoughnessFilter              matlab.ui.control.NumericEditField
        Ck_RoughnessFilter              matlab.ui.control.CheckBox
        RoughnessLabel                  matlab.ui.control.Label
        Ed_EnergyQ3Filter               matlab.ui.control.NumericEditField
        Ck_EnergyQ3Filter               matlab.ui.control.CheckBox
        Ed_EnergyMaxFilter              matlab.ui.control.NumericEditField
        Ck_EnergyMaxFilter              matlab.ui.control.CheckBox
        Ed_EnergyMeanFilter             matlab.ui.control.NumericEditField
        Ck_EnergyMeanFilter             matlab.ui.control.CheckBox
        JointEnergyLabel                matlab.ui.control.Label
        Pn_SnakeEngergy                 matlab.ui.container.Panel
        Ed_MedianIFDist                 matlab.ui.control.EditField
        SmoothnessLabel_4               matlab.ui.control.Label
        Ed_IFDist                       matlab.ui.control.EditField
        SmoothnessLabel_5               matlab.ui.control.Label
        Ed_Length                       matlab.ui.control.EditField
        SmoothnessLabel_2               matlab.ui.control.Label
        Ed_Roughness                    matlab.ui.control.EditField
        SmoothnessLabel                 matlab.ui.control.Label
        Ed_SnakeEnergyQ3                matlab.ui.control.EditField
        Q3Label                         matlab.ui.control.Label
        Ed_SnakeEnergyMax               matlab.ui.control.EditField
        MaxEditFieldLabel               matlab.ui.control.Label
        Ed_SnakeEnergyMean              matlab.ui.control.EditField
        MeanEditFieldLabel              matlab.ui.control.Label
        JointenergyLabel                matlab.ui.control.Label
        Ed_TotalTime                    matlab.ui.control.NumericEditField
        Ed_Time                         matlab.ui.control.NumericEditField
        TimesLabel                      matlab.ui.control.Label
        Lp_Status                       matlab.ui.control.Lamp
        Ed_TotalFrames                  matlab.ui.control.EditField
        Ed_Frame                        matlab.ui.control.NumericEditField
        FrameEditFieldLabel             matlab.ui.control.Label
        Ed_FileInd                      matlab.ui.control.EditField
        Ed_CurrentFile                  matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        Ax1                             matlab.ui.control.UIAxes
        Ax0                             matlab.ui.control.UIAxes
    end

%% DeepEdge for Ultrasound: 
% Automatic ultrasound tongue contouring 
% combining a deep neural network and an edge detection algorithm
% 
% Copyright (C) 2020 Wei-Rong Chen <wei-rong.chen@yale.edu>
% This program is free software under GNU General Public License, version 3.
% This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. 
% See the GNU General Public License for more details.
% wei-rong chen
% Update: 
%  14DEC2023 v2.7.5  Added "Only track non-empty in TextGrid"; added "Play selected button"; added "Eraser width"
%  15DEC2023 v.2.7.6 Fixed bugs.    
%  17DEC2023 v.2.7.7 Added "Extract selection as new video/audio/mat/TextGrid (cropped)"
%  25DEC2023 v.2.7.8 Added support for *.MTS video format.
%  03JAN2024 v.2.7.9 
%  05FEB2024 v.2.8 Fixed audio selection bugs; Added Select all audio function. 
%  11FEB2024 v.2.8.1 Fixed crop selection bugs. 
%  09JUN2024 v.2.8.2 Fixed ReadTextGrid warning
%  12JUN2024 v.2.8.3 Updated playing audio with realtime EMA positions

    properties (Access = private)
        AppName = 'DeepEdge for Ultrasound';
        Version = '2.8.3'; Update = '12JUN2024';  % Update version
        minMatlabVer = '9.10'; % R2021a
        minMatlabVerName = 'R2021a'; 
        matFileVersion = '-v7';  
        appStartUpTime = [];  appPath = []; 
        vObj; % video object
        crop_rect = [1, 1, 0, 0];% cropping rectangle: [xmin ymin width height]
        ANCHORS = []; % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
        ENERGY = []; XY = []; TIME = []; METHOD = []; MPP = []; nnMASKS = []; ORIGIN = [];
        CONTOURS = []; 
        anchorTableArray = [];
        draw_xy = []; % hand-draw contour
        minDist = 3; % (pixel) min distance in Ax0
        palate = [];
        count_modified_frames = 0;
        Ax0_norm_width = 150; % normalized Ax0 width (denominator to determine minDist
        bufferMovieFrames = struct('cdata',[],'colormap',[]); % Buffer/preload of movie frames in a struct array
        %% Graphic handles:
        handle_rect = []; % handle for the seleted cropping rectangle
        CurrentImgObj = []; %current image object
        handle_xy = []; handle_anchors = []; handle_draw = []; 
        handle_mpp = []; handle_anchor_numbers = []; handle_ema = []; hg_ema_names = [];
        handle_palate = [];
        handle_AudioSelection =[]; handle_AudioCursor = []; hg_mask_boundary=[];
        hg_textgrid_labels= []; handle_diagnosed_frame_pointer = []; hg_anchor_numbers = []; handle_contoured_frame_pointer = [];
        %% Visual parameters:
        anchor_size = 45;  anchor_alpha= 0.4; nXYpoints = 100;
        contour_alpha = 0.7; contour_color = 'y'; contour_visible = 'on'; contour_style = ':'; contour_width = 1;
        anchor_color = [1,0,0]; % model-generated anchor color = red
        user_anchor_color = [0, 1, 1]; % user anchor color = rgb('Green');
        anchor_edge_color = 'k';
        ifInvert = false; 
        draw_color = 'c'; draw_alpha = 0.6; draw_width = 1; draw_style = '-'; draw_visible = 'On';
        rect_linewidth = 1; rect_linestyle = ':'; rect_marker = 'o'; rect_color = 'r'; 
        mpp_linewidth = 1; mpp_linestyle = ':'; mpp_marker = '_'; mpp_color = 'r'; 
        status_busy_color = 'r'; status_ready_color = 'g'; status_standby_color = 'k';
        ema_color = [1, 0, 0]; ema_alpha = 0.6; ema_width = 1; ema_marker = 'o'; pal_width = 2;
        ema_visible = 'on'; pal_visible = 'off'; palate_color = [0, 1, 1]; % cyan;
        MppLineAlpha = 0.5; 
        diagnosis_marker = '|'; diagnosis_color = 'r'; diagnosis_marker_size = 5;
        diagnosis_visible = 'on'; diagnosis_alpha = 0.6; 
        contouredFramedPointers_marker = '|'; 
        contouredFramedPointers_color = [.4, .4, .4]; %gray
        contouredFramedPointers_marker_size = 3;
        %% Saving variables:
        frame_list = []; modelname = []; net = []; modelPath = [];
        img = []; % original image of current frame
        height=[]; width=[]; duration=[]; % video height & width & duration
        FrameRate = []; nFrames = [];
        selectedAnchorID = []; mask = [];  defaultMsg = []; AUDIO=[]; 
        TextGrid =[]; TextGridTier =[]; TextGridFilename = [];  WhichTextGridTierSelected  = []; 
        PARAMS = []; % Collection of parameters
        minSnakeAnchors = 3;  AudPLAYER = []; FileList = []; filePath = []; 
        LoadMode = [] % app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
        PreviousClickAnchorValue = 'On';  CurrentFileNumber = [];
        TrackingDelayMs = 20; minTrackingDelayMs = 10; 
        flag_ifAnchorModifed = false;
        flag_FrameTrackingOn = false; flag_FileTrackingOn = false;
        snakeDefaultPara=[]; snakePara = [];
        audioOffset = 0; minAudioDisplayDur = 0.010; AudioCursorTime = [];
        minAudioPlayDur = 0.055; 
        minAudioSelectionDenominator = 200; minAudioSelectDur = [];
        audioSelectionTimeStamps = []; % [startTime, endTime] of selected audio
        audioSelectionFrameIndices = []; % [startFrame, endFrame] of selected audio
        diagnosticMatrix = []; % diagnostic feature values: [nFrame x k diagnosticVariables] (number)
        diagnosticCriteriaMetMatrix = []; % diagnostic criteria met: [nFrame x k diagnosticVariables] (logical)
        diagnosticVariables = [];   BadContourFrameIndex = [];
        defaultAudioResampleRate = 22000
        %% Flags:
        active = false; % set active to true when video is loaded.
        flag_GetMppOn = false;  
        flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
        flag_SelectRectMouseDown = false; % flag to indicate mouse button down for SelectCropRect
        flag_AudioSelectDown = false; % flag to indicate mouse button down for audio selection
        flag_mouseClicked = false; flag_LeftClickDown = false; 
        flag_RightClickDown = false;   flag_busy = false; Ax1ClickLog = [];
        %% FFMPEG:
        ffmpegZipFilename = 'ffmpeg.zip';  ffmpegFound = false;  ffmpegFullPathFilename = '';
        %% ADMIN: 
        keyPressBufferLength = 5; keyPressBuffer = '';
        AdminPassword = 'admin'; UserGroup = 'user'; 
    end
    methods (Access = private)
        %%
        function initialize(app)
            % Run at App Startup & Loading a video file:
            %
            % Output results:
            app.CONTOURS = [];
            app.anchorTableArray = NaN(1,2); 
            app.UIAnchorTable.Data = app.anchorTableArray;
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.flag_ifAnchorModifed = false;  % true if there is unsaved changes in the anchors. 
            app.diagnosticMatrix = []; app.BadContourFrameIndex = []; app.diagnosticCriteriaMetMatrix = []; 
            app.count_modified_frames = 0;
            % VIDEO:
            app.vObj = []; app.Ed_CurrentFile.Value = '';
            app.bufferMovieFrames = struct('cdata',[],'colormap',[]); % Buffer/preload of movie frames in a struct array
            % AUDIO:
            app.AUDIO = []; app.PARAMS.AudioResampleRate = app.defaultAudioResampleRate; 
            app.PARAMS.AudioAllColor = [0.3, 0.3, 0.3]; % Audio display color: rgb('gray')
            app.PARAMS.AudioCursorColor = [0.54, 0, 0]; % rgb('darkred')
            app.PARAMS.AudioCursorAlpha = 0.6; app.PARAMS.AudioCursorWidth = 1;
            app.PARAMS.AudioSelectColor = [1.0000    0.4102    0.7031]; % rgb('HotPink')
            app.PARAMS.AudioSelectAlpha = 0.5;
            app.PARAMS.AudioCursorMinWidth = 0.005; % (sec) Minimum display width for audio cursor.
            app.minAudioSelectDur = []; % (sec);
            app.TextGridTier = []; % TextGridTier.segs: timestamps;  TextGridTier.labs: labels
            app.TextGrid = []; app.TextGridFilename = [];
            app.PARAMS.TextGridLineColor = [0, 0.39, 0]; % rgb('darkgreen')
            app.PARAMS.TextGridFontSize = 9;
            app.PARAMS.TextGridLineAlpha = 0.6;
            app.PARAMS.TextGridLabelColor = [0, 0, 0.5];
            app.audioSelectionTimeStamps = [0, 0]; % [startTime, endTime] of selected audio
            app.audioSelectionFrameIndices = []; % [startFrame, endFrame] of selected audio
            % Deactivate: 
            app.active = false; % set active to true when video is loaded.
            app.flag_SelectCropRect = false; % flag to indicate screen select cropping rectangle.
            app.flag_SelectRectMouseDown = false; % flag to indicate mouse button down for SelectCropRect
            app.flag_AudioSelectDown = false; % flag to indicate mouse button down for audio selection
            rect = app.crop_rect; 
            app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
%             app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
            % Ax0: Main image axes
            app.Ax0.Visible = 'off'; cla(app.Ax0, 'reset'); 
            app.Ax0.Interactions = []; app.Ax0.Box = 'on'; app.Ax0.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Ax0)
            disableDefaultInteractivity(app.Ax1)
            %% UserData for frame changing buttons specify 'how many frame to move forward/backward'
            app.Bn_NextFrame.UserData = 1; app.Bn_PreviousFrame.UserData = -1; 
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
            app.Bn_FirstFrame.UserData = -inf; app.Bn_LastFrame.UserData = inf; 
            app.Ed_GoToN.Value = 1; 
            app.Mn_NextFrame.UserData = 1; app.Mn_PreviousFrame.UserData = -1;
            %% UserData for loading Previous/Next file:
            app.Bn_NextFile.UserData = 1; app.Bn_PreviousFile.UserData = -1;
            %% UserData for Menu Load video(s) LoadMode:
            app.Mn_LoadVideo.UserData = 'Video';
            app.Mn_LoadVideoAndContours.UserData = 'Video&Contour';
            app.Mn_LoadVideoAndTextGrid.UserData = 'Video&TextGrid';
            app.Mn_LoadVideoAndContoursAndTextGrid.UserData = 'Video&Contour&TextGrid';
            %% UserData for ADMIN: Set status: 
            app.Mn_SetStatusBusy.UserData = 'busy';
            app.Mn_SetStatusReady.UserData = 'ready';
            %% UserData for audio view:
            app.Mn_ZoomIn.UserData = 0.5;
            app.Mn_ZoomOut.UserData = 2;
            app.Mn_ScrollPageBack.UserData = - 0.5; 
            app.Mn_ScrollPageForward.UserData = 0.5;
            %% UserData for contour detect method:
             app.DetectMethodGroup.UserData = app.DetectMethodGroup.SelectedObject.Text;
             %% UserData for N-next frames to tracking
             app.Mn_Track.UserData = inf; app.Bn_TrackFrames.UserData = inf;
             app.Mn_TrackNextFrame.UserData = 1;
             app.Mn_TrackNFrames.UserData =  app.Ed_NextN.Value;
             app.Mn_TrackNFrames.Text = sprintf('Track next %d frames', app.Ed_NextN.Value);
            %% UserData for silent mode for Export/Quick Save Contours to MAT file
            app.Mn_SaveContours.UserData.Silent = false;
            app.Mn_QuickSaveContours.UserData.Silent = true;
            %% UserData for Export selected video:
            app.Mn_ExtractSelectedVideo_Uncropped.UserData.Crop = false;
            app.Mn_ExtractSelectedVideo_cropped.UserData.Crop = true;
            %%
            % app.Sw_ClickAnchor.Value = 'On';
            app.img = []; % original image of current frame
            app.anchor_size = app.Slid_AnchorSize.Value;
            app.anchor_alpha = app.Slid_AnchorAlpha.Value/100; %Slider range:0:100
            app.nXYpoints = 100;
            app.ifInvert = false;
            app.selectedAnchorID = []; app.mask = [];  app.AudPLAYER = [];
            app.handle_draw = []; app.draw_xy = []; 
            %% 
            app.Mn_ExpImgRaw.UserData.Crop=false; app.Mn_ExpImgCrop.UserData.Crop=true;
            app.Mn_Anchors2TSV_Ori.UserData.Crop = false; app.Mn_Anchors2TSV_Crop.UserData.Crop = true;
            % Graphic handles: 
            app.CurrentImgObj = []; %current image object
            app.handle_rect = []; % handle for the seleted cropping rectangle 
            app.hg_mask_boundary=[];% handle group of two animatedlines
            app.hg_textgrid_labels = []; % handle group of TextGrid labels
            app.hg_anchor_numbers = []; % handle group of anchor order numbers
            app.handle_AudioSelection = []; app.handle_AudioCursor = [];
            app.handle_diagnosed_frame_pointer =[];
            app.handle_contoured_frame_pointer =[];
            app.defaultMsg = '';
            cla(app.Ax1); % Clear axis
            %% Reset diagnosis:
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
                app.Ed_Roughness.Value ='';   app.Ed_Length.Value = ''; app.Ed_IFDist.Value = '';
                app.Ed_MedianIFDist.Value = ''; app.Ed_MedianLength.Value = '';
                app.UIBadContourTable.Data = [];
            %% Set tooltips:
            Ed_savingFreqValueChanged(app);
            %%
            SetStatus(app, 'standby');
        end % function initialize
        %%
        function SetStatus(app, status)

            %% Disable the following functions/buttons when busy (enable them when ready):
            funcsToBeDisabledIfBusy = ...
                {app.Bn_NextFile; app.Pn_FrameControl; app.Bn_PreviousFile; ...
                app.Ck_FlipY; app.Bn_ApplyCurrentFrame; ...
                app.Bn_TrackFrames; app.Bn_TrackFiles; ...
                app.DetectMethodGroup; ...
                app.Bn_CropButton; app.Bn_Uncrop; ...
                app.Bn_Diagnose; ...
                app.Mn_File; app.Mn_Export; app.Mn_Command; app.Mn_Audio; app.Mn_Info; ...
                app.Mn_LoadTextGrid; app.Mn_LoadContours; app.Mn_ShowSelectFromFileList; app.Mn_LoadAudio; app.Mn_ReselectTextGridTier};

             funcsToBeDisabledIfNotActive = ...
                {app.Bn_NextFile; app.Pn_FrameControl; app.Bn_PreviousFile; ...
                app.Ck_FlipY; app.Bn_ApplyCurrentFrame; ...
                app.Bn_TrackFrames; app.Bn_TrackFiles; ...
                app.DetectMethodGroup; ...
                app.Bn_CropButton; app.Bn_Uncrop; ...
                app.Bn_Diagnose; ...
                app.Mn_Export; app.Mn_Command; app.Mn_Audio; ...
                app.Mn_LoadTextGrid; app.Mn_LoadContours; app.Mn_ShowSelectFromFileList; app.Mn_LoadAudio; app.Mn_ReselectTextGridTier};           
            %%
            if ~app.active, status = 'standby'; end
            switch status
                case 'busy'
                   app.Lp_Status.Color = app.status_busy_color; 
                    app.flag_busy = true;
                    for i = 1:numel(funcsToBeDisabledIfBusy)
                        funcsToBeDisabledIfBusy{i}.Enable = 'off';
                    end                   
                case 'ready'
                    app.Lp_Status.Color = app.status_ready_color; 
                    app.Bn_TrackFrames.Value = false;  app.Bn_TrackFiles.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
                    app.Bn_StopTrack.Value = false;
                    app.flag_busy = false;
                    for i = 1:numel(funcsToBeDisabledIfBusy)
                        funcsToBeDisabledIfBusy{i}.Enable = 'on';
                    end                      
                case 'standby'
                    app.Lp_Status.Color = app.status_standby_color; 
                    app.flag_busy = false; 
                    for i = 1:numel(funcsToBeDisabledIfNotActive)
                        funcsToBeDisabledIfNotActive{i}.Enable = 'off';
                    end  
            end
        end %SetStatus
        function [fl, selectionSuccess, vpath] = SelectVideoFileUI(app)
            % If selected 1 video file, the full path will be returned as
            % FName. If more than 1 video files are selected, FName is the
            % first video file in the selected file list.   'fl' : file list
            % Display uigetfile dialog
            %% Deactivate: 
%             app.active = false; 
            filterspec = {'*.avi;*.mp4;*.wmv;*.mov;*.m4v;*.mkv;*.ult;*.mts','All Video Files'};
            [fl, vpath] = uigetfile(filterspec, 'multiselect', 'on');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fl,0),  selectionSuccess = false;  fl=[]; vpath = []; return; end 
            selectionSuccess = true; 
            app.WhichTextGridTierSelected = [];            
            if ~iscell(fl), fl = {fl}; end % only 1 file selected
            if isrow(fl), fl = fl'; end
        end % SelectVideoFileUI(app)
        %% 
        function success = LoadOneFileFromFilelist(app, whichFile)
            % Load "whichFile" in the file list. 
            %  "whichFile" can be either numeric or a string of filename.
%        app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
            fl = {app.FileList.Filename}; 
            if isnumeric(whichFile)
                ind = whichFile;
            elseif ischar(whichFile)
                ind = find(ismember(fl, whichFile));
            else
                return
            end
            if ind < 0 || ind > numel(fl), return; end  
            app.CurrentFileNumber = ind; fn = fl{ind};  vPath = app.FileList(ind).Path; contourStruct = app.FileList(ind).CONTOURS;
            app.Ed_FileInd.Value = sprintf('%d/%d', ind, numel(fl));
            FName = fullfile(vPath, fn);     
            %  Load video:
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading video file','Indeterminate','on');
            initialize(app); 
            app.Ed_CurrentFile.Value = fn;
            success = LoadVideoActivateApp(app, FName);  
            if ~success, close(UIprogressDialog_handle); return; end
            dispFrame(app, 1);  updatePlotAnchors(app); 
            close(UIprogressDialog_handle)
            [p, f, e] = fileparts(FName);  matFName = fullfile(p, [f '.mat']);    tgFName = fullfile(p, [f '.TextGrid']); 
            
            switch app.LoadMode
                case 'Video&Contour'
                     % Load contours:
                     if isempty(contourStruct)
                         if exist(matFName, 'file'), LoadContourStruct(app, matFName); end  
                     else
                         LoadContourStruct(app, contourStruct);
                     end
                case 'Video&TextGrid'
                    % Load TextGrid:
                    if exist(tgFName, 'file') && ~isempty(app.AUDIO)
                        tgSuccess = loadTextGrid(app, tgFName); 
                        if tgSuccess, drawTextGridIntervals(app); CopyTextGridLabelsToContour(app);end
                    end
                case 'Video&Contour&TextGrid'
                     % Load contours:
                     if isempty(contourStruct)
                         if exist(matFName, 'file'), LoadContourStruct(app, matFName); end  
                     else
                         LoadContourStruct(app, contourStruct);
                     end 
                    % Load TextGrid:
                    if exist(tgFName, 'file') && ~isempty(app.AUDIO)
                        tgSuccess = loadTextGrid(app, tgFName); 
                        if tgSuccess, drawTextGridIntervals(app); CopyTextGridLabelsToContour(app); end
                    end
            end % switch app.LoadMode
%             SetStatus(app, 'ready');
        end
        %%
        function success = LoadVideoActivateApp(app,videoFName)
           %UIFigure.Pointer is supported after R2020a (ver9.8)
            set(app.UIFigure, 'pointer','watch');
            cla(app.Ax1); % Clear axis
            success = Read1VideoFile(app, videoFName);
            if ~success, return; end
            
            if strcmpi(app.vObj.VideoFormat, 'ULT')
                % app.vObj.Param.TimeInSecsOfFirstFrame;  app.vObj.wavFName
                if ~isempty(app.vObj.wavFName)
                     successfullyReadAudio = Read1AudioFile(app, app.vObj.wavFName); 
                else
                    successfullyReadAudio = false;
                end
            else
                successfullyReadAudio = Read1AudioFile(app, videoFName); 
            end
            if ~successfullyReadAudio, GenDummyAudio(app); end
            InitializeVarsForOneFile(app);
             app.active = true; % Activate the App
        end %LoadVideoActivateApp(app,FName)
        %% 
        function InitializeVarsForOneFile(app)
            % Reset/initialize variables for one video file.
            axis(app.Ax0, 'image');
            app.Ed_CurrentFile.Value = app.vObj.Name;
            app.nFrames = app.vObj.NumFrames; app.Ed_TotalFrames.Value = ['/ ' num2str(app.nFrames)]; 
            app.Ed_TotalTime.Value = app.vObj.Duration; 
            timeCells = cell(app.nFrames,1);
            for iFrame = 1: app.nFrames
                timeCells{iFrame} = (iFrame-1)/app.vObj.FrameRate; 
            end
            app.frame_list = 1:app.nFrames; 
            anchorCells = cell(app.nFrames,1);  xyCells = cell(app.nFrames,1); 
            energyCells = cell(app.nFrames,1); methodCells = cell(app.nFrames,1);         
            nnMaskCells = cell(app.nFrames,1); noteCells = cell(app.nFrames,1); 
            FRAME = num2cell(app.frame_list'); %#ok<*ADPROPLC> 
            TRKRES = cellfun(@(x, y) struct('ENERGY', x, 'NPARTICLES', [], 'METHOD', y, 'NNMASKBoundaries', [], 'NNMASKArea', []), energyCells, methodCells, 'uniformoutput', false);
             % ANCHORS{iFrame}: n x 3 {x, y, isUserInput}
            app.CONTOURS = struct('XY', xyCells, 'ANCHORS', anchorCells, 'FRAME', FRAME, 'NOTE', noteCells, ...
                'TRKRES',TRKRES, 'TIME', timeCells, 'IMAGE', [], 'nnMASKS', nnMaskCells); 
            %%
            %  IF:
            %    1. loading video the first time
            %    2. the newly loaded video has different height or width from the previous video.
            %  THEN: 
            %     reset cropping parameters and XLim, YLim
            %  ELSE:
            %     retain the previous cropping paramters and XLim, YLim
            if isempty(app.crop_rect) || isempty(app.height) || isempty(app.width) || app.height ~= app.vObj.Height || app.width ~= app.vObj.Width
                 app.crop_rect = [1, 1, app.vObj.Width, app.vObj.Height]; 
            elseif app.crop_rect(3) ==0 || app.crop_rect(4) == 0
                app.crop_rect = [app.crop_rect(1:2), app.vObj.Width, app.vObj.Height]; 
            end
            rect = app.crop_rect;
            app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1]; 
            UpdateMinDist(app);
            updateStoredCropRect(app, app.crop_rect);
            app.height = app.vObj.Height; app.width = app.vObj.Width; app.duration = app.vObj.Duration;
            app.TextGridTier = []; % TextGridTier.segs: timestamps;  TextGridTier.labs: labels
            app.TextGrid = []; app.TextGridFilename = []; 
            if ~numel(app.FileList) > 1
                app.WhichTextGridTierSelected = []; 
            end
        end %InitializeVarsForOneFile
        %%
        function UpdateMinDist(app)
            w = abs(app.Ax0.XLim(2) - app.Ax0.XLim(1)); h = abs(app.Ax0.YLim(2) - app.Ax0.YLim(1));
            minD = (max([w,h])) / app.Ax0_norm_width;
            if minD < 1, minD = 1; end
            app.minDist = minD;
        end % UpdateMinDist
        %%
        function success = Read1VideoFile(app, videoFName)
            [~, fn, vExt] = fileparts(videoFName);
            try  
                  if strcmpi(vExt, '.ult')
                      [app.vObj, success] = app.ReadUlt(videoFName); 
        % video.vData : [height, width, nFrame] matrix of video frames.
        % video.NumFrames; video.Duration (sec); video.FrameRate; video.PixelsPerMm; video.Height; video.Width;  video.Origin;
         % video.Param.TimeInSecsOfFirstFrame; video.wavFName
                     app.MPP = 1/app.vObj.PixelsPerMm; app.ORIGIN = app.vObj.Origin; 
                     app.audioOffset = -1 * app.vObj.Param.TimeInSecsOfFirstFrame;
                      if ~success, return;end 
                  else                   
                      app.vObj = VideoReader(videoFName); 
                      app.audioOffset = 0;
                  end                    
                    app.Msg1.Value = ''; 
                % Try reading 1st frame
                    [im, success] = app.Read1Frame(app.vObj, 1);
                    if ~success
                        app.Msg1.Value = ['** Can''t read video frame! Could be ...' ...
                            'due to unsupported pixel format (e.g., yuv444p).']; 
                    end
                    set(app.UIFigure, 'pointer','arrow'); 
            catch 
                app.Msg1.Value = 'Unsupported video file!';  success = false;
                set(app.UIFigure, 'pointer','arrow'); 
            end
        end % success = Read1VideoFile(app, videoFName)

        %% 
        function GenDummyAudio(app)
            sr = 12000; factor = 0.1;
            Dur = app.vObj.Duration;
            if Dur < app.minAudioPlayDur, return;end
            nSamples = round(Dur * sr) - 1;
            s = rand(nSamples,1) .* factor - factor/2;
            InitiatePlotAudio(app, s, sr, Dur, '');
            app.Msg1.Value = 'No audio found. Random noises generated as dummy audio.';
        end
        function InitiatePlotAudio(app, s, sr, Dur, FName)
                x = linspace(0, Dur, size(s,1)); 
                h=plot(app.Ax1, x, s, 'color', app.PARAMS.AudioAllColor,'HitTest','off'); %#ok<NASGU> 
                set(app.Ax1, 'XLim', [0, app.vObj.Duration]);  
                app.Ax1.YLim = [-1,1];
                app.AUDIO.s = s; app.AUDIO.sr = sr; app.AUDIO.Dur = Dur;
                app.AUDIO.FileName = FName;
                plotUpdateAudioCursor(app, 0); 
                app.PARAMS.AudioCursorMinWidth = Dur/1000;      
                app.minAudioSelectDur  = Dur / app.minAudioSelectionDenominator; 
        end 
        %%
        function success = Read1AudioFile(app, FName)
            % AudOffset: adding offset to audio 
            % if AudOffset > 0, add zeros at the begining of the audio. 
            % if AudOffset < 0, chop off audio at the begining. 
            AudOffset = app.audioOffset; 
            try
                tic;
                [s, sr, aDur] = app.ImportAudio_simple(FName, app.PARAMS.AudioResampleRate);
                elapsedTime = toc;
                fprintf('Read 1 audio file. Took %04f sec(s). \n', elapsedTime);
                if AudOffset > 0
                    delayN = floor(AudOffset * sr) +1; aDur = aDur + AudOffset;
                    padding = zeros(delayN , 1);  
                    s = [padding; s];
                    app.Msg1.Value = sprintf('Audio shifted to the right (zero-padding) by %0.1f ms.', AudOffset*1000);
                elseif AudOffset < 0
                    delayN = floor(-AudOffset * sr) +1; 
                    if aDur > AudOffset && (size(s,1) - delayN) > 2
                        aDur = aDur + AudOffset; s = s(delayN:end);
                        app.Msg1.Value = sprintf('Audio chopped off by %0.1f ms.', -AudOffset*1000);
                    else
                        app.Msg1.Value = sprintf('Audio trimming (%0.1f ms) is larger than total duration.', -AudOffset*1000);
                    end
                else 
                    app.Msg1.Value = app.defaultMsg;
                end % AudOffset
                %% Adapt video duration:
                vDur = app.vObj.Duration; durDiff = vDur - aDur; aDur = aDur + durDiff; diffN = floor(abs(durDiff) * sr) +1; 
                if durDiff > 0, s = [s; zeros(diffN, 1)];  elseif durDiff < 0, s = s(1:end - diffN +1);   end 
                %%
                InitiatePlotAudio(app, s, sr, aDur, FName);
                success = true;
            catch 
                app.AUDIO = [];  success = false;
               
            end
        end 
        %%
        function success = dispFrame(app, iFrame)
            if ~app.active, return;end
            if iFrame < 1 || iFrame > app.nFrames, return;end
            currentFrame = app.Ed_Frame.Value;  currentTime = app.Ed_Time.Value;
            try 
                im= app.Read1Frame(app.vObj, iFrame);     
            catch
                success = false;
                app.Ed_Frame.Value = currentFrame; app.Ed_Time.Value= currentTime; 
                return;
            end
            %% Go to frame:
            app.Ed_Frame.Value = iFrame;    frameTime = (iFrame-1)/app.vObj.FrameRate;
            app.Ed_Time.Value = frameTime;%
            
            im = enhanceImage(app, im);
            app.img = im;        
            if app.ifInvert
                if size(im, 3) > 1, im = rgb2gray(im);   end
                colormap(app.Ax0, 'gray');
                im = imcomplement(im);
            end
%             if size(im,3) ==1, colormap(app.Ax0, 'gray');end
            if isempty(app.CurrentImgObj) || ~isgraphics(app.CurrentImgObj)
                app.CurrentImgObj = imagesc(app.Ax0, im); 
                % colormap(app.Ax0, 'gray');
                app.Ax0.Toolbar.Visible = 'off'; % turn off again because 'imagesc' turns on Ax0.Toolbar
                rect = app.crop_rect;
                app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1]; 
                if app.Ck_FlipY.Value, app.Ax0.YDir = 'normal'; else, app.Ax0.YDir = 'reverse';  end
            else
                app.CurrentImgObj.CData = im; 
            end
            if size(im,3) ==1, colormap(app.Ax0, 'gray');end
            %% MUST execute drawnow:
            drawnow % without drawnow, "Stop Tracking" button may be delayed.
            %%
            success = true;
        end % dispFrame(app, frame)
        %%
        function app = plotUpdateAudioCursor(app, x1, x2)
%             if ~app.active, return; end
            % x1 : first audio time-stamp at the mouse click
            % x2 : last point of audio selection
            if nargin < 3
                x2 = x1; 
                % app.audioSelectionFrameIndices = time2frame(app, x1);
            end
            app.audioSelectionTimeStamps = [x1, x2];
            xlm = app.Ax1.XLim;displayedAudioDur = xlm(2) - xlm(1);
            d = abs(x2 - x1); 
            if d < app.minAudioDisplayDur, d = displayedAudioDur/1000; end
            left = min([x1, x2]); right = left + d;
            app.AudioCursorTime = left;
            % xy: [top-left, bottom-left, bottom-right, top-right];
            top = 1; bottom = -1;
            x = [left, right, right, left];
            y = [top, top, bottom, bottom];
            c = app.PARAMS.AudioSelectColor; alpha = app.PARAMS.AudioSelectAlpha;
            hold(app.Ax1,'on');
            if isempty(app.handle_AudioSelection) || ~isgraphics(app.handle_AudioSelection)
                app.handle_AudioSelection = patch(app.Ax1, x, y, c, ...
                        'EdgeColor', 'none', 'FaceColor',  c, 'FaceAlpha', alpha);
            else
                app.handle_AudioSelection.XData = x; 
            end
            c = app.PARAMS.AudioCursorColor; alpha = app.PARAMS.AudioCursorAlpha;
            lw = app.PARAMS.AudioCursorWidth; 
            if isempty(app.handle_AudioCursor) || ~isgraphics(app.handle_AudioCursor)
                app.handle_AudioCursor = plot(app.Ax1, [left, left], [top, bottom], ...
                        'color',  c, 'LineWidth', lw);
                app.handle_AudioCursor.Color(4) = alpha;
            else
                app.handle_AudioCursor.XData = [left, left];  
            end
            app.Ax1.YLim = [-1,1];
            hold(app.Ax1,'off');
        end
        %%
        function app = updateStoredCropRect(app, rect)
            app.crop_rect = rect; app.Ed_CropRect.Value = sprintf('%.0f, %.0f, %.0f, %.0f', rect(1), rect(2), rect(3), rect(4));
        end %function app = updateStoredCropRect(app, rect)
        %%
        function app = updatePlotEMA(app)
            if ~app.active, return; end
            if ~isempty(app.hg_ema_names) && isgraphics(app.hg_ema_names)
                delete(app.hg_ema_names);
            end
            iFrame = app.Ed_Frame.Value; 
            if ~isfield(app.CONTOURS(iFrame), 'EMA') || isempty(app.CONTOURS(iFrame).EMA)
                if isgraphics(app.handle_ema), delete(app.handle_ema); end
                % return;
            end
            hold(app.Ax0,'on');
        %% Plot EMA:
            if isfield(app.CONTOURS(iFrame),'EMA') && ~isempty(app.CONTOURS(iFrame).EMA)
                ema = app.CONTOURS(iFrame).EMA; 
                if app.Ck_ShowEMA.Value, app.ema_visible = 'on'; else, app.ema_visible = 'off'; end
                if isempty(app.handle_ema) || ~isgraphics(app.handle_ema)
                    app.handle_ema = plot(app.Ax0, ema(:,1), ema(:,2), app.ema_marker, 'color', app.ema_color, ...
                        'visible', app.ema_visible, 'LineWidth', app.ema_width);
                    app.handle_ema.Color(4) = app.ema_alpha;
                else
    % %         % Update existing EMA:
                    app.handle_ema.Visible = app.ema_visible;
                    app.handle_ema.XData = ema(:,1); app.handle_ema.YData = ema(:,2);
                    app.handle_ema.Color = app.ema_color; 
                    app.handle_ema.Color(4) = app.ema_alpha;  
                end                
                %% Plot EMA sensor names:
                if isfield(app.CONTOURS(iFrame), 'EMANAMES') && ~isempty(app.CONTOURS(iFrame).EMANAMES)
                    emaFontSize = app.Spn_EMANameFontSize.Value;
                    emanames = app.CONTOURS(iFrame).EMANAMES; 
                    app.hg_ema_names = hggroup(app.Ax0);
                    text(app.Ax0, ema(:,1), ema(:,2), emanames, 'FontSize', emaFontSize,...
                                'HorizontalAlignment','center', 'VerticalAlignment','top', ...
                                'color', app.ema_color, 'clipping', 'on', 'parent', app.hg_ema_names);
                    if app.Ck_ShowEMANAME.Value
                        app.hg_ema_names.Visible = 'on';
                    else 
                        app.hg_ema_names.Visible = 'off';
                    end
                end
            end
            %% Plot Palatal trace:
            if ~isempty(app.palate)
                pal = app.palate; 
                if app.Ck_ShowPal.Value, app.pal_visible = 'on'; else, app.pal_visible = 'off'; end
                if isempty(app.handle_palate) || ~isgraphics(app.handle_palate)
                    app.handle_palate = plot(app.Ax0, pal(:,1), pal(:,2), '-', 'color', app.palate_color, ...
                        'visible', app.pal_visible, 'LineWidth', app.pal_width);
                    app.handle_palate.Color(4) = app.ema_alpha;
                else
    % %         % Update existing Palate:
                    app.handle_palate.Visible = app.pal_visible;
                    app.handle_palate.XData = pal(:,1); app.handle_palate.YData = pal(:,2);
                    app.handle_palate.Color = app.palate_color; 
                    app.handle_palate.Color(4) = app.ema_alpha;  
                    app.handle_palate.LineWidth = app.pal_width;
                end 
            else
                if ~isempty(app.handle_palate) && isgraphics(app.handle_palate)
                delete(app.handle_palate);
                end
            end 
            %%
            drawnow 
            hold(app.Ax0,'off');
        end %updatePlotEMA
        %%
        function app = updatePlotAnchors(app, anchors, method)
            if ~app.active, return; end
            if ~isempty(app.hg_mask_boundary) && isgraphics(app.hg_mask_boundary)
                delete(app.hg_mask_boundary);
            end
            updatePlotEMA(app); 
            iFrame = app.Ed_Frame.Value; 
            %% Update stored anchors and contours
            if nargin > 2
                if size(anchors,1) > 1
                     xy = app.interpLine(anchors, app.nXYpoints,'makima');
                elseif  size(anchors,1) == 1
                    xy = anchors(:,1:2);
                else
                    xy = [];
                end
                 app.CONTOURS(iFrame).ANCHORS = anchors; app.CONTOURS(iFrame).XY =xy;                
            end 
            if nargin > 2, app.CONTOURS(iFrame).TRKRES.METHOD = method; end
            if isempty(app.CONTOURS(iFrame).ANCHORS)
                if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
                if isgraphics(app.hg_anchor_numbers), delete(app.hg_anchor_numbers);end
                if isgraphics(app.handle_xy), delete(app.handle_xy);end
                %% Update anchor table:
                app.anchorTableArray = NaN(1,2);
                app.UIAnchorTable.Data = app.anchorTableArray;
                %% Update diagnostic panel:
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
                app.Ed_Roughness.Value =''; app.Ed_Length.Value = '';  app.Ed_IFDist.Value = '';
                return;
            end
            anchors = app.CONTOURS(iFrame).ANCHORS; xy = app.CONTOURS(iFrame).XY;
            x = anchors(:,1); y = anchors(:,2); isUser = anchors(:,3); nAnchors = size(anchors,1);
            anchorNumCells = cellstr(num2str((1:nAnchors)'));
            sz = app.anchor_size;  anchorNumSz  = app.Spn_AnchorOrderFontSize.Value;
            ancColor =  isUser*app.user_anchor_color + ~isUser*app.anchor_color;
            hold(app.Ax0,'on');

            if isempty(app.handle_anchors) || ~isgraphics(app.handle_anchors)
               % First time plotting anchor points:
                app.handle_anchors = scatter(app.Ax0,x,y, sz, ancColor, ...
                    'filled', 'o', 'MarkerFaceAlpha', app.anchor_alpha,'Visible', 'on', ...
                    'MarkerEdgeColor',app.anchor_edge_color, 'MarkerEdgeAlpha', app.anchor_alpha);
            else
% %               % Update existing plotted anchors:
                app.handle_anchors.XData = x; app.handle_anchors.YData = y; 
                app.handle_anchors.SizeData = app.anchor_size;
                app.handle_anchors.CData = ancColor;
                app.handle_anchors.MarkerFaceAlpha = app.anchor_alpha;
                app.handle_anchors.MarkerEdgeAlpha = app.anchor_alpha;
            end
%% Update plotted anchor order numbers:
            if ~isempty(app.hg_anchor_numbers) && isgraphics(app.hg_anchor_numbers)
                delete(app.hg_anchor_numbers);
            end
            app.hg_anchor_numbers = hggroup(app.Ax0);
            text(app.Ax0, x, y, anchorNumCells, 'FontSize', anchorNumSz,...
                        'HorizontalAlignment','center', 'VerticalAlignment','middle', 'clipping', 'on', 'parent', app.hg_anchor_numbers);
            if app.Ck_ShowAnchorOrder.Value
                app.hg_anchor_numbers.Visible = 'on';
            else 
                app.hg_anchor_numbers.Visible = 'off';
            end
%%  Update plotted anchor-connecting lines:
            if isempty(app.handle_xy) || ~isgraphics(app.handle_xy)
                % First time plotting XY connecting line:
%                 contour_alpha = 0.7; contour_color = 'y'; contour_visible = 'on'; contour_style = ':'; contour_width = 1;
                app.handle_xy = plot(app.Ax0, xy(:,1), xy(:,2), 'color', app.contour_color, ...
                    'visible', app.contour_visible, 'LineStyle', app.contour_style, 'LineWidth', app.contour_width);
                app.handle_xy.Color(4) = app.contour_alpha;
            else
% %         % Update existing XY connecting line:
                app.handle_xy.Visible = app.contour_visible;
                app.handle_xy.XData = xy(:,1); app.handle_xy.YData = xy(:,2);
                app.handle_xy.Color = app.contour_color; app.handle_xy.LineStyle = app.contour_style; 
                app.handle_xy.Color(4) = app.contour_alpha;  
            end
            %% Update plotted nnMask:
            % if app.hasfield(app.CONTOURS(iFrame).TRKRES, 'NNMASKBoundaries')
            if isfield(app.CONTOURS(iFrame).TRKRES, 'NNMASKBoundaries')
                 app.hg_mask_boundary = app.DisplayMaskBoundary(app.Ax0, app.CONTOURS(iFrame).TRKRES.NNMASKBoundaries);
                if app.Ck_ShowNNMask.Value
                    app.hg_mask_boundary.Visible = 'on';
                else
                    app.hg_mask_boundary.Visible = 'off';
                end
            end 

            %%
            drawnow 
            hold(app.Ax0,'off');
            % F = getframe(app.Ax0);
            %% Update anchor table:
            app.anchorTableArray = round([x,y]);
            app.UIAnchorTable.Data = app.anchorTableArray;
            %% Update Snake energy shown in the panel:
            energy = app.CONTOURS(iFrame).TRKRES.ENERGY;
            energyDispFormat = '%0.3g';
            if ~isempty(energy) && isnumeric(energy)
                meanEnergy = mean(energy, 'omitnan');
                maxEnergy = max(energy); 
                q3 = quantile(energy, 0.75);
                app.Ed_SnakeEnergyMean.Value = sprintf(energyDispFormat , meanEnergy);
                app.Ed_SnakeEnergyMax.Value = sprintf(energyDispFormat , maxEnergy);
                app.Ed_SnakeEnergyQ3.Value = sprintf(energyDispFormat , q3);
            else
                app.Ed_SnakeEnergyMean.Value = '';
                app.Ed_SnakeEnergyMax.Value = ''; app.Ed_SnakeEnergyQ3.Value = '';
            end
            if ~isempty(anchors) && size(anchors,1) > 3
                roughness = app.CalcSnakeRoughness(anchors(:,1:2));
                meanRoughness = mean(roughness, 'omitnan');
                contourLength  = app.LineLength(anchors(:,1:2));
                ANCHORS = {app.CONTOURS.ANCHORS};
                IFDist  = app.CalcInterFrameDistance(ANCHORS, iFrame);
                app.Ed_Roughness.Value = sprintf(energyDispFormat, meanRoughness);
                app.Ed_Length.Value = sprintf('%0.0f', contourLength); 
                app.Ed_IFDist.Value = sprintf('%0.2f', IFDist); 
            else
                app.Ed_Roughness.Value =''; app.Ed_Length.Value = ''; app.Ed_IFDist.Value = '';
            end

        end %function app=updatePlotAnchors(app)
        %%
        function app = updatePlotAnchorsFast(app)
            % updatePlotAnchorsFast for Play Video purpose only
            if ~app.active, return; end
            if ~isempty(app.hg_mask_boundary) && isgraphics(app.hg_mask_boundary)
                delete(app.hg_mask_boundary);
            end
            iFrame = app.Ed_Frame.Value; 
            if isempty(app.CONTOURS(iFrame).ANCHORS)
                if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
                if isgraphics(app.hg_anchor_numbers), delete(app.hg_anchor_numbers);end
                if isgraphics(app.handle_xy), delete(app.handle_xy);end
                return
            end
            anchors = app.CONTOURS(iFrame).ANCHORS; xy = app.CONTOURS(iFrame).XY;
            x = anchors(:,1); y = anchors(:,2); isUser = anchors(:,3); 
            sz = app.anchor_size;  
            ancColor =  isUser*app.user_anchor_color + ~isUser*app.anchor_color;
            hold(app.Ax0,'on');

            if isempty(app.handle_anchors) || ~isgraphics(app.handle_anchors)
               % First time plotting anchor points:
                app.handle_anchors = scatter(app.Ax0,x,y, sz, ancColor, ...
                    'filled', 'o', 'MarkerFaceAlpha', app.anchor_alpha,'Visible', 'on', ...
                    'MarkerEdgeColor',app.anchor_edge_color, 'MarkerEdgeAlpha', app.anchor_alpha);
            else
% %               % Update existing plotted anchors:
                app.handle_anchors.XData = x; app.handle_anchors.YData = y; 
                app.handle_anchors.SizeData = app.anchor_size;
                app.handle_anchors.CData = ancColor;
                app.handle_anchors.MarkerFaceAlpha = app.anchor_alpha;
                app.handle_anchors.MarkerEdgeAlpha = app.anchor_alpha;
            end
%% Update plotted anchor order numbers:
            if ~isempty(app.hg_anchor_numbers) && isgraphics(app.hg_anchor_numbers)
                delete(app.hg_anchor_numbers);
            end

%%  Update plotted anchor-connecting lines:
            if isempty(app.handle_xy) || ~isgraphics(app.handle_xy)
                % First time plotting XY connecting line:
%                 contour_alpha = 0.7; contour_color = 'y'; contour_visible = 'on'; contour_style = ':'; contour_width = 1;
                app.handle_xy = plot(app.Ax0, xy(:,1), xy(:,2), 'color', app.contour_color, ...
                    'visible', app.contour_visible, 'LineStyle', app.contour_style, 'LineWidth', app.contour_width);
                app.handle_xy.Color(4) = app.contour_alpha;
            else
% %         % Update existing XY connecting line:
                app.handle_xy.Visible = app.contour_visible;
                app.handle_xy.XData = xy(:,1); app.handle_xy.YData = xy(:,2);
                app.handle_xy.Color = app.contour_color; 
                app.handle_xy.LineStyle = app.contour_style; 
                app.handle_xy.Color(4) = app.contour_alpha;  
            end
            % %% Update plotted nnMask: 
            % if app.Ck_ShowNNMask.Value && app.hasfield(app.CONTOURS(iFrame).TRKRES, 'NNMASKBoundaries')
            if app.Ck_ShowNNMask.Value && isfield(app.CONTOURS(iFrame).TRKRES, 'NNMASKBoundaries')
                 app.hg_mask_boundary = app.DisplayMaskBoundary(app.Ax0, app.CONTOURS(iFrame).TRKRES.NNMASKBoundaries);
            end
            updatePlotEMA(app); 
            %
            drawnow 
            hold(app.Ax0,'off');
        end %function app=updatePlotAnchorsFast(app)
        %%
        function app = do_uncrop(app)
            app.Bn_CropButton.Value = 0; app.flag_SelectCropRect = false;
            app.flag_SelectRectMouseDown = false;
            app.Msg1.Value = app.defaultMsg; 

            app = updateStoredCropRect(app, [1,1,app.width,app.height]);
            app.Ax0.XLim = [1,app.width]; app.Ax0.YLim = [1,app.height]; 
            UpdateMinDist(app);
            set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
        end %function app=do_uncrop(app)
        %%
        function app = do_crop(app, rect)
            if nargin < 2 || isempty(rect)
                if isempty(app.handle_rect), return;end
                rect = app.get_rect_from_plot_handle(app.handle_rect);
                delete(app.handle_rect); app.handle_rect = [];
            end
            % rect: [left, top, width, height];
            if rect(3) < app.minDist || rect(4) < app.minDist
                if ~isempty(app.handle_rect) && isgraphics(app.handle_rect)
                    delete(app.handle_rect); app.handle_rect = []; 
                end
                app.Msg1.Value = 'Selected cropping area is too small.';
                return;
            end
            %% rect can't be larger than video width&height:
            x = rect(1)+rect(3)-1; 
            if x > app.vObj.Width,  rect(3) = app.vObj.Width - rect(1) +1; end 
            y =  rect(2)+rect(4)-1; 
            if y > app.vObj.Height, rect(4) = app.vObj.Height - rect(2) +1; end 
            %%
            app = updateStoredCropRect(app, rect);
            app.Ax0.XLim = [rect(1), rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2), rect(2)+rect(4)-1]; 
            UpdateMinDist(app);
        end %function app=do_crop(app)
        %%
        function [valid, msg] = validateCropRect(app, rect)
            valid = false; msg = '';
            min_width = 3; min_height = 3; 
            if ~isnumeric(rect), valid = false; msg = 'rect is not numeric'; return; end
            if length(rect) ~= 4, valid = false; msg = 'length of rect is not 4'; return; end
            if rect(1) < 1, valid = false; msg = 'xmin < 1'; return; end
            if rect(2) < 1, valid = false; msg = 'ymin < 1'; return; end
            if rect(3) < min_width, valid = false; msg = sprintf('width < %d', min_width); return; end
            if rect(4) < min_height, valid = false; msg = sprintf('heightn < %d', min_height); return; end            
            H = app.vObj.Height; W = app.vObj.Width; 
            x0 = rect(1); y0 = rect(2);   x1 = x0 + rect(3) -1; y1 = y0 + rect(4) -1; 
            if y1 > H, valid = false; msg = 'Height > image height'; return; end
            if x1 > W, valid = false; msg = 'Width > image width'; return; end
            valid = true; msg = 'valid'; 
        end % validateCropRect
        
        %%
        function do_mpp(app)
            % Calculate & update/store MPP
            if isempty(app.handle_mpp), return;end
            x = app.handle_mpp.XData; y = app.handle_mpp.YData; 
            x1 = x(1); x2 = x(end); y1 = y(1); y2 = y(end); 
            d = app.udist([x1, y1], [x2, y2]); mpp = 10/d; 
            app.MPP = mpp;  
            app.Msg1.Value = sprintf('Millimeter/Pixel (MPP) = %0.4g', mpp);
            pause(1);
            if isgraphics(app.handle_mpp), delete(app.handle_mpp); end
            app.handle_mpp=[];
        end %do_mpp(app)      
        %%
        function LoadContourStructUI(app)
            [f, p] = uigetfile('*.mat','Select contour file ...');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(f,0),  return; end 
            matFName = fullfile(p, f);              
            LoadContourStruct(app, matFName);         
        end  %function LoadContourStructUI(app)
        %%
        function LoadContourStruct(app, contourStruct)
            % if 'contourStruct' is a string of text, then load contours
            % from .mat file. if 'contourStruct' is a struct array, then it
            % is loaded from app.FileList
            if ischar(contourStruct)
                if ~exist(contourStruct, 'file'), return;end
                try
                    m = app.load1var(contourStruct);
                catch
                    % app.Msg1.Value = 'Invalid contour format.'; 
                    ShowSuccessMsg(app, 'Error', 'Invalid contour format!');
                    return         
                end
            else 
                m = contourStruct;
            end
             UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading contours MAT file','Indeterminate','on');
            % Check if it is correct struct format:
            if ~app.isContourStruct(m), ShowSuccessMsg(app, 'Error', 'Invalid contour format!');return; end
            ANCHORS = {app.CONTOURS.ANCHORS};
            if ~all(cellfun(@isempty, ANCHORS))
                selection = uiconfirm(app.UIFigure, 'Erase all & then load, or Append', 'How to deal with current contours','Options',{'Erase all & Load', 'Append', 'Cancel'}, 'DefaultOption',1,'CancelOption',3);  
                 if strcmpi(selection, 'Erase all & Load'), app= EraseContoursAllFrames(app); end
                if strcmpi(selection, 'Cancel'), return;  end
            end
           %%
            tic
            for i = 1:numel(m)
                k = m(i).FRAME; % k=frame number
                k = round(k);
                if k > app.nFrames, continue;end
                if k < 1, continue;end 
                anchors = m(i).ANCHORS; 
                anchors(:,3) = 0; % 3rd column is 'IsUser' input.
                app.CONTOURS(k).ANCHORS =  anchors;
                app.CONTOURS(k).XY = m(i).XY; 
                if isfield(m,'TRKRES') && ~isempty(m(i).TRKRES) 
                    if isfield(m(i).TRKRES,'ENERGY') && ~isempty(m(i).TRKRES.ENERGY)
                        app.CONTOURS(k).TRKRES.ENERGY = single(m(i).TRKRES.ENERGY);
                     end
                    if isfield(m(i).TRKRES,'METHOD') && ~isempty(m(i).TRKRES.METHOD)
                        app.CONTOURS(k).TRKRES.METHOD = m(i).TRKRES.METHOD;
                    end
                     if isfield(m(i).TRKRES,'NNMASKBoundaries')
                        app.CONTOURS(k).TRKRES.NNMASKBoundaries = m(i).TRKRES.NNMASKBoundaries;
                     end             
                     if isfield(m(i).TRKRES,'NNMaskArea')
                        app.CONTOURS(k).TRKRES.NNMaskArea =single(m(i).TRKRES.NNMaskArea);
                    end        
                end
                if isfield(m, 'EMA') && ~isempty(m(i).EMA)
                    app.CONTOURS(k).EMA = m(i).EMA;
                end
                if isfield(m, 'EMANAMES') && ~isempty(m(i).EMANAMES)
                    app.CONTOURS(k).EMANAMES = m(i).EMANAMES;
                end                
            end %for i = 1:numel(m)
            elapsedTime = toc;
            fprintf('Loaded %d contours. Took %04f sec(s).\n', numel(m), elapsedTime);
            updatePlotAnchors(app);    
            close(UIprogressDialog_handle);
        end %function LoadContourStruct(app)
        %%
        function app= EraseContoursAllFrames(app)

            for iFrame = 1:numel(app.CONTOURS)
                app.CONTOURS(iFrame).XY = []; 
                app.CONTOURS(iFrame).ANCHORS = [];
                app.CONTOURS(iFrame).nnMASKS = [];
                app.CONTOURS(iFrame).TRKRES.ENERGY = [];
                app.CONTOURS(iFrame).TRKRES.METHOD =[];
            end
            
            app.anchorTableArray = NaN(1,2);
            app.UIAnchorTable.Data = app.anchorTableArray;    
            updatePlotAnchors(app);  
        end %function app= EraseContoursAllFrames(app)
        %%
        function updateAx0Ed_CursorLocValue(app)
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %current x,y positions in Ax0.
            app.Ed_CursorLoc.Value = sprintf('%0.0f, %0.0f', x, y);
        end %updateAx0Ed_CursorLocValue(app)
        function updateAx1Ed_CursorLocValue(app)
            x = app.Ax1.CurrentPoint(1,1);  %current x,y positions in Ax1.
            % y = app.Ax1.CurrentPoint(1,2); 
             app.Ed_CursorLoc.Value = sprintf('%0.3f', x);
        end %updateAx0Ed_CursorLocValue(app)
        %%
        function [x,y, t_audio] = update_axes_cursor_loc(app)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1.Position; % Axis for AudioAll
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
            x = app.Ax0.CurrentPoint(1,1); y = app.Ax0.CurrentPoint(1,2); %current x,y positions in Ax0.
            t_audio = [];
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                app.Ed_CursorLoc.Value = sprintf('%0.0f, %0.0f', x, y);
            elseif app.ifWithinPos(fig_pt, pos_Ax1) && ~isempty(app.AUDIO)
                % If the cursor is within Ax1_AudioAll, update the
                % CursorLoc value to audio time.
                t_audio = app.Ax1.CurrentPoint(1,1); % Cursor current time in audio
                app.Ed_CursorLoc.Value = sprintf('%0.3f', t_audio);
            else
                app.Ed_CursorLoc.Value = '';
            end
        end %function [x,y] = update_axes_cursor_loc(app)
        %%
        function [x,y] = Ax0_cursor_loc(app)
            temp = app.Ax0.CurrentPoint; ax_pt = [temp(1,1) temp(1,2)]; % ax_pt : current x,y positions in Ax0.
            x = round(ax_pt(1),2); y = round(ax_pt(2),2);
        end %function [x,y] = Ax0_cursor_loc(app)  
        %%
        function im = enhanceImage(app, im)
            enhanceMethod = app.BnG_EnhanceContrast.SelectedObject.Text;
            if strcmpi(enhanceMethod, 'None'), return;end
            if size(im, 3) > 1, im = rgb2gray(im); end
            switch enhanceMethod
                case 'None'
                case 'Adjust'
                    im = imadjust(im);
                case 'HistEq'
                    im = histeq(im);
                case 'AdaptHistEq'
                    im = adapthisteq(im);
            end  
        end % function im = enhanceImage(app, im)
        %%

        %% 
        function [anchors, snakeEnergy, xy, nnMask, success] = detectOneFrame(app, im, method, anchors)
            % 'anchors' : n x 3 {x, y, isUserInput}  % the x-y coordinates in the stored "anchors" are with respect to the original (uncropped) image. 
            % crop_rect: % cropping rectangle: [xmin ymin width height]
            success = false; rect = app.crop_rect;
            if size(im,3) > 1, im= rgb2gray(im); end
            im = enhanceImage(app, im);        
            if app.Ck_FlipY.Value
                im = flipud(im); rect  = app.flipYCropParaRect(rect, app.vObj.Height);
                anchors = app.flipYcoordinatesInImage(anchors, app.vObj.Height);
            end            
            im_cropped = frameCropPreprocess(im, rect);
            %%   SNAKE parameters: 
            sigma= app.snakePara.Sigma; 
            Delta=app.snakePara.Delta;  
            BandPenalty = app.snakePara.BandPenalty;  
            Alpha = app.snakePara.Alpha; Lambda1 = app.snakePara.Lambda1; 
            if app.Ed_SnakeBandPenalty.Value > 0
                UseBand =1; 
            else
                UseBand = 0;
            end
            ROI = [];  nnMask = ones(app.height, app.width); 
            snakeEnergy = NaN;
            blobProbThreshold  = app.Spn_NNProbThresh.Value;
            % alpha : internal engergy
            % beta = 1 - alpha; external engergy
            % TotalEnergy: E = alpha * Eint + beta * Eext
            %  InternalEnergy: Eint = lambda1 * curvature + lambda2 * stiffness
            %  ExternalEngergy: Eext = Egrad + Eband
            %%

            nPt = 20; % how many points for interpolation
            cropPt = double(rect(1:2)); % Upper-leftmost point (xmin, ymin) in cropping parameters
            % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                snakeEnergy=[]; xy = []; anchors = []; return;
            end
            switch method
                case 'NeuralNet'
                    %% NN prediction
                    [nnXY, nnMask, probMask,  nnXYlength, anchors] = predUSTC(app.net, im_cropped, nPt, blobProbThreshold);
                    if isempty(anchors)  
                        snakeEnergy=[];xy=[]; anchors = [];  
                        app.Msg1.Value = 'No contour detected';  return;  
                    end  
                    nnMask = app.imUncrop(nnMask, rect, im); 
                    success = true;
                case 'NN+SNAKE'
                    %% NN predict + SNAKE
                    [nnXY, nnMask, probMask,  nnXYlength, anchors] = predUSTC(app.net, im_cropped, nPt, blobProbThreshold);
                    if isempty(anchors) || size(anchors,1) < app.minSnakeAnchors
                        snakeEnergy=[]; xy = []; anchors = [];  
                        app.Msg1.Value = 'No contour detected'; 
                        return; 
                    end
                    nnMask = app.imUncrop(nnMask, rect, im); 
                    [anchors, snakeEnergy]=slurp_snake(im_cropped , anchors, [], nPt, sigma, Delta, BandPenalty, Alpha, Lambda1, ROI, [], UseBand);
                    if isempty(anchors), return;end
                    success = true; 
                case 'SNAKE'
                    %% SNAKE only; needs existing anchors
                    if isempty(anchors) || size(anchors,1) < app.minSnakeAnchors || size(anchors, 2) < 2
                        snakeEnergy=[]; xy=[]; anchors = []; 
                        app.Msg1.Value = sprintf('SNAKE error! SNAKE requires at least %d anchors.', app.minSnakeAnchors);
                        return; 
                    end
                    anchors = anchors(:,1:2) - cropPt + [1,1];
                     [anchors, snakeEnergy]=slurp_snake(im_cropped, anchors, [], nPt, sigma, Delta, BandPenalty, Alpha, Lambda1, ROI, [], UseBand);     
                     if isempty(anchors), return;end
                    success = true; 
                otherwise
                    snakeEnergy=[]; xy = []; anchors = []; return;
            end % switch method
            if success, app.Msg1.Value = app.defaultMsg; end
            anchors = [anchors  + cropPt - [1,1], zeros(nPt,1)];
            if size(anchors,1) > 2
                xy = app.interpLine(anchors(:,1:2), app.nXYpoints, 'makima');
            else
                xy = anchors;
            end
            %% If flip y:
             if app.Ck_FlipY.Value
                anchors = app.flipYcoordinatesInImage(anchors, app.vObj.Height);
                xy = app.flipYcoordinatesInImage(xy, app.vObj.Height);
                nnMask = flipud(nnMask);
            end                
            % Round up: 
            xy = round(xy, 2); anchors = round(anchors, 2);
           %%
            function im_cropped = frameCropPreprocess(im, rect)
                % Preprocess frame image: 
                %   Cropping; 
                % h0 = size(im,1); w0 = size(im,2);
                % if color, convert im to gray scale:
                if size(im, 3) > 1, im = rgb2gray(im); end
                im = im2double(im);   
                im_cropped = myimcrop(im, rect);  %(inclusive)
                im_cropped = im2uint8(im_cropped);
                    function out = myimcrop(im, rect)
                        % MyImCrop:   (inclusive) Crop image by 'rect'  [xmin(left), ymin(top), width, height];
                        % WChen 21APR2023
                        % [H, W, C] = size(im); out = [];
                        x0 = rect(1); y0 = rect(2);   x1 = x0 + rect(3) -1; y1 = y0 + rect(4) -1; 
                        if y1 > size(im, 1), y1 = size(im,1); end
                        out = im(y0:y1, x0:x1,:);
                    end % myimcrop()                
            end %[im_cropped] = frameCropPreprocess(im, rect)
        end % function [anchors, snakeEnergy, xy] = detectOneFrame
        %
        function loadModel(app, FName)
            % function pragma to load toolboxes:
            %#function nnet.cnn.layer.PixelClassificationLayer
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading model','Indeterminate','on');
            mat = app.load1var(FName,'net');
            close(UIprogressDialog_handle)
            if ~isa(mat, 'DAGNetwork')
                uialert(app.UIFigure,'Incorrect model','Error loading model'); 
                return            
            end
            [p,f,~]=fileparts(FName); app.net = mat; app.modelname = f; app.modelPath = p; 
        end %loadModel(app, FName)
        %%
        function success = loadCropPara(app, FName)
            success = false;
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading cropping parameters','Indeterminate','on');
            try, rect = app.load1var(FName);catch, return;end
            close(UIprogressDialog_handle)
            w = app.vObj.Width; h = app.vObj.Height; 
            rect = uint16(rect);
            [valid, msg] = validateCropRect(app, rect);
            if ~valid, app.Msg1.Value = msg; return; end
            do_crop(app, rect);
            success = true;
        end %success = loadCropPara(app, FName)
        %%
        function success = loadTextGrid(app, FName)
            % AudOffset: adding offset to audio 
            % if AudOffset > 0, add zeros at the begining of the audio. 
            % if AudOffset < 0, chop off audio at the begining. 
            delay = app.audioOffset;
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading TextGrid file','Indeterminate','on');
            SetStatus(app, 'busy');
            try
                [TG, TierNames] = app.ReadTextGrid(FName); 
                if delay ~= 0
                    for i =1:numel(TG)
                        TG(i).segs = TG(i).segs +delay; 
                    end
                end
                success = true; 
                app.TextGrid = TG; app.TextGridFilename = FName;
            catch 
                app.Msg1.Value = 'Invalid TextGrid'; 
                success = false; SetStatus(app, 'ready');
                close(UIprogressDialog_handle);
                return
            end
            
            if numel(app.FileList) > 1 && ~isempty(app.WhichTextGridTierSelected) ...
                    && app.WhichTextGridTierSelected > 0 ...
                    && app.WhichTextGridTierSelected <= numel(TierNames)
                ind = app.WhichTextGridTierSelected; ifSelected = true;
            elseif numel(TierNames) > 1
                [ind,ifSelected] = listdlg('PromptString',{'Select only one tier ',...
                 'in the TextGrid',''},'SelectionMode','single','ListString',TierNames);
                figure(app.UIFigure) % Get focus back on UIFigure
            else 
                % If there is only one tier, then just select the tier without asking. 
                ind = 1; ifSelected = true;
            end          
            if ~ifSelected, success = false; close(UIprogressDialog_handle); SetStatus(app, 'ready');return; end
            SetStatus(app, 'ready'); close(UIprogressDialog_handle);
            app.TextGridTier = TG(ind);
            app.WhichTextGridTierSelected = ind;
        end % loadTextGrid
        %%
        function CopyTextGridLabelsToContour(app)
            % Copy TextGrid labels to app.CONTOURS struct
            if isempty(app.TextGrid), return; end
            tg = app.TextGridTier;  labs = tg.labs; segs = tg.segs;
            fps = app.vObj.FrameRate; durPerFrame = 1/fps; durHalfFrame = durPerFrame/2;
            contours = app.CONTOURS; 
            frameArr= cell2mat({contours.FRAME})';  ftArr = cell2mat({contours.TIME})'; 
            for i = 1:numel(labs)
                lab = labs{i};  t = segs(i,:);
                if isempty(lab), continue;end
                if size(segs,2) > 1 % interval tier
                    t1 = t(1); t2 = t(2);
                    if t2 < t1 || t2 <= 0 - durHalfFrame || t1 > ftArr(end)+durHalfFrame, continue;end % wrong interval
                    ind1 = app.FindClosest(ftArr, t1); ind2 = app.FindClosest(ftArr, t2);
                    if ind2 < ind1, continue;end % wrong interval
                     frameInds = frameArr(ind1:ind2)';
                    for j =  frameInds, contours(j).NOTE = lab; end
                else % point tier
                    if t < 0 || t > ftArr(end), continue;end
                    ind = app.FindClosest(ftArr, t);  frameInd = frameArr(ind);
                    contours(frameInd).NOTE = lab;
                end
            end        
            app.CONTOURS = contours;
        end %CopyTextGridLabelsToContour
        %%
        function drawTextGridIntervals(app)
          % Called only when loading TextGrid.
            if isempty(app.TextGrid), return; end
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Loading TextGrid labels','Indeterminate','on');
            SetStatus(app, 'busy');
            h = findobj(app.Ax1,'tag', 'TextGridLine');
            if ~isempty(h), delete(h); end
            h = findobj(app.Ax1,'tag', 'TextGridLabel');
            if ~isempty(h), delete(h); end
            tg = app.TextGridTier;  labs = tg.labs;
            y = app.Ax1.YLim(2); 
            app.hg_textgrid_labels = hggroup(app.Ax1); % Creat handle group for TextGrid labels.
            for i = 1:numel(labs)
                lab = labs{i}; t1 = tg.segs(i,1); 
                h1 = line(app.Ax1, [t1,t1], [0,1], 'color', app.PARAMS.TextGridLineColor, ...
                    'linestyle', '-', 'linewidth', 1, 'tag', 'TextGridLine'); 
                h1.Color(4) = app.PARAMS.TextGridLineAlpha; 
                if size(tg.segs,2) > 1 % interval tier
                    t = mean(tg.segs(i,:));
                else % point tier
                    t = tg.segs(i);
                end
                text(app.Ax1, t, y, lab, 'fontsize', app.PARAMS.TextGridFontSize, ...
                    'horizontalalignment', 'center', 'tag', 'TextGridLabel','color', app.PARAMS.TextGridLabelColor,...
                    'VerticalAlignment','top', 'interpreter', 'none', 'clipping', 'on', 'parent', app.hg_textgrid_labels);
            end
            close(UIprogressDialog_handle);
            SetStatus(app, 'ready');
        end %drawTextGridIntervals(app)
        %% 
        function success = CloseVideoFile(app)
            % Called when closing a video file (reading a new video file)
            ifAutosave = app.Ck_Autosave.Value;
            if ifAutosave && app.flag_ifAnchorModifed
                silent = true; Export2Mat(app, silent);
            elseif app.flag_ifAnchorModifed
            % If there are unsaved changes, prompt the user to confirm:
                msg = {'There are unsaved changes to contours in the current file', ...
                    'Continue closing current file? '};
                selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
                if strcmpi(selection, 'Cancel'), success = false; return;end
            end
            success = true;
        end %CloseVideoFile(app)
        %% 
        function axisNum = CurrentCursorWhichAxis(app)
            pos_Ax0 = app.Ax0.Position; % left bottom width height
            pos_Ax1 = app.Ax1.Position; % Axis for AudioAll
            fig_pt = app.UIFigure.CurrentPoint; % fig_pt: current x,y position in UIFig
%             axisNum = [];
            if  app.ifWithinPos(fig_pt, pos_Ax0)
                axisNum = 0;
            elseif app.ifWithinPos(fig_pt, pos_Ax1)
                axisNum = 1;
            else 
                axisNum = -1; % not in any axis
            end            
        end %CurrentCursorWhichAxis
        %%
        function clearDrawLine(app)
            % Clear/delete/terminate draw line
            app.draw_xy = []; 
            if isgraphics(app.handle_draw), delete(app.handle_draw);end
            app.handle_draw = [];
        end %clearDrawLine(app)
        %%
        function plotDrawLine(app, mouseButton)
            % Plot hand-drawn contour
            if isempty(app.draw_xy), clearDrawLine(app); return; end
            hold(app.Ax0,'on');
            xy = app.draw_xy; 
            if strcmpi(mouseButton, 'alt')
                linewidth = app.draw_width *  app.Slid_EraserWidth.Value;
            else
                linewidth = app.draw_width; 
            end
            % First time plotting draw line:
            if isempty(app.handle_draw) || ~isgraphics(app.handle_draw)
                app.handle_draw = plot(app.Ax0, xy(:,1), xy(:,2), 'color', app.draw_color, ...
                    'visible', app.draw_visible, 'LineStyle', app.draw_style, 'LineWidth', app.draw_width);
                app.handle_draw.Color(4) = app.draw_alpha;              
            else
            % Update existing draw line:
                app.handle_draw.Visible = app.draw_visible;
                app.handle_draw.XData = xy(:,1); app.handle_draw.YData = xy(:,2);
                app.handle_draw.Color = app.draw_color; 
                app.handle_draw.LineStyle = app.draw_style; 
                app.handle_draw.LineWidth =  linewidth; 
                app.handle_draw.Color(4) = app.draw_alpha;  
            end
            drawnow 
            hold(app.Ax0,'off');
        end % plotDrawLine

        %% 
        function Proc_WindowButtonUp_Ax0(app)
         % Procedure of WindowButtonUp in Ax0 (Ultrasound image axis)
                mouseButton = app.UIFigure.SelectionType;
                iFrame = app.Ed_Frame.Value; 
                [x,y] = Ax0_cursor_loc(app); pt = [x,y];
                anchors = app.CONTOURS(iFrame).ANCHORS; 
                drawxy = app.draw_xy;  clearDrawLine(app);
                
                %% If left-click % Sw_ClickAnchor is 'On':
                if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                        ~app.flag_SelectRectMouseDown && ...
                        isempty(app.selectedAnchorID) && ... % no selected anchor
                       strcmpi(mouseButton, 'normal') % left click
                    % if the clicked pt is not within crop_rect:
                    if ~app.ifWithinPos(pt, app.crop_rect), return; end 
                    if isempty(drawxy), return; end
                    
                    d=sqrt(sum((drawxy(end,1:2)-pt).^2,2)); % distance between current pt and previous pt.
                    if d > app.minDist,  drawxy = [drawxy; pt];  end

                    if size(drawxy,1) > 1
                         len = app.LineLength(drawxy(:,1:2)); 
                         nPt = ceil(len / app.minDist /2); % # points for the draw line
                         if nPt > 2
                             drawxy = app.interpLine(drawxy(:,1:2), nPt); 
                         else
                             drawxy = drawxy([1,end],:);
                         end
                         drawxy(:, 3)= 1; % add user input index to the 3rd column
                        anchors = [anchors; drawxy]; 
                        anchors = app.sortAnchors(anchors); 
                    elseif size(drawxy,1) == 1 % if size(drawxy,1) == 1
                         
                        if ~isempty(anchors)
                            % if only one point added, find the closest existing anchor and insert it:
                            d = app.udist(anchors(:,1:2), drawxy(1,1:2));
                            [~, INDS] = sort(d,'ascend' ); 
                            minInd1 = INDS(1); %minInd2 = INDS(2);
                            drawxy(:, 3)= 1; % add user input index to the 3rd column
                            if minInd1 > 1 && minInd1 < size(anchors,1)
                                anchors = [anchors(1:minInd1, :); drawxy; anchors(minInd1+1:end, :)];
                            elseif minInd1 == size(anchors,1)
                                anchors = [anchors; drawxy];
                            elseif minInd1 == 1
                                anchors = [drawxy; anchors];
                            end
                        else % if isempty(anchors)
                            drawxy(:, 3)= 1; % add user input index to the 3rd column
                            anchors = drawxy;
                         end
                    end %if size(drawxy,1) > 1

                    updatePlotAnchors(app, anchors, 'USER');    
                    app.flag_ifAnchorModifed = true;
                end % Add anchor

                %% If Right-click & ClickAnchor, Delete anchor:
                if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                        ~app.flag_SelectRectMouseDown && ...
                       strcmpi(mouseButton, 'alt') % Right Click

                    % if the clicked pt is not within crop_rect:
                    if ~app.ifWithinPos(pt, app.crop_rect), return; end 
                    if isempty(drawxy), return; end
                    if size(drawxy,1) > 1
                         len = app.LineLength(drawxy(:,1:2));  nPt = ceil(len / app.minDist)*1.5; % # points for the draw line
                         if nPt > 2, drawxy = app.interpLine(drawxy(:,1:2), nPt); end
                    end
                    anchorIDsToBeDeleted = [];
                    for kPt = 1:size(drawxy,1)
                        pt = drawxy(kPt,:);
                        [~, ID] = app.AddOrSelectAnchor(anchors, pt, app.minDist * app.Slid_EraserWidth.Value);
                        anchorIDsToBeDeleted = [anchorIDsToBeDeleted; ID];
                    % if right click on existing anchor, delete the selected anchor:
                    end
                    if ~isempty(anchorIDsToBeDeleted)
                        anchors(anchorIDsToBeDeleted,:)=[]; 
                        updatePlotAnchors(app, anchors, 'USER');      
                        app.flag_ifAnchorModifed = true;
                    end
                end % Delete anchor
                %%
                function out = interpLine_simple(in, nOut, method) %in:nIn x mDims; out:nOut x mDims
                    in = unique(in, 'rows', 'stable'); 
                    cumD = [0; cumsum(sqrt(sum(diff(in).^2,2)))];  out = interp1(cumD, in, linspace(0, cumD(end), nOut), method);
                end %function out =interpLine_simple
        end % Proc_WindowButtonUp_Ax0
        %%
        function Proc_WindowButtonMotion_Ax0(app)
            % Procedure of WindowButtonMotion (mouse move) in Ax0 (Ultrasound image axis)
           
            % IF no WindowButtonDown event yet, do nothing:
            if ~app.flag_mouseClicked, return; end
             [x,y] = Ax0_cursor_loc(app); pt = [x,y];
             mouseButton = app.UIFigure.SelectionType;

            if app.flag_SelectRectMouseDown
                % Update Cropping rectangle
                app.handle_rect = app.updateXYinPlotRect(app.handle_rect, pt); return
            end % Update Cropping rectangle

            if app.flag_GetMppOn
                % Update Cropping rectangle
                app.handle_mpp = app.updateXYinMppLine(app.handle_mpp, pt); return
            end % Update Cropping rectangle
            
            if ~isempty(app.draw_xy) && app.ifWithinPos(pt, app.crop_rect)
                 d = app.udist(pt, app.draw_xy(end,:));% distance between current pt and previous pt.
                 if d > app.minDist/2, app.draw_xy = [app.draw_xy; pt];  else, return; end % update drawxy only when movement is big enough. 
            end
             %% If an anchor is selected & Sw_ClickAnchor is 'On':
             % Left click to move 1 selected anchor
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    ~isempty(app.selectedAnchorID) && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                    strcmpi(mouseButton, 'normal') % left click
                    if ~app.ifWithinPos(pt, app.crop_rect) 
                        clearDrawLine(app); return; 
                    end % if the clicked pt is not within crop_rect
                iFrame = app.Ed_Frame.Value; anchors = app.CONTOURS(iFrame).ANCHORS; 
                anchors(app.selectedAnchorID, 1:2) = pt;
                anchors(app.selectedAnchorID, 3) =1; 
                method = 'USER'; 
                updatePlotAnchors(app, anchors, method);
                app.flag_ifAnchorModifed = true;
                app.draw_xy = [];
            end % Move 1 selected anchor

            %% IF no selected anchor & left click move, update draw:
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    isempty(app.selectedAnchorID) && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                  strcmpi(mouseButton, 'normal') % left click
                    if ~app.ifWithinPos(pt, app.crop_rect), clearDrawLine(app); return; 
                    end % if the clicked pt is not within crop_rect
                  plotDrawLine(app, mouseButton);
            end % update draw

            %% IF no selected anchor & right click move, update draw:
             % isempty(app.selectedAnchorID) && ...
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ...
                  strcmpi(mouseButton, 'alt') % left click
                if ~app.ifWithinPos(pt, app.crop_rect), clearDrawLine(app); return; end
                  plotDrawLine(app, mouseButton);
            end % update draw
        end % Proc_WindowButtonMotion_Ax0

        %%
        function Proc_WindowButtonDown_Ax0(app)
             % Procedure of WindowButtonDown (mouse click) in Ax0 (Ultrasound image axis)
           [x,y] = Ax0_cursor_loc(app); pt = [x,y];
           app.draw_xy = pt;  %% store first click
           
            %% 'SelectCropRect' pushed:
            epsilon = 0.01; % small number to avoid plotting rectangle at one single point
            if app.flag_SelectCropRect
                app.flag_SelectRectMouseDown = true;
                hold(app.Ax0,'on');  plotRectAx0(app, pt, pt+epsilon);
                app.flag_SelectCropRect = false;
                app.Msg1.Value = 'Drag to select cropping rectangle....';
            end % Crop
            %% GetMPP:
            if app.flag_GetMppOn
                if app.flag_SelectCropRect, return; end
                if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, return; end
                hold(app.Ax0,'on'); 
                plotMppLine(app, pt, pt);
                app.Msg1.Value = 'Drag to select 1 cm interval....';
            end % Crop
            %% If Sw_ClickAnchor is 'On':
            % for both left and right click
            if strcmpi(app.Sw_ClickAnchor.Value, 'On') && ...
                    ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn
                iFrame = app.Ed_Frame.Value; 
                app.selectedAnchorID = []; anchors = app.CONTOURS(iFrame).ANCHORS;
                [~, app.selectedAnchorID] = app.AddOrSelectAnchor(anchors, pt, app.minDist);
            end % first click
        end % Proc_WindowButtonDown_Ax0
        %%
        function Proc_WindowButtonDown_Ax1(app)
            % Procedure of WindowButtonDown (mouse click) in Ax1 (audio axis)
            app.flag_AudioSelectDown = true; % flag to indicate mouse button down for audio selection
            x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
            mouseButton = app.UIFigure.SelectionType;
            if strcmpi(mouseButton, 'normal') % left click
                app.Ax1ClickLog = x;  %% store first click
            end % if strcmpi(mouseButton, 'normal') % left click
        end% Proc_WindowButtonDown_Ax1
        %% 
        function Proc_WindowButtonMotion_Ax1(app)
            % Procedure of WindowButtonMotion (mouse move) in Ax1 (Audio axis)          
            % IF no WindowButtonDown event yet, do nothing:
            if ~app.flag_mouseClicked, return; end
            if ~app.flag_AudioSelectDown, return; end
            if isempty(app.Ax1ClickLog), return;end
             x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
             minSelectDur = app.minAudioSelectDur; 
             firstx = app.Ax1ClickLog(1);lastx = app.Ax1ClickLog(end); 
             if abs(x-lastx) < minSelectDur, return;end
             mouseButton = app.UIFigure.SelectionType;
             if strcmpi(mouseButton, 'normal') % left click
                app.Ax1ClickLog = [firstx; x]; % only store first and last point
                firstx = app.Ax1ClickLog(1); lastx = app.Ax1ClickLog(end); 
                d = abs(lastx - firstx); 
                if d > minSelectDur 
                    plotUpdateAudioCursor(app, firstx, lastx); 
                end
             end %   if strcmpi(mouseButton, 'normal')
        end % Proc_WindowButtonMotion_Ax1(app)
       %% 
       function Proc_WindowButtonUp_Ax1(app)
        % Procedure of WindowButtonUp in Ax1 (Audio axis)   
            if isBusy(app), return; end
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, return; end
            if isempty(app.Ax1ClickLog), return;end
            x = app.Ax1.CurrentPoint(1,1); % Cursor time in audio
            minSelectDur = app.minAudioSelectDur; 
            firstx = app.Ax1ClickLog(1); lastx = app.Ax1ClickLog(end); 
            if abs(x-lastx) > minSelectDur, app.Ax1ClickLog = [firstx; x]; end 
            firstx = min(app.Ax1ClickLog); lastx = max(app.Ax1ClickLog); 
            SelectAudioFunc(app, firstx, lastx);
            app.Ax1ClickLog = [];  % clear Ax1ClickLog
       end % Proc_WindowButtonUp_Ax1  

       function SelectAudioFunc(app, firstx, lastx)
            % firstx, lastx: first and last time points in second. 
            if firstx < 0, firstx = 0; end
            d = abs(lastx - firstx);
            frame1 = time2frame(app, firstx); frame2 = time2frame(app, lastx); 
            app.audioSelectionFrameIndices = sort([frame1, frame2]);
            frame1 = app.audioSelectionFrameIndices(1); frame2 = app.audioSelectionFrameIndices(2);
            nSelectedFrames = frame2 - frame1 + 1;
            
                if d > app.minAudioSelectDur
                    plotUpdateAudioCursor(app, firstx, lastx); 
                    txt1 = sprintf('Audio selected from %0.4g to %0.4g (s).', firstx, lastx);
                    txt2 = sprintf('Selected audio duration = %0.4g (s)', d);
                    txt3 = sprintf('%d frames selected from %d to %d.', nSelectedFrames, frame1, frame2);
                    app.Msg1.Value = {txt1, txt2, txt3};
                else
                    plotUpdateAudioCursor(app, firstx); 
                    app.Msg1.Value = app.defaultMsg;
                end
           
            dispFrame(app, frame2); 
            updatePlotAnchors(app);   
       end % SelectAudioFunc()

       %%
       function frameIndex = time2frame(app, timeStamp)
            frameIndex = floor(timeStamp * app.vObj.FrameRate) + 1;  
            if frameIndex > app.vObj.NumFrames, frameIndex = app.vObj.NumFrames;end           
       end % time2frame
       function timeStamp = frame2time(app, frameIndex)
            timeStamp  = (frameIndex-1) ./ app.vObj.FrameRate;
            if timeStamp < 0, timeStamp = 0; end
       end % time2frame
        %%
       function Export2Mat(app, silent, outFName, startFrame, endFrame)
            if nargin < 2 || isempty(silent), silent = false; end
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
             [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            if nargin < 3 || isempty(outFName), outFName = fullfile(videoPath, vFn); end
            if nargin < 4 || isempty(startFrame), startFrame = 1; end
            if nargin < 5 || isempty(endFrame), endFrame = inf; end
            fig = app.UIFigure; 
            contours = app.CONTOURS;
            try contours = rmfield(contours, 'nnMASKS'); catch, end % remove nnMASKS to save space
            
            frames = cell2mat({contours.FRAME})';
            ix = frames >= startFrame & frames <= endFrame; 
            if all(ix), ifUpdateContoursInFileList = true; else, ifUpdateContoursInFileList = false; end

            contours = contours(ix);

            for iFrame = 1:numel(contours)
                xy = contours(iFrame).XY; anchors = contours(iFrame).ANCHORS;
                if ~isempty(xy), contours(iFrame).XY = xy(:,1:2); end
                if ~isempty(anchors), contours(iFrame).ANCHORS = anchors(:,1:2); end
                if startFrame > 1
                    contours(iFrame).FRAME = iFrame;
                    contours(iFrame).TIME = (iFrame-1)/app.vObj.FrameRate;
                end
                if isfield(contours(iFrame), 'TRKRES')
                     if isfield(contours(iFrame).TRKRES, 'NNMASKBoundaries') 
                         contours(iFrame).TRKRES = rmfield(contours(iFrame).TRKRES, 'NNMASKBoundaries');
                     end
                end
            end
            contours(cellfun(@isempty,{contours.XY})) = [];					% remove empty frames
            if isempty(contours)
                app.Msg1.Value = 'No contours.';  return
            end

            %%
             [p,f,e]=fileparts(outFName);  if isempty(e), outFName = fullfile(p, [f '.mat']); end
             if ~isvarname(f), varN = 'contours'; else, varN = f; end
             tmpStruct.(varN) = contours; %save(outFName, '-struct','tmpStruct', app.matFileVersion);  
            % If output file exists:
            successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Contours successfully saved.'; 
            if exist(outFName,'file') && ~silent
                msg = 'Saving these changes will overwrite previous changes.';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFName, '-struct','tmpStruct', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);  
                    case 'Save as new'
                        [file, p] = uiputfile(outFName,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFName = fullfile(p, file);  save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                        ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);  
                    case 'Cancel',  return;
                end %switch selection
            elseif silent
                % if silent,  save file no matter file exists or not:
                save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                app.Msg1.Value = 'Contours saved.';
                pause(0.5);
                app.Msg1.Value = app.defaultMsg;
            else
                 % if file does not exist and not silent:
                save(outFName, '-struct','tmpStruct', app.matFileVersion); 
                ShowSuccessMsg(app, 'Success', successMsg , successMsgWaitTime);               
            end %if exist(outFN,'file')       
            app.count_modified_frames = 0; 
            app.flag_ifAnchorModifed = false;
            %% if Update Contours In FileList:
            if all(ifUpdateContoursInFileList)
                fInd = app.CurrentFileNumber;  app.FileList(fInd).CONTOURS = contours; 
                app.FileList(fInd).NumContours = numel(contours); 
            end
       end %function Export2Mat
       %%
       function ExportCropPara(app, outFN, crop_para)
            fig = app.UIFigure; successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = 'Cropping parameters successfully saved.'; 
            % If output file exists:
            if exist(outFN,'file')
                msg = 'Cropping parameter file exists. Overwrite?';  title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFN, 'crop_para', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Save as new'
                        [file, p] = uiputfile(outFN,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFN = fullfile(p, file);   save(outFN, 'crop_para', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Cancel',  return;
                end %switch selection
                return
            else 
                 save(outFN, 'crop_para', app.matFileVersion); 
                 ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
            end %if exist(outFN,'file')
        end %  function ExportCropPara
        %%
        function ExportVariable(app, outFN, var, varname, variable_disp_name)
             % Save variable to .mat file.
            % INPUT:  
            %   var:  data variable to be saved. 
            %   varname: variable name in the saved .mat file.
            %   variable_disp_name:  varible name shown in the message only. 

            fig = app.UIFigure; successMsgWaitTime = 1.5; % wait time (s) for displaying success message.
            successMsg = sprintf('%s successfully saved.', variable_disp_name); 
             if ~isvarname(varname), varN = 'var'; else, varN = varname; end
             tmpStruct.(varN) = var; 
            % If output file exists:
            if exist(outFN,'file')
                msg = sprintf('%s  file exists. Overwrite?', variable_disp_name);   title = 'Confirm Save';
                selection = uiconfirm(fig,msg,title,'Options',{'Overwrite','Save as new','Cancel'},'DefaultOption',2,'CancelOption',3);     
                switch selection
                    case 'Overwrite'
                        save(outFN, '-struct','tmpStruct', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Save as new'
                        [file, p] = uiputfile(outFN,'Save as file ...'); 
                        figure(fig); % Get focus back to main figure
                        outFN = fullfile(p, file);   
                        save(outFN, '-struct','tmpStruct', app.matFileVersion);  
                        ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
                    case 'Cancel',  return;
                end %switch selection
                return
            else 
                 save(outFN, '-struct','tmpStruct', app.matFileVersion);  
                 ShowSuccessMsg(app, 'Success', successMsg, successMsgWaitTime)
            end %if exist(outFN,'file')
        end %  function ExportCropPara
       %%
        function ShowSuccessMsg(app, title, msg, waitTimeSec)
            if nargin < 4 || isempty(waitTimeSec), waitTimeSec = 1; end
            fig = app.UIFigure; d = uiprogressdlg(fig,'Title', title, 'Message',msg,'Value', 1);
            pause(waitTimeSec); close(d)
        end %ShowSuccessMsg
        %%
        function plotRectAx0(app, p1, p2, varargin)
            % plot a rectangle from 2 corners: p1, p2 : [x,y] coordinates;  
            x = [p1(1), p1(1), p2(1), p2(1), p1(1)];   y = [p1(2), p2(2), p2(2), p1(2), p1(2)]; 
            app.handle_rect = plot(app.Ax0, x,y, 'marker', app.rect_marker,'color', app.rect_color, ...
                'linestyle', app.rect_linestyle, 'linewidth', app.rect_linewidth, varargin{:});  
        end %function plotRectAx0(ax, p1, p2, varargin)
        %%

        %%
        function plotMppLine(app, p1, p2, varargin)
            % plot MPP line of 1 CM from 2 points: p1, p2 : [x,y] coordinates;  
            app.handle_mpp = plot(app.Ax0, [p1(1), p2(1)], [p1(2), p2(2)], 'marker', app.mpp_marker, ...
                'color', app.mpp_color, 'linestyle', app.mpp_linestyle, 'linewidth', app.mpp_linewidth, varargin{:});  
        end %function plotMppLine(ax, p1, p2, varargin)
        %%
        function busy = isBusy(app)
           % Check if status is busy
            busy = false; 
            if app.flag_GetMppOn, busy = true; return; end
            if app.flag_SelectCropRect, busy = true; return; end
            if app.flag_FrameTrackingOn || app.flag_FileTrackingOn, busy = true; return; end
            if app.flag_mouseClicked, busy = true; return; end
            if app.flag_busy, busy = true; return; end
        end % isBusy
        %%
        function RefreshAudioAxis(app, newXlm)
            if isempty(app.AUDIO), return;end
            oldXlm = app.Ax1.XLim; currentDur = oldXlm(2) - oldXlm(1);  
            if nargin < 2 || isempty(newXlm), newXlm = [app.AudioCursorTime, app.AudioCursorTime+currentDur]; end

            audioSig = app.AUDIO.s; audioSr = app.AUDIO.sr; audioDur = app.AUDIO.Dur; minDispDur = app.minAudioDisplayDur;
            newDur = newXlm(2) - newXlm(1); 
            if newDur < minDispDur, return; end
            if newDur > audioDur, return;end
            left = newXlm(1); right = newXlm(2);
            if left < 0, left = 0; right = newDur; end
            if right > audioDur, left = audioDur - newDur; right = audioDur; end
            x = [left, right]; n = floor(x*audioSr) +1; 
            if n(1) < 1, n(1) = 1;end
            if n(2) > size(audioSig,1), n(2) = size(audioSig,1);end
            s1 = audioSig(n(1):n(2));  ylm = [min(s1), max(s1)];
            if any(isnan(ylm)), ylm = [-1,1];end
            app.Ax1.XLim = x; app.Ax1.YLim = ylm;
            app.minAudioSelectDur = newDur / app.minAudioSelectionDenominator;
            if ~isempty(app.AudioCursorTime)
                plotUpdateAudioCursor(app, app.AudioCursorTime);
            end
            if ~isempty(app.TextGrid) && ~isempty(app.hg_textgrid_labels)
                updateTextGridLabels(app);
            end

            if ~isempty(app.handle_contoured_frame_pointer) && isgraphics(app.handle_contoured_frame_pointer)
               plotUpdateContouredFramePointer(app);
            end 
            if ~isempty(app.handle_diagnosed_frame_pointer) && isgraphics(app.handle_diagnosed_frame_pointer)
                plotUpdateDiagnosis(app);
            end 
        end %RefreshAudioAxis
        %% 
        function updateTextGridLabels(app)
            if isempty(app.TextGrid) || isempty(app.AUDIO), return;end
            ylm = app.Ax1.YLim;  xlm = app.Ax1.XLim; 
            hg = app.hg_textgrid_labels; pos = cell2mat({hg.Children.Position}');
            ix = pos(:,1) > xlm(1) & pos(:,1) < xlm(2); 
            if ~any(ix), return; end
            ind = find(ix)'; 
            for i = ind
                try
                     hg.Children(i).Position(2) = ylm(2); 
                catch
                end
            end
        end %updateTextGridLabels
        %%
        function RunDiagnosticFilters(app)
              diag = app.diagnosticMatrix;
              app.BadContourFrameIndex=[];
             % diag: nFrame x 5 (energy mean, max, q3, roughness, length, IFdist)
             lengthColInd = 5; IFdistColInd = 6;
             n = size(diag,1); m = size(diag,2); ix = false(n, size(diag,m));  
             if n >=10
                 medianLength = median(diag(:,lengthColInd), 'omitnan'); 
                 medianIFdist = median(diag(:, IFdistColInd), 'omitnan'); 
             else, medianLength = 0; medianIFdist = inf;
             end
             lengthMinThresh = app.Ed_LengthMinFilter.Value * medianLength /100; 
             lengthMaxThresh = app.Ed_LengthMaxFilter.Value * medianLength /100; 
             IFdistThresh = app.Ed_IFDistFilter.Value * medianIFdist /100; 
             checkValues = [app.Ck_EnergyMeanFilter.Value, ...
                 app.Ck_EnergyMaxFilter.Value, app.Ck_EnergyQ3Filter.Value, ...
                 app.Ck_RoughnessFilter.Value, app.Ck_LengthFilter.Value, app.Ck_IFDistFilter.Value];
             threshs =  [app.Ed_EnergyMeanFilter.Value, ...
                 app.Ed_EnergyMaxFilter.Value, app.Ed_EnergyQ3Filter.Value, ...
                 app.Ed_RoughnessFilter.Value, lengthMaxThresh,  IFdistThresh];
             for i = 1:m
                 ix(:,i) = diag(:,i) > threshs(i) & repmat(checkValues(i),n,1);
             end 
             % Additional minimal length filter:
             ix(:, lengthColInd) =ix(:, lengthColInd) | (diag(:, lengthColInd) < lengthMinThresh & repmat(checkValues(lengthColInd),n,1));
             app.BadContourFrameIndex = find(any(ix,2));
             app.diagnosticCriteriaMetMatrix = ix; 
             app.Msg1.Value = sprintf('Found %d frames with less optimal contour.', numel(app.BadContourFrameIndex));
             app.Ed_MedianLength.Value = sprintf('%0.0f', medianLength); app.Ed_MedianIFDist.Value = sprintf('%0.1f',medianIFdist); 
        end%RunDiagnosticFilters(app)
        %%
        function plotUpdateDiagnosis(app)
            hold(app.Ax1,'on');
            diagFrames = app.BadContourFrameIndex; nDiagFrames = numel(diagFrames);
            if nDiagFrames == 0 && ~isempty(app.handle_diagnosed_frame_pointer) && isgraphics(app.handle_diagnosed_frame_pointer)
                delete(app.handle_diagnosed_frame_pointer); app.handle_diagnosed_frame_pointer = []; return;
            end  
            if nDiagFrames == 0, return; end
            x = (diagFrames -1) ./ app.vObj.FrameRate;
            y = repmat(app.Ax1.YLim(1), nDiagFrames, 1);
%             y = zeros(n,1);
            if isempty(app.handle_diagnosed_frame_pointer) || ~isgraphics(app.handle_diagnosed_frame_pointer)
               % First time plotting handle_diagnosis:
                app.handle_diagnosed_frame_pointer = plot(app.Ax1, x, y, 'color', app.diagnosis_color, 'LineStyle', 'none', ...
                    'visible', app.diagnosis_visible, 'Marker', app.diagnosis_marker, 'MarkerSize', app.diagnosis_marker_size, 'clipping', 'on');
                app.handle_diagnosed_frame_pointer.Color(4) = app.diagnosis_alpha;
            else
% %               % Update existing handle_diagnosis:
                app.handle_diagnosed_frame_pointer.XData = x; app.handle_diagnosed_frame_pointer.YData = y; 
                app.handle_diagnosed_frame_pointer.LineStyle = 'none';
            end    
            hold(app.Ax1, 'off');
        end %plotUpdateDiagnosis(app)
        %%
        function plotUpdateContouredFramePointer(app)
            hold(app.Ax1,'on');
            contours = app.CONTOURS; contours(cellfun(@isempty,{contours.XY})) = [];
            contouredFrames = cell2mat({contours.FRAME})'; nContouredFrames = numel(contouredFrames);
            if nContouredFrames == 0 && ~isempty(app.handle_contoured_frame_pointer) && isgraphics(app.handle_contoured_frame_pointer)
                delete(app.handle_contoured_frame_pointer); app.handle_contoured_frame_pointer = []; return;
            end  
            if nContouredFrames == 0, return; end
            x = (contouredFrames -1) ./ app.vObj.FrameRate;
            y = repmat(app.Ax1.YLim(1), nContouredFrames, 1);
%             y = zeros(n,1);
            if isempty(app.handle_contoured_frame_pointer) || ~isgraphics(app.handle_contoured_frame_pointer)
               % First time plotting handle_contoured_frame_pointer:
                app.handle_contoured_frame_pointer = plot(app.Ax1, x, y, 'color', app.contouredFramedPointers_color, 'LineStyle', 'none', ...
                    'visible', app.diagnosis_visible, 'Marker', app.contouredFramedPointers_marker, ...
                    'MarkerSize', app.contouredFramedPointers_marker_size, 'clipping', 'on');
                app.handle_contoured_frame_pointer.Color(4) = app.diagnosis_alpha;
            else
% %               % Update existing handle_contoured_frame_pointer:
                app.handle_contoured_frame_pointer.XData = x; app.handle_contoured_frame_pointer.YData = y; 
                app.handle_contoured_frame_pointer.LineStyle = 'none';
                app.handle_contoured_frame_pointer.Color = app.contouredFramedPointers_color;
            end    
            hold(app.Ax1, 'off');
        end %plotUpdateContouredFramePointer(app)        
        %%
        function [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method)
            % status = 0 : error;  1: success;  2: user stopped
            status = 0; nFrames = EndFrame - StartFrame +1;
            trackingDelaySec = app.TrackingDelayMs/1000;
            if strcmpi(method, 'None')
                ifApplyAlgorithm = false;
            else
                ifApplyAlgorithm = true;
                if  app.TrackingDelayMs < app.minTrackingDelayMs, trackingDelaySec = app.minTrackingDelayMs/1000; end
            end 
            %% preprocess TextGrid intervals:
            labs = []; segs = [];
            if app.Ck_TrackOnlyNonEmptyTG.Value && ~isempty(app.TextGridTier) && nFrames >1
                tg = app.TextGridTier;  labs = tg.labs; segs = tg.segs; edge = app.Ed_PlusMsAtEdge.Value/1000;
                if size(segs,2) == 1, segs = [segs-edge, segs+edge];  end % if point tier
                if size(segs,2) ==2, segs(:,1) = segs(:,1) - edge; segs(:,2) = segs(:,2) + edge; end
                ix = ~cellfun(@isempty, labs);  segs = segs(ix,:); % keep segs with non-empty labels only
                if isempty(segs), app.Msg1.Value = 'No labels in the TextGrid tier!   Uncheck "Only track non-empty..." to track all frames.'; status=3; return; end
            end
            %%
            iFrame = StartFrame;
            while iFrame <= EndFrame && ~app.Bn_StopTrack.Value   
                if app.Ck_TrackOnlyNonEmptyTG.Value && ~isempty(app.TextGridTier)  &&  nFrames >1
                    ifSkip = false;   iFrameTime = frame2time(app, iFrame);
                    if isempty(segs), ifSkip = true;
                    else
                         ifSkip = ~any(segs(:,1) < iFrameTime & segs(:,2) > iFrameTime,1);
                    end        
                    if ifSkip, iFrame = iFrame +1;  continue;  end
                end
                if ifApplyAlgorithm
                    current_anchors = app.CONTOURS(iFrame).ANCHORS;
                    if iFrame > 1 
                        previous_anchors = app.CONTOURS(iFrame-1).ANCHORS;
                        if size(previous_anchors,1) < app.minSnakeAnchors
                            anchors = current_anchors;
                        else
                            anchors = previous_anchors;
                        end
                    elseif iFrame == 1
                        anchors = current_anchors;
                    end
                    if strcmpi(method, 'SNAKE') &&  size(anchors,1) < app.minSnakeAnchors
                        app.Msg1.Value = ['Snake requires at least ' num2str(app.minSnakeAnchors) '!'];
                        app.Bn_StopTrack.Value = false; %reset the button state
                        app.Bn_TrackFrames.Value = false;  app.Bn_TrackFiles.Value = false; app.flag_FrameTrackingOn = false; 
                        app.flag_FileTrackingOn = false; SetStatus(app, 'ready');  
                        break
                    end
                    im = app.Read1Frame(app.vObj, iFrame);
                    [anchors, energy, xy, nnMask, success] = detectOneFrame(app, im, method, anchors);
                    app.CONTOURS(iFrame).ANCHORS = anchors; app.CONTOURS(iFrame).XY = xy; 
                    app.CONTOURS(iFrame).TRKRES.ENERGY = single(energy); app.CONTOURS(iFrame).TRKRES.METHOD = method; 
                    if ~isempty(nnMask)
                        blobs = bwboundaries(nnMask); 
                        app.CONTOURS(iFrame).TRKRES.NNMASKBoundaries = blobs;
                        app.CONTOURS(iFrame).TRKRES.NNMASKArea = sum(nnMask(:));
                    else
                        app.CONTOURS(iFrame).TRKRES.NNMASKBoundaries = [];
                        app.CONTOURS(iFrame).TRKRES.NNMASKArea = [];                       
                    end
                    app.CONTOURS(iFrame).nnMASKS = nnMask;
                    app.flag_ifAnchorModifed = true;
                    if app.Ck_Autosave.Value 
                        app.count_modified_frames = app.count_modified_frames +1;
                        if app.count_modified_frames >= app.Ed_savingFreq.Value
                            silent = true; Export2Mat(app, silent); 
                            [~, vFn, ~]= fileparts( app.vObj.Name); matFn = [vFn, '.mat'];
                            fprintf([matFn ': saved Frame #'  num2str( iFrame) ' \n']);
                        end
                    end 
                end %ifApplyAlgorithm

                dispFrame(app, iFrame); updatePlotAnchors(app); 
                plotUpdateAudioCursor(app, app.Ed_Time.Value); 
                app.audioSelectionFrameIndices = iFrame;
                % Pause between frames:
                if iFrame < EndFrame, pause (trackingDelaySec); end
                % If user stopped:
                if app.Bn_StopTrack.Value 
                    app.Bn_StopTrack.Value = false; %reset the button state
                    app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
                    status = 2; % status = 0 : error;  1: success;  2: user stopped
                    EndFrame = iFrame -1;
                    SetStatus(app, 'ready');   break
                end % Bn_StopTrack
                iFrame = iFrame + 1;
                status = 1;
            end % while iFrame <= EndFrame  
        %  status = 1; % status = 0 : error;  1: success;  2: user stopped;  3: No non-empty TextGrid labels
        end % TrackThruAllFrames(app)
        %%
        function PreloadMovieFrames(app, StartFrame, EndFrame)
        % Preload frames into movie struct (struct('cdata',[],'colormap',[]))    
        if EndFrame <= StartFrame, return; end
        if EndFrame > app.vObj.NumFrames, return;end 
        if StartFrame < 1, return; end
        app.bufferMovieFrames = struct('cdata',[],'colormap',[]);
        nFrames = EndFrame - StartFrame +1;
         app.bufferMovieFrames(nFrames) = struct('cdata',[],'colormap',[]);                     

         for iFrame = StartFrame : EndFrame
             im = app.Read1Frame(app.vObj, iFrame);
             im = enhanceImage(app, im);
             if app.ifInvert
                if size(im, 3) > 1, im = rgb2gray(im);   end
                im = imcomplement(im);
            end
             app.bufferMovieFrames(iFrame-StartFrame+1).cdata =im;
             app.bufferMovieFrames(iFrame-StartFrame+1).FRAME = iFrame;
         end
        end % PreloadMovieFrames
        %%
        function [status, EndFrame] = PlayVideoFrames(app, StartFrame, EndFrame, ticStart, frameElapsedTimeStamps, startTime, endTime, ifShowAnchors)
            % status = 0 : error;  1: success;  2: user stopped
            nFrames = EndFrame - StartFrame +1;
            if nargin < 4 || isempty(ticStart), ticStart = tic; end
            if nargin < 5 || isempty(frameElapsedTimeStamps)
                frameInds = (1:nFrames)'; frameDur = 1/app.vObj.FrameRate;
                frameElapsedTimeStamps = (frameInds-1) .* frameDur;
            end
            status = 0; timeElapsedTolerance = 0.3;
            T = zeros(nFrames,1); 
            skippedFrameCount = 0;
 
            for iFrame = StartFrame : EndFrame
                % If user stopped:
                if app.Bn_StopTrack.Value 
                    app.Bn_StopTrack.Value = false; %reset the button state
                    app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false;
                    status = 2; % status = 0 : error;  1: success;  2: user stopped
                    EndFrame = iFrame -1;
                    dispFrame(app, iFrame); updatePlotAnchors(app); 
                    plotUpdateAudioCursor(app, app.Ed_Time.Value); 
                    SetStatus(app, 'ready');   break
                end % Bn_StopTrack
 
                frameInd = iFrame - StartFrame +1;

                im= app.Read1Frame(app.vObj, iFrame);         
                if app.ifInvert
                    if size(im, 3) > 1, im = rgb2gray(im);   end
                    colormap(app.Ax0, 'gray');
                    im = imcomplement(im);
                end            
                app.Ed_Frame.Value = iFrame;    frameTime = (iFrame-1)/app.vObj.FrameRate;
                app.Ed_Time.Value = frameTime;%   
                
                T(frameInd) = toc(ticStart); 
                pauseTime = frameElapsedTimeStamps(frameInd) - T(frameInd); 
                if (T(frameInd) - frameElapsedTimeStamps(frameInd)) > timeElapsedTolerance
                    skippedFrameCount = skippedFrameCount +1; continue;
                end    
                if iFrame > 1 && iFrame < EndFrame && pauseTime > 0 , pause(pauseTime); end 
                app.CurrentImgObj.CData = im; 
                app.handle_AudioCursor.XData = [frameTime, frameTime]; 
                if ifShowAnchors, updatePlotAnchorsFast(app); end
            end
            % tEnd = toc(ticStart);
            dispFrame(app, StartFrame); updatePlotAnchorsFast(app); 
            plotUpdateAudioCursor(app, startTime, endTime); 
            totalDur = frameElapsedTimeStamps(end); playedFPS = (nFrames - skippedFrameCount) / totalDur;
            fprintf('FRAMES: Played=%d;Skipped=%d;Duration=%0.3g(s);DisplayFPS=%0.3f\n', nFrames, skippedFrameCount, totalDur, playedFPS); 
            if status~=2; status = 1;end
            
            % lag = T - frameElapsedTimeStamps;
            % nLag = sum(lag > timeElapsedTolerance);
        end % PlayVideoFrames
        %%
        function ChangeUserGroup(app, user_group)
            app.UserGroup = user_group;
            if strcmpi(user_group, 'admin')
              app.AdminMenu.Visible = "on";
            end
        end % ChangeUserGroup
    end % method (Access=private)
    
    %% Below are supporting functions
    %% Call by app.FUNCTIONNAME
    methods (Access = public, Static=true)
        %%
        function [len, dist] = LineLength(input_line)
            % This function calculates the length of a line by using Euclidean distance. 
            % 'input_line':  n(Points) x m (dimensions) matrix for the coordinates of the points of the input line.
            %  'len' : length of input_line   %  'dist' : distances of each segment along the line
            d = diff(input_line); dist = sqrt(sum(d.^2,2)); len =sum(dist);
        end %LineLength
        % 
        function handle = updateXYinPlotRect(handle, p2)
            % Update the 'XData', and 'YData' in the handle of plotted object.
            % p1, p2 : [x,y] coordinates;  
            x1 = handle.XData(1); y1 = handle.YData(1);   x2 = p2(1); y2 = p2(2); 
            x = [x1, x1, x2, x2, x1];  y = [y1, y2, y2, y1, y1]; handle.XData = x;  handle.YData = y;
        end % updateXYinPlotRect(h, p2)
        function handle = updateXYinMppLine(handle, p2)
            % Update the 'XData', and 'YData' in the handle of plotted object.
            % p1, p2 : [x,y] coordinates;  
            if isempty(handle) || isempty(p2), return; end
            x1 = handle.XData(1); y1 = handle.YData(1); y2 = p2(2);  %x2 = p2(1); 
            x = [x1, x1];  y = [y1, y2]; 
            handle.XData = x;  handle.YData = y;
        end % updateXYinMppLine(h, p2)
%      %
        function hg = DisplayMaskBoundary(ax, MaskOrBlobs, color1, color2, edgealpha)
            if nargin<3 || isempty(color1), color1 = 'w'; end
            if nargin<4 || isempty(color2), color2 = 'b'; end
            if nargin<5 || isempty(edgealpha), edgealpha = 0.3; end
            % nPts = 200; 
            hg = hggroup(ax);
            if isempty(MaskOrBlobs), return;end
            if isnumeric(MaskOrBlobs)
                blobs = bwboundaries(MaskOrBlobs); 
            elseif iscell(MaskOrBlobs)
                blobs = MaskOrBlobs;
            end
            nBlobs = numel(blobs);
            if nBlobs ==0, return; end
            for i = 1:nBlobs
                blob1 = blobs{i};
%                 b1 = interpLine_simple(b1, nPts, 'makima');
                x = blob1(:,2); y = blob1(:,1); 
                h1 = animatedline(ax, x, y, 'linewidth', 2, 'Marker', 'none', 'color', color1,'Parent',hg); h1.Color(4)= edgealpha; 
                h2 = animatedline(ax, x, y, 'linewidth', 1, 'Marker', 'none', 'color', color2,'Parent',hg); h2.Color(4)= edgealpha; 
            end
            % function OutputLine = interpLine_simple(InputLine, density, method)
            %     %  Simplified version of interpLine;  {See also interpLine.m} InputLine: n (points) x m (dimenstions) data points;  OutputLine: 'density' x m (dimenstions) data points
            %     d = sqrt(sum(diff(InputLine).^2,2)); cumDist = [0; cumsum(d)]; lineLength = cumDist(end); newCumDist = linspace(0, lineLength, density); OutputLine = interp1(cumDist, InputLine, newCumDist, method);
            % end %function [OutputLine, lineLength1] =interpLine_simple
        end % function hg = DisplayMaskBoundary
        %
        function [mask, bw] = GenMaskFromAnchors(anchors, sigma, w, h)
            % w : image width; h : image height; anchors : [x, y] coordinates
            % anchors must be sorted before calling this function
            nPts = 200;  
            xy = interpLine_simple(anchors,nPts, 'makima');%
            % Downsample then upsample to increase speed:
            s = 10; % downsample scale
            xy1 = xy /s; w1 = round(w /s); h1 = round(h /s);
            mask1 = xy2mask(xy1(:,1), xy1(:,2),  w1, h1, sigma); 
            mask2 = imresize(mask1, [h, w]);  mask = imgaussfilt(mask2,3); bw = mask>0.5;
            
            function mask = xy2mask(x, y,  w, h, sigma)
                % Convert [x,y] coordinates on an image to a mask with the same size of the image, using Gaussian kernels.
                % INPUT:  x : n x 1 x-axis coordinates;  y : n x 1 y-axis coordinates;  w : width;   h : height;  sigma : pixel to calculate Gaussian kernel.; OUTPUT:  mask:  h x w (0~1) mask 
                mask = zeros(h,w); sigSq2 = 2*sigma^2;
                for i =1:w,  for j = 1:h, intensity = sum(exp(-1 * (((i-x).^2 + (j-y).^2) ./ sigSq2)));  mask(j,i) = intensity; end; end
            end  %function mask = xy2mask(x, y,  w, h, sigma)
            function out = interpLine_simple(in, nOut, method) %in:nIn x mDims; out:nOut x mDims
                % InputLine = unique(InputLine, 'rows', 'stable'); n=size(InputLine,1);
                cumD = [0; cumsum(sqrt(sum(diff(in).^2,2)))];  out = interp1(cumD, in, linspace(0, cumD(end), nOut), method);
            end %function out =interpLine_simple
        end % function [mask, bw] = GenMaskFromAnchors  
        %
        function crop_rect = get_rect_from_plot_handle(h)
            % 'crop_rect' : [xmin ymin width height]  cropping rectangle  
            x = h.XData;  y = h.YData; p1x = min(x); p1y = min(y); 
            if p1x < 1, p1x = 1; end; if p1y < 1, p1y = 1; end
            p2x = max(x); p2y = max(y);
            crop_rect = round([p1x, p1y, p2x-p1x+1, p2y-p1y+1]);
        end % get_rect_from_plot_handle
        %
        function [anchors, selectedID] = AddOrSelectAnchor(anchors, pt, dist_epsilon)
            % anchors: n x 3 [x,y, isUser] coordinates of anchor points.
            % pt: [x,y] coordinates of clicked location.
            % dist_epsilon: distance tolerance to determine whether the current click coincides with existing anchors
            % If the clicked point 'pt' coincides with an existing point in 'anchors',
            % then the sequence number of the clicked point is returned as
            % 'selectedID', and the same 'anchors' will be returned. 
            %  anchors were sorted from left to right.
            selectedID = [];  pt1 = [pt, 1]; % the 3rd element denotes user input
            if nargin<3, dist_epsilon = 3; end
            if isempty(anchors), anchors = pt1; return; end
            d = sqrt(sum((anchors(:,1:2)-pt).^2,2));
            [sorted_d, ind ] = sort(d);
            d_min = sorted_d(1);
            if d_min < dist_epsilon, selectedID = ind(1); return; end
            anchors = [anchors; pt1];
         end % function [anchors, selectedID] =AddOrSelectAnchor(anchors, pt, dist_epsilon) 
        %
        function sortedAnchors = sortAnchors(anchors, ifAscend, ifDropLastEccentric)
            % Sort the anchor points (x,y coordinates) on ultrasound tongue contours. 
            % 'anchors' :  n x {x, y + other meta information} 
            % ' ifAscend'  set to true ->  sort points from left to right {default}
            %                     false ->  sort points from right to left
            % 'ifDropLastEccentric' :  Sometime a middle point is not
            % captured by nearest neighbor method; this will cause the last
            % point curling back long distance. If 'ifDropLastEccentric =
            % true', the last excursion will be evaluated and if it is too
            % far away, then drop the last point. 
            %  Weirong Chen    25DEC2022
            if nargin < 3 || isempty(ifDropLastEccentric), ifDropLastEccentric = false;end
            if nargin < 2 || isempty(ifAscend), ifAscend = true; end
            if nargin < 1 || isempty(anchors), anchors = load1var('anchors');end
            if isempty(anchors), sortedAnchors = [];return; end
            [~, ia,~] = unique(anchors(:,1:2),'rows', 'stable');
            anchors = anchors(ia,:);
            if size(anchors, 1) < 2, sortedAnchors = anchors; return; end
            x  = anchors(:,1); y = anchors(:,2); n = numel(x);
            xy = [x, y];
            lengthMat = NaN(n, 1);
            for startInd = 1:n
                 lengthMat(startInd) = LineLength(sortByMinDist(xy, startInd)); 
            end 
            [~, optInd] = min(lengthMat);  [~, orderInds] =sortByMinDist(xy, optInd); sortedAnchors = anchors(orderInds,:);
            % IF drop last point if the latest trip is too far:
             if ifDropLastEccentric
                 [~, dist] = LineLength(sortedAnchors);
                 medianDist = median(dist, 'omitnan');
                 if dist(end) > medianDist*3, sortedAnchors = sortedAnchors(1:end-1,:);end
             end %  if ifDropLastEccentric
            %% Set the direction of sorting:
            if ifAscend
                if  sortedAnchors(1,1) > sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            else
                if  sortedAnchors(1,1) < sortedAnchors(end,1), sortedAnchors=flipud(sortedAnchors);end
            end
            function [sortedAnchors,orderInds] = sortByMinDist(anchors, startInd)
                sortedAnchors = NaN(size(anchors)); tmpAnchors = anchors;  nn = size(anchors,1); orderInds = NaN(nn,1);
                pt = anchors(startInd,1:2); tmpAnchors(startInd,1:2) =[inf, inf]; sortedAnchors(1,:) = pt; orderInds(1) = startInd;
                for i = 2:nn
                    d = sqrt(sum((tmpAnchors(:,1:2)-pt(:,1:2)).^2,2));
                    [~, Ind] = min(d); pt = tmpAnchors(Ind,:);  sortedAnchors(i,:) = pt; 
                    tmpAnchors(Ind,1:2) = [inf, inf];  orderInds(i) = Ind;
                end
            end %sortByMinDist
            function [len, dist] = LineLength(input_line)
                d = diff(input_line); dist = sqrt(sum(d.^2,2)); len =sum(dist);
            end % LineLength 
        end %function sortedAnchors = sortAnchors(anchors, ifAscend)
        %%
        
        function im_uncropped = imUncrop(im_cropped, rect, im_original)
            % uncrop an image by padding zeros
            % rect : [xmin(left) ymin(top) width height]
            [h0, w0] = size(im_original); [h1, w1] = size(im_cropped); 
            ymin = rect(2); xmin = rect(1); topPad_h = ymin-1; leftPad_w = xmin-1;
            bottomPad_h = h0 - (h1+topPad_h); rightPad_w = w0 - (w1 +leftPad_w);
            topPad = zeros(topPad_h, w0); bottomPad = zeros(bottomPad_h, w0);
            leftPad = zeros(h1, leftPad_w); rightPad = zeros(h1,  rightPad_w);
            im_uncropped = [topPad; [leftPad, im_cropped, rightPad]; bottomPad];
        end %imUncrop
        %edit 
        function in = ifWithinPos(pt, pos)
            % check if the (x,y) position is within the region defined by 'pos' : left bottom width height
            x = pt(1); y = pt(2); [xv, yv] = pos2xy(pos); in = inpolygon(x,y, xv, yv);
                function [xv,yv]=pos2xy(p)
                    % Converting positional values 'pos' (left bottom width height) into 4 pairs of x-y coordinates.
                    % The output x-y pairs starts from lower left corner counterclockwisely. 
                    x1 = p(1); x2 = x1+p(3); x3 = x2; x4 = x1; y1 = p(2); y2 = y1; y3 = y1+p(4); y4 = y3;
                    xv = [x1; x2; x3; x4]; yv = [y1; y2; y3; y4];
                end %function [x,y]=pos2xy
        end %function in = ifWithinPos(pt, pos)  
        %
        function var=load1var(matfilename, varname)
            % Load 1 variable from a .mat file
            % If 'varname' is empty, load the first variable in the file.
            if ~exist(matfilename,'file'), var = []; return; end
            varlist = who('-file', matfilename);
            if nargin < 2 || isempty(varname), varname = varlist{1}; end
            ix = ismember(varlist, varname);
            if ~any(ix), var = []; return; end
            var = load(matfilename, varname); 
            %var = eval(['var.',varname,';']);	 %#ok<*NASGU>
            var = var.(varname);
        end % end of function var=load1var(matfilename, varname)
        %
        function yes = hasfield(struct, fieldname)
            yes = any(ismember( fieldnames(struct), fieldname));
        end % function x = hasfield(s, f)
        %
        function [OutputLine, lineLen] = interpLine(InputLine, density, method)
            %  Interpolation for a line.
            % InputLine: n (points) x m (dimenstions) data points
            % OutputLine: 'density' x m (dimenstions) data points
            % {'density' > n} 
            % 'method': interpolation method. default value = 'spline';
            % W. Chen   20JUL2021
            if nargin<3, method='spline';end; if nargin<2, density=50; end
            if isempty(InputLine), OutputLine = []; lineLen = []; return; end
            InputLine = unique(InputLine, 'rows', 'stable'); n=size(InputLine,1);
            if n == 1, OutputLine = repmat(InputLine, density,1);return;end
            cumDist = [0; cumsum(sqrt(sum(diff(InputLine).^2,2)))]; % cumulative distances along the line
            OutputLine = interp1(cumDist, InputLine,linspace(0, cumDist(end), density), method);
            d1 = sqrt(sum(diff(OutputLine).^2,2)); newCumDist1 = [0; cumsum(d1)]; lineLen = newCumDist1(end);
        end %function [OutputLine, lineLen] =interpLine
        %
        function [s, sr, Dur] = ImportAudio_simple(input, resampleRate)
            if nargin<2 || resampleRate==0,  resampleRate = []; end
            [s,sr] = audioread(input); 
            if size(s,2)>1, s = mean(s(:,1:2),2); end 
            s = (s - mean(s)); s = s ./ max(abs(s)); Dur = length(s) / sr; 
            if ~isempty(resampleRate) && sr~=resampleRate,  s = resample(s,resampleRate,sr); sr = resampleRate; end
        end % function ImportAudio_simple
        %%
        function [TextGridStruct,tierNames,TierStartLineNum, IsPointTier]= ReadTextGrid(tgFName, whichTier)
        % This function reads TextGrid file and returns a struct of TextGrid.
        % INPUT: 
        %      'tgFName' :  TextGrid filename.
        %      'whichTier' :  Specify which tier will be parsed and returned. Leave blank to output all tiers.  
        % Output : 
        %   'TextGridStruct' : TextGrid structure with the following format: 
        %       TextGrid(i).NAME : tier name
        %       TextGrid(i).segs :
        %            n Intervals x 2 ([StartTime EndTime]) matrix, if  this tier is interval tier; 
        %            n Intervals x 1 ([Time]) array, if  this tier is point tier; 
        %       TextGrid(i).labs=labs : n Intervals x 1 cell array
        %       TextGrid(i).IsPointTier= 0 : if this tier is interval tier; 
        %                                         1 : if this tier is point tier; 
        % 
        % Wei-rong Chen  Apr-19-2016
        % Update:  Aug-1-2018:  Fix the issue of double quotation marks. 
        %                      double quotation " is stored as duplication: ""
        %                      in Praat TextGrid internally. 
        %                Oct-2021: Added tier selection
        %                Mar-2022: Embeded supporting functions within the main function. 
        
        [~,~,e]=fileparts(tgFName); 
        if isempty(e), tgFName=[tgFName '.TextGrid'];end
        if ~exist(tgFName, 'file'), error('File NOT exist!');end
        [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(tgFName); 
        [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines);
        if nargin < 2 || isempty(whichTier)
            tierInd = 1:numel(tierNames);
        elseif isnumeric(whichTier)
            tierInd = whichTier;
        elseif ischar(whichTier) 
            tierInd = find(ismember(tierNames, whichTier));
        elseif iscell(whichTier)
            j = 1;
            for i = 1:numel(whichTier)
                k = find(ismember(tierNames, whichTier{i}));
                if isempty(k),  continue; end
                tierInd(j) = k;  j = j+1;
            end
        else 
            tierInd = 1:numel(tierNames);
        end
        % Check if 'whichTier' doesn't exist in the TextGrid
        totalTierInds = 1:numel(tierNames);
        if isempty(tierInd) || any(~ismember(tierInd, totalTierInds))
            error('Tier NOT exist!');
        end
        k = 1; 
        for i=tierInd
            tierName=tierNames{i};
            isPoint = IsPointTier(i); tierStart = TierStartLineNum(i);
            [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart);
            TextGridStruct(k).NAME=tierName;TextGridStruct(k).segs=segs; %#ok<*AGROW>
            TextGridStruct(k).labs=labs; TextGridStruct(k).IsPointTier=isPoint;
            k = k+1;
        end
        %% Supporting sub-functions:
                %% 
                function [segs, labs]=ParseOneTier(lines, isShort, isPoint, tierStart)
                    interval_size_line = lines{tierStart+3}; 
                    ind_size = regexp(interval_size_line,'\d+'); 
                    nSegs = str2double(interval_size_line(ind_size:end));
                    M = 4 - isShort - isPoint;
                    StartLineNum = tierStart + 4;  EndLineNum = nSegs*M + StartLineNum - 1;
                    nIntervals = (EndLineNum - StartLineNum + 1) / M;
                    items = reshape(lines(StartLineNum:EndLineNum),M,nIntervals)';
                    labs = items(:,M);  kk = cellfun(@strfind, labs,repmat({'"'}, length(labs),1), 'UniformOutput',false);
                    idx1 = num2cell(cellfun(@(x) x(1), kk)+1); idx2 = num2cell(cellfun(@(x) x(end), kk)-1);
                    labs = cellfun(@(x, a,b) x(a:b), labs, idx1, idx2, 'UniformOutput',false);
                    for ilab = 1:numel(labs)
                        lab = labs{ilab};  labs{ilab} = strrep(lab, '""', '"');
                    end
                    lines1 = items(:,2-isShort:(M-1));
                    segs = cellfun(@str2double,regexprep(lines1, '[^0-9.]', ''));
                end %ParseOneTier
                %%
                function [lines, isShort, startTime, totalDur, nTiers] = ReadValidateDataProc(fName)
                    encoding = DetectTextGridEncoding(fName);
                    try
                        lines = txt2cell(fName, encoding);
                    catch
                        error('error attempting to load from %s', fName);
                    end
                    lines=DetectStrainedQuotationSybol(lines);
                    % format checking
                    if length(lines)<15 || isempty(strfind(lines{1},'ooTextFile')) || isempty(strfind(lines{2},'"TextGrid"')) || isempty(strfind(lines{6},'exists'))    
                        error('%s : unrecognized file format', fName);
                    end
                    [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(lines);
                end %ReadValidateDataProc
                %%
                function [tierNames,TierStartLineNum, IsPointTier] = GetTierNamesAndTypes(lines)
                % get tier names and types
                    pointTierNames = {};pointTiers=[];
                    intTierNames = {};intTiers =[];   
	                ind1 = find(~cellfun(@isempty,regexp(lines,'TextTier')));
                    if ~isempty(ind1)
		                pointTiers = ind1 + 1;  TierNameLines = lines(pointTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        pointTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    
	                ind1 = find(~cellfun(@isempty,regexp(lines,'IntervalTier')));
                    if ~isempty(ind1)
		                intTiers = ind1 + 1; TierNameLines = lines(intTiers);
                        kk = cellfun(@strfind,TierNameLines,repmat({'"'}, length(TierNameLines),1), 'UniformOutput',false);
                        idx1 = cellfun(@(x) x(1), kk); idx2 = cellfun(@(x) x(end), kk);
                        intTierNames = cellfun(@(x, a,b) x(a:b), TierNameLines,  num2cell(idx1+1), num2cell(idx2-1), 'UniformOutput',false);
                    end
                    tierNames = [pointTierNames;intTierNames];   TierStartLineNum = [pointTiers;intTiers]; 
                    IsPointTier = [true(size(pointTiers)); false(size(intTiers))]; 
                    [~, TierOrder]=sort(TierStartLineNum); tierNames = tierNames(TierOrder); 
                    TierStartLineNum = TierStartLineNum(TierOrder);  IsPointTier = IsPointTier(TierOrder);
                end %GetTierNamesAndTypes
                %%
                function [isShort, startTime, totalDur, nTiers]=DetectTextGridCellsLongOrShort(TGlineCells)
                try
                    metaData = TGlineCells([4 5 7]);
                    idx1 = ~isempty(strfind(lower(metaData{1}),'xmin')); %#ok<*STREMP>
                    idx2 = ~isempty(strfind(lower(metaData{2}),'xmax'));
                    idx3 = ~isempty(strfind(lower(metaData{3}),'size'));
                    if idx1 && idx2 && idx3
                        isShort = 0;
                        data =str2double(regexprep(metaData,'(XMIN|XMAX|SIZE|xmin|xmax|size|=| )',''));
                    elseif ~idx1 && ~idx2 && ~idx3
                        isShort = 1; data = str2num(char(metaData)); %#ok<ST2NM>
                %         data = cellfun(@str2double, metaData);
                    else 
                        error('%s has unrecognized file format', fName);
                    end
                catch
	                error('%s has unrecognized file format', fName);
                end
                startTime = data(1); totalDur = data(2); nTiers = data(3);
                end % end of DetectTextGridCellsLongOrShort
                
                function outTextGridLines=DetectStrainedQuotationSybol(TextGridLines)
                    outTextGridLines = TextGridLines;ToDelete=[];
                    for iii = 1:length(TextGridLines)-1
                        thisLine = TextGridLines{iii};nextLine = TextGridLines{iii+1};
                        thisLine = strrep(thisLine,' ','');nextLine = strrep(nextLine,' ','');
                        if strcmp(thisLine,'text="') && strcmp(nextLine(end),'"')
                            ToDelete = [ToDelete iii+1];  outTextGridLines{iii} = [TextGridLines{iii} TextGridLines{iii+1}];
                        end
                    end
                    outTextGridLines(ToDelete) = [];
                end %DetectStrainedQuotationSybol2
                %%
                function encoding = DetectTextGridEncoding(TextGridFName)
                % Detect the text encoding method of a PRAAT .TextGrid file.
                % Usage: encoding = DetectTextGridEncoding(TextGridFName)
                    [~,~,ext]=fileparts(TextGridFName);
                    if isempty(ext), TextGridFName=[TextGridFName '.TextGrid'];end
                    encodings{1}='UTF-8'; encodings{2}='UTF-16BE'; encodings{3}='UTF-16LE';
                    encodingWeight=NaN*zeros(1,length(encodings));
                    wid='MATLAB:iofun:UnsupportedEncoding';
                    warning('off',wid);
                    for jjj=1:length(encodings)
                        fid = fopen(TextGridFName, 'r', 'l', encodings{jjj});
                        S = fscanf(fid, '%c'); fclose(fid);
                        out = strfind(S, 'Text'); encodingWeight(jjj)=length(out);
                    end
                    [~,idx]=max(encodingWeight);
                    encoding=encodings{idx};
                    warning('on',wid);
                end %DetectTextGridEncoding
                %%
                function lines = txt2cell(filename, encoding)
                    % Read text file into cell array. Each line is stored in one cell in a cell array.
                    % Requires MATLAB R2016b or newer. 
                    % W. Chen   APR-24-2021
                    if ~exist(filename, 'file'), lines = {}; fprintf('txt2cell: file not found.\n'); return; end
                    permission = 'r'; % read file in text mode. See 'help fopen'.
                    machinefmt = 'n'; % reading or writing in bytes or bits. 'n' = 'native' {default}
                    if nargin < 2 || isempty(encoding)
                        % if encoding is not specified, fopen will automatically detect the characterset set. 
                        fid = fopen(filename,permission, machinefmt);
                    else
                        fid = fopen(filename,permission, machinefmt, encoding);
                    end
                    s = fscanf(fid, '%c'); fclose(fid);
                    % if the first character is BOM, then remove it:
                    if unicode2native(s(1))==26, s = s(2:end);end 
                    % 'splitlines' is a new funciton since R2016b, which handles different EOLs.
                    lines = splitlines(s); 
                end  % end of  txt2cell
                %%
        end %ReadTextGrid()
         %% 
        function d = udist(pt1,pt2)
            % Calculate the Euclidean distance from pt2 (one or more points) to pt1 (multiple points)
            %   If pt2 = 1 x n (dims) and pt1 = m (points) x n (dims), output 'd' is the distances from pt2 to each elements in pt1.
            %   If both pt1 and pt2 are m (points) x n (dims), 'd' is an array of the point-to-point distance for each pair of [pt1i, pt2i]. 
            % INPUT:  pt1, pt2 : m (points) x n (dimensions) pt1 must be m x n matrix; pt2 can be either 1 x n vector or m x n matrix.    
            % OUTPUT: d : m (points) x 1 vector
            nPointsPt2=size(pt2,1); nPointsPt1=size(pt1,1); dimsPt1=size(pt1,2); dimsPt2=size(pt2,2);        
            if dimsPt1~=dimsPt2, error('dimensions not match!');end % if dimsPt1~=dimsPt2, return d=0.
            if nPointsPt2==nPointsPt1, d=sqrt(sum((pt1-pt2).^2,2)); elseif nPointsPt2==1 && nPointsPt1>1, arr1=pt1; arr2=repmat(pt2,nPointsPt1,1);  d=sqrt(sum((arr1-arr2).^2,2)); end
        end % udist
        function [indArr, distArr] = FindClosest(RefPts, TestPts)
            %    Find the index of the closest element in 'RefPts' for all elements in 'TestPts'. 
            % INPUT:   RefPts : m (points) x n (dimensions) ;  TestPts: k (points) x n (dimensions)
            % OUTPUT: indArr: k x 1  indices in 'RefPts';    distArr: k x 1 distances of each element in TestPts to RefPts.     
            k = size(TestPts,1);indArr = NaN * zeros(k,1); distArr = NaN * zeros(k,1);
            for i = 1:k, p = TestPts(i,:); dists=udist(RefPts, p); [minDist, ind]= min(dists); indArr(i) = ind;  distArr(i) = minDist; end
                    function d = udist(pt1,pt2)
                        nPointsPt2=size(pt2,1); nPointsPt1=size(pt1,1); dimsPt1=size(pt1,2); dimsPt2=size(pt2,2);        
                        if dimsPt1~=dimsPt2, error('dimensions not match!');end % if dimsPt1~=dimsPt2, return d=0.
                        if nPointsPt2==nPointsPt1, d=sqrt(sum((pt1-pt2).^2,2)); elseif nPointsPt2==1 && nPointsPt1>1, arr1=pt1; arr2=repmat(pt2,nPointsPt1,1);  d=sqrt(sum((arr1-arr2).^2,2)); end
                    end % udist
        end %FindClosest
         %%
        function roughness = CalcSnakeRoughness(xy)
            %% Calculate roughness(1-smoothness) in SNAKE (active contour) algorithm 
            %    defined by Akgul & Kambhamettu (1999) 
            %    roughness ranges between 0 (least rough) to 1 (most rough)
                d=sqrt(sum((xy(1:end-1,:)-xy(2:end,:)).^2,2)); v = xy(2:end,:) - xy(1:end-1,:); 
                roughness = 1 - dot(v(1:end-1,:), v(2:end,:), 2) ./ (d(1:end-1) .* d(2:end))  ;
        end% CalcSnakeRoughness
        %%
        function [diag, diagnosticVariables] = DiagnoseAnchors(ANCHORS, ENERGY)
            %% Calculate diagnostic matrix for anchors. 
            % INPUT: 
            %    ANCHORS: nFrames x1 cell. Each cell contains N x 2 (x,y) anchor coordinates.
            %    ENERGY: nFrames x1 cell. Each cell contains N x 1 SNAKE energy
            % OUTPUT: 
            %       diag:  nFrames x 5 matrix. 
            %          Each row contains SNAKE energy: mean, max, Q3,
            %          roughness, contour length and distance to adjacent
            %          frames (IFDist)
            % WR Chen   26DEC2022
            diagnosticVariables = {'MeanEnergy', 'MaxEnergy', 'Q3Energy', 'Roughness', 'Length', 'IFDist'};
                n = numel(ANCHORS); diag = NaN(n, numel(diagnosticVariables)); ifd = nan(n, 1);
                for i = 1:n
                    en = ENERGY{i}; 
                    if isempty(en), continue;end
                    if sum(~isnan(en), 'all') < 3 || ~isnumeric(en) || length(en) < 3, continue;end
                    enMean = mean(en, 'omitnan'); enMax = max(en);   q3 = quantile(en, 0.75);
                    mat = [enMean, enMax, q3]; diag(i, 1:numel(mat)) = mat;
                end
                for i = 1:n
                    anc = ANCHORS{i};   if isempty(anc), continue;end
                    anc = anc(:,1:2); diag(i, ismember(diagnosticVariables , 'Length')) = LineLength(anc);
                    if sum(~any(isnan(anc),2))<3 || ~isnumeric(anc) || length(anc) < 3, continue;end
                    roughness = mean(CalcSnakeRoughness(anc), 'omitnan'); diag(i, 4) = roughness;
                end
                for i = 1:n
                    anc = ANCHORS{i};  if isempty(anc), continue;end
                    if i==1, preAnc =  anc; else, preAnc = ANCHORS{i-1};end
                    if i==n, nextAnc =  anc; else, nextAnc = ANCHORS{i+1};end
                    if isempty(preAnc), preAnc = anc; end; if isempty(nextAnc), nextAnc = anc; end
                    anc = anc(:,1:2); preAnc = preAnc(:,1:2); nextAnc = nextAnc(:,1:2);
                    dd1 = two_lines_dist(anc, preAnc); dd2  = two_lines_dist(anc, nextAnc);
                    ifd(i) = mean([dd1, dd2]);
                end
                diag(:, ismember(diagnosticVariables ,  'IFDist')) = ifd;
                 function roughness = CalcSnakeRoughness(xy)
                    %% Calculate roughness(1-smoothness) in SNAKE (active contour) algorithm 
                    %    defined by Akgul & Kambhamettu (1999) 
                    %    roughness ranges between 0 (least rough) to 1 (most rough)
                    d=sqrt(sum((xy(1:end-1,:)-xy(2:end,:)).^2,2)); v = xy(2:end,:) - xy(1:end-1,:); 
                    roughness = 1 - dot(v(1:end-1,:), v(2:end,:), 2) ./ (d(1:end-1) .* d(2:end))  ;
                end% CalcSnakeRoughness
                function [len, dist] = LineLength(input_line)
                    d = diff(input_line); dist = sqrt(sum(d.^2,2)); len =sum(dist);
                end % LineLength 
                function [dist, i1, i2] = two_lines_dist(a, b)
                    % This function calculates the distance between 'a' and 'b' are two lines/curves.
                    % a: m1 points x n1 dims;  b: m2 points x n2 dims;  n1 must be equal to n2  (n1 == n2)
                    [m1,n1] = size(a); [m2, n2]= size(b); if n1~=n2, dist = []; return; end
                    %% Vectorized method:
                    b2 = b.^2; a2 = a.^2; sb2 = sum(b2,2); sa2 = sum(a2,2); SB2 = ones(m1,1)*sb2'; SA2 = sa2*ones(1,m2); AB = a*b';
                    dm1 = real(sqrt(SB2 + SA2 - 2*AB)); [d1, i1] = min(dm1,[],2); [d2,i2] = min(dm1,[],1); i2 = i2';
                    md1 = mean(d1, 'omitnan'); md2 = mean(d2, 'omitnan'); dist = mean([md1,md2]);
                end  % two_lines_dist
        end % DiagnoseAnchors
        function IFDist = CalcInterFrameDistance(ANCHORS, iFrame)
            % Calculate Inter-frame distance (IFDist): Mean distance from the contour (anchors) of the
            % current frame (iFrame) to those in the adjacent frames (preceding and following frames). 
            anc = ANCHORS{iFrame};  if isempty(anc), IFDist=NaN; return;end
            n = numel(ANCHORS);
            if iFrame==1, preAnc =  anc; else, preAnc = ANCHORS{iFrame-1};end
            if iFrame==n, nextAnc =  anc; else, nextAnc = ANCHORS{iFrame+1};end
            if isempty(preAnc), preAnc = anc; end; if isempty(nextAnc), nextAnc = anc; end
            anc = anc(:,1:2); preAnc = preAnc(:,1:2); nextAnc = nextAnc(:,1:2);
            dd1 = two_lines_dist(anc, preAnc); dd2  = two_lines_dist(anc, nextAnc);
            IFDist = mean([dd1, dd2]);
            function [dist, i1, i2] = two_lines_dist(a, b)
                % This function calculates the distance between 'a' and 'b' are two lines/curves.
                % a: m1 points x n1 dims;  b: m2 points x n2 dims;  n1 must be equal to n2  (n1 == n2)
                [m1,n1] = size(a); [m2, n2]= size(b); if n1~=n2, dist = []; return; end
                %% Vectorized method:
                b2 = b.^2; a2 = a.^2; sb2 = sum(b2,2); sa2 = sum(a2,2); SB2 = ones(m1,1)*sb2'; SA2 = sa2*ones(1,m2); AB = a*b';
                dm1 = real(sqrt(SB2 + SA2 - 2*AB)); [d1, i1] = min(dm1,[],2); [d2,i2] = min(dm1,[],1); i2 = i2';
                md1 = mean(d1, 'omitnan'); md2 = mean(d2, 'omitnan'); dist = mean([md1,md2]);
            end  % two_lines_dist
        end %CalcInterFrameDistance
        %%
        function Par = CircleFitByPratt(XY)
        %--------------------------------------------------------------------------
        %     Circle fit by Pratt
        %      V. Pratt, "Direct least-squares fitting of algebraic surfaces",
        %      Computer Graphics, Vol. 21, pages 145-152 (1987)
        %     Input:  XY(n,2) is the array of coordinates of n points x(i)=XY(i,1), y(i)=XY(i,2)
        %     Output: Par = [a b R] is the fitting circle:
        %                           center (a,b) and radius R
        %     Note: this fit does not use built-in matrix functions (except "mean"),
        %           so it can be easily programmed in any programming language
        % This is a robust and accurate circle fit. It works well even if data
        % points are observed only within a small arc. This circle fit was proposed by V. Pratt in article "Direct least-squares fitting of algebraic surfaces", Computer Graphics, Vol. 21, pages 145-152 (1987). It is more stable than the simple Circle Fit by Kasa (file #5557).
        % Nikolai Chernov (2022). Circle Fit (Pratt method) (https://www.mathworks.com/matlabcentral/fileexchange/22643-circle-fit-pratt-method), MATLAB Central File Exchange. Retrieved December 28, 2022.
        %--------------------------------------------------------------------------
        n = size(XY,1);   centroid = mean(XY);   
        %     computing moments (note: all moments will be normed, i.e. divided by n)
        Mxx=0; Myy=0; Mxy=0; Mxz=0; Myz=0; Mzz=0;
        for i=1:n
            Xi = XY(i,1) - centroid(1);  Yi = XY(i,2) - centroid(2);  %  centering data
            Zi = Xi*Xi + Yi*Yi; Mxy = Mxy + Xi*Yi; Mxx = Mxx + Xi*Xi; Myy = Myy + Yi*Yi; Mxz = Mxz + Xi*Zi; Myz = Myz + Yi*Zi; Mzz = Mzz + Zi*Zi;
        end
        Mxx = Mxx/n; Myy = Myy/n; Mxy = Mxy/n; Mxz = Mxz/n; Myz = Myz/n; Mzz = Mzz/n;
        %    computing the coefficients of the characteristic polynomial
        Mz = Mxx + Myy; Cov_xy = Mxx*Myy - Mxy*Mxy;
        Mxz2 = Mxz*Mxz; Myz2 = Myz*Myz;
        A2 = 4*Cov_xy - 3*Mz*Mz - Mzz; A1 = Mzz*Mz + 4*Cov_xy*Mz - Mxz2 - Myz2 - Mz*Mz*Mz;
        A0 = Mxz2*Myy + Myz2*Mxx - Mzz*Cov_xy - 2*Mxz*Myz*Mxy + Mz*Mz*Cov_xy; A22 = A2 + A2;
        epsilon=1e-12;  ynew=1e+20; IterMax=20; xnew = 0;
        %    Newton's method starting at x=0
        for iter=1:IterMax
            yold = ynew; ynew = A0 + xnew*(A1 + xnew*(A2 + 4.*xnew*xnew));
            if (abs(ynew)>abs(yold)), disp('Newton-Pratt goes wrong direction: |ynew| > |yold|');  xnew = 0;  break;  end
            Dy = A1 + xnew*(A22 + 16*xnew*xnew); xold = xnew; xnew = xold - ynew/Dy;
            if (abs((xnew-xold)/xnew) < epsilon), break, end
            if (iter >= IterMax), 
%                 disp('Newton-Pratt will not converge');  
                xnew = 0; 
            end
            if (xnew<0.), 
%                 fprintf(1,'Newton-Pratt negative root:  x=%f\n',xnew); 
                xnew = 0; 
            end
        end
        %    computing the circle parameters
        DET = xnew*xnew - xnew*Mz + Cov_xy; Center = [Mxz*(Myy-xnew)-Myz*Mxy , Myz*(Mxx-xnew)-Mxz*Mxy]/DET/2; Par = [Center+centroid , sqrt(Center*Center'+Mz+2*xnew)];
        end    %    CircleFitByPratt
        %%
        function [success, msg, startTime, endTime, startFrame, endFrame, outputVideoFilename] = ...
            ExtractVideoByFrame(inputVideo, startFrame, endFrame, outputVideoFilename, ifWriteAudio, AUDIO, CropRect)
            % Extract video fragment from a video. 
            %  INPUT: 
            %     inputVideo : input video as filename or VideoReader object. 
            %     startFrame :  extraction starting frame number.
            %     endFrame :  extraction ending frame number.
            %     outputVideoFilename :  output video filename.
            %     ifWriteAudio :  if write (selected) audio as a separate .flac file
            %     AUDIO  :  Audio struct.  AUDIO.s = audio signals [nSamples x nChannels];  
            %               AUDIO.sr = sampling rate (Hz); 
            %              If AUDIO is empty, then it will use audioread function to read audio from video file.
            %     CropRect  : Cropping parameter [left, top, width, height]
            %                   If CropRext is empty (default) then no cropping is
            %                   applied.
            % app.Ax0.XLim = [rect(1),rect(1)+rect(3)-1]; app.Ax0.YLim = [rect(2),rect(2)+rect(4)-1];
            % WRC  30OCT2023
            if nargin < 7, CropRect = [];end
            if nargin < 5 || isempty(ifWriteAudio), ifWriteAudio = true; end
            minFrames = 5; 
            success = 0; %msg = '';
            if ischar(inputVideo) 
                try
                    v1 = VideoReader(inputVideo);
                catch
                    msg = 'Video file not readable!\n'; fprintf(msg); return
                end
            elseif strcmpi(class(inputVideo), 'VideoReader')
                v1 = inputVideo;
            elseif isstruct(inputVideo)
                v1 = inputVideo;
            else
                  msg = 'Video file not readable!\n'; fprintf(msg); return
            end
            videoFilename = v1.Name;  [~, vfn, vExt] = fileparts(videoFilename); 
            outVideoExt = '.mp4';  fps = v1.FrameRate; 
            if startFrame < 1, startFrame = 1; end
            if endFrame > v1.NumFrames, endFrame = v1.NumFrames; end
            startTime = (startFrame -1) / fps; endTime = (endFrame -1) / fps;  nFrames = endFrame - startFrame +1; 
            if nFrames < minFrames ,  msg = 'Too less frames selected!\n'; fprintf(msg); return;  end
            if nargin < 4 || isempty(outputVideoFilename)
                outputVideoFilename = sprintf('%s_Frame%d-%d%s', vfn, startFrame, endFrame, outVideoExt);
                outputVideoFilename = fullfile(v1.Path, outputVideoFilename);
            end
            outputVideoFilename = NextAvailableFileName(outputVideoFilename); 
            try
                v2 = VideoWriter(outputVideoFilename, 'MPEG-4'); v2.FrameRate = fps; open(v2);
                for ii = startFrame:endFrame
                    img = Read1Frame(v1, ii);                     
                    if ~isempty(CropRect)
                        img = myimcrop(img, CropRect);% (inclusive)
                    end
                    writeVideo(v2, img); 
                end
            catch
                close(v2);
            end
            [outPath, vfn, ~] = fileparts(outputVideoFilename); 
            close(v2);  success = 1; msg = sprintf('Wrote: %s', vfn);
            if ~ifWriteAudio, return; end
            % if write audio: 
            try
                if nargin < 6 || isempty(AUDIO)
                         [audSig, sr] = audioread(fullfile(v1.Path, videoFilename)); 
                else 
                    audSig = AUDIO.s; sr = AUDIO.sr;
                end
                if size(audSig, 2) > 1, audSig = mean(audSig, 2); end
                nAudSig = size(audSig,1); outAudioExt = '.flac';
                outputAudioFilename = fullfile(outPath, [vfn outAudioExt]); 
                n1 = floor(startTime * sr) + 1; n2 = floor(endTime * sr)+1;
                if n2 > nAudSig, n2 = nAudSig;end
                if n1 > nAudSig, return; end
                audSig1 = audSig(n1:n2,:); audiowrite(outputAudioFilename, audSig1, sr);
            catch
                 msg = 'Failed to write audio!\n'; fprintf(msg); success = 2; return
            end
            if hasFFmpegInstalled
                try
                    [tmpPath, videofn, ~] = fileparts(outputVideoFilename);  tmpVideoFName = fullfile(tmpPath, [videofn '_tmp.mp4']);
                    ffmpegCombineVideoAudio_simple(outputVideoFilename, outputAudioFilename, tmpVideoFName);
                    if exist(tmpVideoFName, 'file')
                        [copy_success, message, messageid] = copyfile(tmpVideoFName, outputVideoFilename, 'f');
                        delete(outputAudioFilename); delete(tmpVideoFName);
                    end
                catch
                      if exist(tmpVideoFName, 'file'), delete(tmpVideoFName); end                  
                end
            end % if hasFFmpegInstalled
            %%
            function nextFileName = NextAvailableFileName(filename)
                % Find next available filename.
                % Example: 
                % if 'abc.txt', 'abc(1).txt', 'abc(2).txt' exist, 
                % then myNextFileName('abc.txt') will return 'abc(3).txt'
                % W.Chen 06MAR2023
                if ~exist(filename, 'file'), nextFileName = filename; return; end
                [p, f, e] = fileparts(filename); 
                i = 1; upperLimit = 100;
                fn = sprintf('%s(%d)%s', f, i, e);
                nextFileName = fullfile(p, fn);
                while exist(nextFileName, 'file') && i < upperLimit
                    i = i+1; fn = sprintf('%s(%d)%s', f, i, e); nextFileName = fullfile(p, fn);  
                end
                if i >=upperLimit, nextFileName = []; end
            end % myNextFileName
            function [im, success] = Read1Frame(vObj, iFrame)
                success = false; im = [];   if isempty(vObj), return;end
                try 
                    if strcmpi(vObj.VideoFormat, 'ULT'), im = vObj.vData(:, :, iFrame); else,  im = read(vObj, iFrame);  end; success = true;
                catch, success = false;   
                end
            end %[im, success] = Read1Frame(vObj, iFrame)  
            %%
            function out = hasFFmpegInstalled()
               ffmpegExe = 'ffmpeg';  [status,cmdout] = system(ffmpegExe); if contains(cmdout, 'version'), out = true; else,  out= false; end               
            end % hasFFmpegInstalled
            %%
            function ffmpegCombineVideoAudio_simple(videoFName, audioFName, outFName)
            % Combine video and audio using FFMPEG. REQUIRE: FFMPEG installed & added to PATH.  WR Chen   23NOV2023 
                vcodec = '-c:v libx264';  acodec = '-c:a copy';  preset ='-preset slow'; crf  = '-crf 23';  pixel_format  = '-pix_fmt yuv420p'; useShortest = ''; ffmpegExe = 'ffmpeg'; 
               command = sprintf('%s -i "%s" -i "%s" -map 0:v -map 1:a %s %s %s %s %s -strict -2 %s -y "%s"', ...
                   ffmpegExe, videoFName, audioFName, vcodec, pixel_format, preset, crf, acodec, useShortest, outFName);
               [status,cmdout] = system(command,'-echo');
            end % ffmpegCombineVideoAudio_simple
            function out = myimcrop(im, rect)
                % MyImCrop:  (inclusive) Crop image by 'rect'  [xmin(left), ymin(top), width, height];
                % WChen 21APR2023
                % [H, W, C] = size(im); out = [];
                x0 = rect(1); y0 = rect(2);   x1 = x0 + rect(3) -1; y1 = y0 + rect(4) -1;  out = im(y0:y1, x0:x1,:);
            end % myimcrop()
        end % Extract Video  by Frame        
        %%
        function [im, success] = Read1Frame(vObj, iFrame)
            success = false; im = [];   if isempty(vObj), return;end
            try
                if strcmpi(vObj.VideoFormat, 'ULT'), im = vObj.vData(:, :, iFrame); else,  im = read(vObj, iFrame);  end; success = true;
            catch, success = false;  
            end
        end %[im, success] = Read1Frame(vObj, iFrame)     
        %% 
        function [video, success] = ReadUlt(ultFName, rescale)
        % Reade UltraSuite raw ULT files. 
        %   (Inspired by Aciel Eshky's UltraSuite-Tools)
        %
        % INPUT: 
        %       ultFName : UltraSuite raw *.ult file. This function will also read
        %       .param and .wav of the same file name in the same folder. 
        %       rescale :  Rescale factor.  0.5 = reduce 50% of size. 2 = double the original size. 
        % OUTPUT: 
        %       video:  Structure of video:
        %                   video.vData :   [height, width, nFrame] matrix of video frames.
        %                   video.NumFrames : Total number of frames.
        %                   video.Duration: Total duration of video frames (sec).
        %                   video.FrameRate: Video frame rate
        %                   video.PixelsPerMm: Pixels per mm
        %                   video.Height; video.Width; video.Origin
        %                   video.Param.TimeInSecsOfFirstFrame
        %                   video.wavFName
        
            if nargin < 1 || isempty(ultFName), ultFName = 'sample';end
            if nargin < 2 || isempty(rescale), rescale = 1; end
            [vPath, f, ~] = fileparts(ultFName); 
            success = false; video = [];  ultfn = [f '.ult'];
            ultFName = fullfile(vPath, ultfn); paramFName = fullfile(vPath, [f '.param']); wavFName = fullfile(vPath, [f '.wav']);
            if ~exist(ultFName, 'file'), fprintf('.ult file not found!\n'); return; end
            if ~exist(paramFName, 'file'),  fprintf('.param file not found!\n'); return;end

            para = parseUltraSuiteParam(paramFName);

            %% Transform raw ult to images
            [ult, origin]= transult(ultFName, 'num_scanlines', para.NumVectors, ...
                'size_scanline', para.PixPerVector, 'angle', para.Angle,  'zero_offset', para.ZeroOffset,'pixels_per_mm',1/rescale);
            ult = uint8(ult);  nFrames = size(ult,3); 
            video.NumFrames = nFrames;
            video.FrameRate = para.FramesPerSec;
            video.Duration = (nFrames-1) / para.FramesPerSec;
            video.vData = ult; video.PixelsPerMm = para.PixelsPerMm * rescale;
            video.Height = size(ult,1); video.Width = size(ult,2);
            video.Name = ultfn; video.Path = vPath; video.VideoFormat = 'ULT';
            video.Origin = origin; video.Param = para;
            if exist(wavFName, 'file')
                video.wavFName = wavFName; 
            else
                video.wavFName = []; fprintf('%s.wav file not found!\n', f);  
            end
            %%
            success = true;
        %% 
            function param = parseUltraSuiteParam(paramFName)
                fid = fopen(paramFName,'rt'); strs = fscanf(fid, '%c'); fclose(fid);%
                strs = strrep(strs, ' ', ''); strCells = textscan(strs,'%s %f', 'delimiter', '='); 
                param = cell2struct(num2cell(strCells{2}), strCells{1});
            end %param = parseUltraSuiteParam(paramFName)

            function [transformed_ult, origin] = transult(ult, varargin)
            % transform_ultrasuite
            %  Transform raw ultrasound scanlines in UltraSuite format to tongue image on screen. 
            %     (Replication of 'transform_ultrasound' in Aciel Eshky's UltraSuite-Tools)
            %   
            % INPUT: 
            %       ult : raw scanlines in UltraSuite format:  
            %              'ult' can be either a string, specifying the file path and name of the .ult file, 
            %               or a 3D matrix of [size_scanline x num_scanlines x nFrames] 
            %    NAME, VALUE paired arguments: 
            %       'background_colour' : 0~255; Default: 0 = black
            %       'num_scanlines' : number of scanlines. Default: 63
            %       'size_scanline' :  pixels per scanline. Default: 412
            %       'angle' : angle per scanline (in radian). Default: 0.038
            %       'zero_offset' : length from origin to start of scanline (in pixel). Default: 51
            %       'pixels_per_mm' : scaling factor; rescale frame to the size of 1/pixels_per_mm. Default: 1 = no rescale. 
            % OUTPUT: 
            %       transformed_ult :  [height x width x nFrames]  3D image matrix 
            %
            %  W.R. Chen  01-AUG-2021
            
                if nargin<1 || isempty(ult), ult = 'sample.ult'; end
                background_colour =0; num_scanlines = 63; size_scanline = 412; angle = 0.038; zero_offset = 51; pixels_per_mm = 1;  %Default   
                % Input parser
                pars = inputParser;  addParameter(pars,'background_colour', background_colour); 
                addParameter(pars,'num_scanlines', num_scanlines); addParameter(pars,'size_scanline',size_scanline); 
                addParameter(pars,'angle',angle); addParameter(pars,'zero_offset',zero_offset); 
                addParameter(pars,'pixels_per_mm', pixels_per_mm);  parse(pars,varargin{:}); p = pars.Results;
                %%
                if ischar(ult), filename = ult;  fid = fopen(filename, 'rb'); ult = fread(fid, inf, 'uint8'); fclose(fid); end
                ult = uint8(ult);
                ult = reshape(ult, p.size_scanline, p.num_scanlines, []);
                numFrames = size(ult,3);
            %     width = sqrt(p.num_scanlines .^ 2 + p.size_scanline .^2) .* 2 + p.zero_offset;
            %     height = p.size_scanline + p.zero_offset * 1.5;
                r = p.size_scanline + p.zero_offset; % arm length;
                fieldView = p.angle * p.num_scanlines; % Prove field of view in radian
                width = r * sin(fieldView/2)*2 + 10;
                height = r + 10; h_offset = round(p.zero_offset * cos(fieldView/2) /  p.pixels_per_mm);
                output_shape = floor([width /  p.pixels_per_mm, height /  p.pixels_per_mm]); % for UltraSuite: [884, 488] 
                origin = floor([output_shape(1)/2,0]);
                [Xq, Yq] = get_cart2pol_coordinates_vectorised(output_shape, ...
                    'origin', origin, 'num_scanlines', p.num_scanlines, 'angle', p.angle, 'zero_offset', p.zero_offset, 'pixels_per_mm',  p.pixels_per_mm);
                Xq = Xq(h_offset:end,:); Yq = Yq(h_offset:end,:);
                transformed_ult = zeros(output_shape(2)- h_offset+1, output_shape(1), numFrames, 'uint8');
                [x, y]=meshgrid(1:p.num_scanlines, 1:p.size_scanline);
                for i = 1:numFrames
                    frame = single(ult(:,:,i));   trans = flipud(interp2(x, y,frame, Xq, Yq)); 
                    trans(isnan( trans)) = p.background_colour; transformed_ult(:,:,i) = trans;
                end
                origin(2) = round(width/2);
                %%
                function [Xq, Yq] = get_cart2pol_coordinates_vectorised(frame_shape, varargin)
                % Replication of the same function in Aciel Eshky's UltraSuite-Tools.
                % INPUT: 
                %      frame_shape: [width, height] (pixels). Shape of output frame. Default: [884, 488]
                %  NAME, VALUE paired arguments: 
                %     'origin' :  coordinate of origin (in pixel). Default : [442,0]
                %     'num_scanlines' : number of scanlines. Default: 63
                %     'angle' : angle per scanline (in radian). Default: 0.038
                %     'zero_offset' : length from origin to start of scanline (in pixel). Default: 51
                %     'pixels_per_mm' : scaling factor; rescale frame to the size of 1/pixels_per_mm. Default: 1 = no rescale. 
                % OUTPUT: 
                %     Xq: x coordinates_in_input
                %     Yq: y coordinates_in_input
                %
                    if nargin < 1 || isempty(frame_shape), frame_shape = [884, 488]; end
                    orig=[442, 0]; num_scanlin=63; ang=0.038; zero_offs=51; pixels_per_mm_=1; % Default parameters
                    % Input parser
                    pars_ = inputParser;addParameter(pars_,'origin',orig); addParameter(pars_,'num_scanlines',num_scanlin); 
                    addParameter(pars_,'angle',ang); addParameter(pars_,'zero_offset',zero_offs); 
                    addParameter(pars_,'pixels_per_mm', pixels_per_mm_);  parse(pars_,varargin{:}); pp = pars_.Results;
                    % Generate meshgrid:
                    [xx,yy]=meshgrid(1:frame_shape(1), 1:frame_shape(2));
                    x1 = xx - pp.origin(1); y1 =  yy - pp.origin(2); [th, r1] = cart2pol(x1, y1);
                    r1 = r1 .* pp.pixels_per_mm; cl = floor(pp.num_scanlines ./2);
                    Xq = cl - ((th - pi/2) ./ pp.angle);  Yq = r1 - pp.zero_offset;
                end %get_cart2pol_coordinates_vectorised
            end % transform_ultrasuite            
        end % ReadUlt(ultFName)
        %%
        function yes = isContourStruct(m)
            yes = true; if ~isstruct(m), yes = false; return; end
            if ~isfield(m, 'XY') || ~isfield(m,'ANCHORS') || ~isfield(m,'FRAME') || ~isfield(m,'TIME'), yes = false; return; end
        end % isContourStruct
        %%
        function xy = flipYcoordinatesInImage(xy, imageHeight)
            % Flip Y coordinate (upside down) in image
            % e.g., image height = 500; 
            % input y = [1, 10, 100, 200, 201, 500];  % normal image Y coordinate
            % output y = [500, 491, 401, 301, 300, 1]; % reversed image Y coordinate
            % Assume y dimension is the 2nd column of 'xy'
            if isempty(xy), return;end
            ydim = 2;  y = xy(:, ydim); y1 = (y .* -1) + imageHeight +1; xy(:,ydim) = y1;
        end %  flipYcoordinatesInImage
        %% 
        function rect = flipYCropParaRect(rect, imageHeight)
         % Flip the y coordinates of cropping parameters 'rect' in
         % upside-down image. 
         % rect : [left, top, width, height]
         y0 = rect(2); 
         y1 = (y0 * -1) + imageHeight  - rect(4) +1;
         rect(2) = y1;
        end % flipYCropParaRect
        %%

        function out = myimcrop(im, rect)
            % MyImCrop:  (inclusive) Crop image by 'rect'  [xmin(left), ymin(top), width, height];
            % WChen 21APR2023
            % [H, W, C] = size(im); out = [];
            x0 = rect(1); y0 = rect(2);   x1 = x0 + rect(3) -1; y1 = y0 + rect(4) -1;  out = im(y0:y1, x0:x1,:);
        end % myimcrop()
        %%
        function outTG = sliceTextGrid(inTG, startTime, endTime)
            % Slice TextGrid from StartTime to EndTime
            % W. Chen  21SEP2021
            nTiers = numel(inTG);
            outTG = inTG;
            % dur = t2-t1;
            for i = 1:nTiers
                tier = inTG(i); labs = tier.labs; segs = tier.segs;
                isPointTier = size(segs,2)==1;
                if isPointTier
                    ix = segs >= startTime & segs <= endTime;
                else
                    ix = segs(:,1) >= startTime & segs(:,2) <= endTime;
                end
                labs1 = labs(ix); segs1 = segs(ix,:) - startTime;
                outTG(i).labs = labs1; outTG(i).segs = segs1;
            end
        end % sliceTextGrid
        %%
        function WriteTextGrid(TG, outfilename,TotalDur)
            % Usage: WriteTextGrid(TG, outfilename, TotalDur)
            % Write TextTrid struct to TextGrid file.
            % % The extention '.TextGrid' will be automatically added to 'outfilename'.
            % --------------------------------------------------------------------------
            % Input: 
            %  'TG' : TextGrid structure with the following format: 
            %           TextGrid(i).NAME : tier name
            %           TextGrid(i).segs : 
            %                   If it's an interval tier, then:
            %                        TextGrid(i).segs : n Intervals x 2 ([StartTime EndTime]) matrix
            %                   If it's an interval tier, then:
            %                       TextGrid(i).segs : n Intervals x 1 (time) vector
            %           TextGrid(i).labs=labs : n Intervals x 1 cell array
            %           TextGrid(i).IsPointTier : true if this tier is a PointTier, otherwise false
            %           TextGrid(i).xmin :  Start time of the tier
            %           TextGrid(i).xmax : Ending time of the tier 
            %   'TotalDur' : (Optional) The duration of the whole audio signals. 
            % --------------------------------------------------------------------------
            % 
            % Weirong Chen     Jul-16-2013
            % Update:               Jul-28-2018, Nov-18-2020, Nov-22-2021, Dec-21-2022, Feb-1-2023
            % Latest: 15JUN2023 : replace 'hasfield' with 'isfield'
            
            if nargin<3 || isempty(TotalDur)
                TotalDur=findDurFromTextGridStruct(TG);
                if isnan(TotalDur)
                    [~, f, ~]=fileparts(outfilename);
                    fprintf([f '  : No "total duration" provided!\n']);
                    return
                end
            end
            TG = CheckTextGridIntegrity(TG, TotalDur);
            xmin = TG(1).xmin; xmax = TG(1).xmax;
            %------------------------------------------------------
            %% 
            [~,~,e]=fileparts(outfilename);
            if isempty(e), outfilename=[outfilename '.TextGrid']; end    
            
            nTiers=length(TG);
            
            fid=fopen(outfilename,'w', 'n','UTF-8');
            
            fprintf(fid,'File type = "ooTextFile"\r\n');
            fprintf(fid,'Object class = "TextGrid"\r\n');
            fprintf(fid,'\r\n');
            fprintf(fid,'xmin = %f\r\n', xmin);
            fprintf(fid,'xmax = %f\r\n', xmax);
            fprintf(fid,'tiers? <exists>\r\n');
            fprintf(fid,'size = %d\r\n', nTiers);
            fprintf(fid,'item []:\r\n'); 
            
            for i = 1:nTiers
                  tg1 = TG(i); tiername = tg1.NAME; segs = tg1.segs; labs = tg1.labs;
                  if ~isempty(tg1.IsPointTier)
                      isPointTier = tg1.IsPointTier; 
                  else
                      isPointTier = size(tg1.segs,2) < 2;
                  end
            
                 xmin = tg1.xmin; xmax = tg1.xmax;
                 labs = preprocessTGlabels(labs);
                 
                 if isPointTier, tierclass='TextTier'; else, tierclass='IntervalTier'; end
                fprintf(fid,'    item [%d]:\r\n', i);
                fprintf(fid,'        class = "%s"\r\n', tierclass);
                fprintf(fid,'        name = "%s"\r\n', tiername);
                fprintf(fid,'        xmin = %f\r\n', xmin);
                fprintf(fid,'        xmax = %f\r\n', xmax);
                if ~isPointTier % if isIntervalTier
                    nIntervals=size(segs,1);
                    if nIntervals == 0
                        % Write an empty interval tier:
                        fprintf(fid,'        intervals: size = %d\r\n', 1);
                        fprintf(fid,'        intervals [1]:\r\n');
                        fprintf(fid,'            xmin = %f\r\n', xmin);
                        fprintf(fid,'            xmax = %f\r\n', xmax);
                        fprintf(fid,'            text = ""\r\n');
                        continue;
                    end        
                    fprintf(fid,'        intervals: size = %d\r\n', nIntervals);
            
                    %---------------------------------------------------------------
                    % Write first interval separately because the first interval
                    % contains 'xmin=0' without decimal point.
                    label = labs{1}; 
                    fprintf(fid,'        intervals [1]:\r\n');
                    fprintf(fid,'            xmin = %f\r\n', xmin);
                    fprintf(fid,'            xmax = %f\r\n', segs(1,2));
                    fprintf(fid,'            text = "%s"\r\n', label);
                    %---------------------------------------------------------------
                    if nIntervals == 1, continue;end
                    for j=2:nIntervals  % loop-writing intervals starts from the second interval
                        label = labs{j}; 
                        fprintf(fid,'        intervals [%d]:\r\n', j);
                        fprintf(fid,'            xmin = %f\r\n', segs(j,1));
                        fprintf(fid,'            xmax = %f\r\n', segs(j,2));
                        fprintf(fid,'            text = "%s"\r\n', label);
                    end
                else % if isPointTier
                    nIntervals=numel(segs);
                    if nIntervals == 0
                        % Write an empty point tier:
                        fprintf(fid,'        points: size = %d\r\n', 0);
                        continue;
                    end    
                    fprintf(fid,'        points: size = %d\r\n', nIntervals);
                    if nIntervals == 0, continue;end
                    for j=1:nIntervals
                         label = labs{j}; 
                        fprintf(fid,'        points [%d]:\r\n', j);
                        fprintf(fid,'            number = %f\r\n', segs(j,1));
                        fprintf(fid,'            mark = "%s"\r\n', label);
                    end
                end % if isIntervaltier,
            end % for i = 1:nTiers
            fclose(fid); 
                %%
                function labs = preprocessTGlabels(labs)
                    % Replace double quotation mark (") with 2x double quotation mark ("") 
                    if isempty(labs), return;end
                    nlabs = numel(labs);
                    for pii = 1:nlabs
                        lab = labs{pii}; 
                        if isempty(lab), continue; end
                        lab = strrep(lab,'"','""'); % replace double quotation mark (") with 2x double quotation mark ("") 
                        labs{pii} = lab;
                    end
                end %preprocessTGlabels(labs)
                   
                function dur=findDurFromTextGridStruct(TG)
                    dur = NaN;
                    for fii = 1:length(TG)
                        if isfield(TG(fii), 'xmax')
                            if ~isempty(TG(fii).xmax)
                                dur = TG(fii).xmax; return; 
                            end
                        end
                        if size(TG(fii).segs,2)==2 % find interval tier
                            dur=TG(fii).segs(end); % the last timestamp is the total duration of the sound file.
                            return
                        end
                    end % for fii = 1:length(TextGridStruct)
                end % function dur=findDurFromTextGridStruct(TextGridStruct)
            
                function [outTG, errorFlag] = CheckTextGridIntegrity(TG, Dur)
                    % For each interval tier, check if the begining of each label coincides
                    % with the end of the previous label. 
                    % 
                    % xmin : Starting time of TextGrid. Default:  0 (sec.)
                    % xmax: Ending time of TextGrid. 
                    
                    tolerance = 0.0001;  % set interval tolerance = 0.0001 sec (0.1ms)
                    outTG = TG; 
                    errorFlag = 0; 
                    for cii = 1:numel(TG)
                        if isfield(TG(cii), 'IsPointTier'), isPoint = TG(cii).IsPointTier; else, isPoint = size(TG(cii).segs,2) ==1; end
                        if isfield(TG(cii), 'xmin'), xmin1 = TG(cii).xmin;  else, xmin1 = 0; end
                        if isfield(TG(cii), 'xmax'), xmax1 = TG(cii).xmax;  else, xmax1 = Dur; end
                        outTG(cii).xmin = xmin1; outTG(cii).xmax = xmax1; outTG(cii).IsPointTier = isPoint;
                        
                        if isPoint, continue; end  % if isPoint, skip checking this tier. 
                    
                        labs1 = TG(cii).labs; segs1 = TG(cii).segs; 
                        n = length(labs1); 
                        if n == 0, continue;end 
                        
                        if segs1(1) ~= xmin1 % if interval tier and this tier doesn't start from xmin
                            if segs1(1) < xmin1 % if it starts < xmin, then set segs(1,1) to xmin
                                segs1(1) = xmin1; 
                            elseif segs1(1)  > xmin1 % if it starts > xmin, add an empty interval starting from 0 to the beginning of the first label.
                                segs1 = [xmin1, segs1(1); segs1];   labs1 = [{''}; labs1];
                            end
                        end
                    
                    
                        if xmax1 - segs1(end) > tolerance
                            % if interval tier and this tier doesn't end in xmax, add an empty
                            % interval starting from the end of the last interval to xmax.
                            segs1 =  [segs1; segs1(end), xmax1];
                            labs1 = [labs1; {''}];
                        end
                    
                        idd = 1; 
                        while idd < length(labs1) && idd < 1000000 % avoid infinite loops
                            if segs1(idd,2) ~= segs1(idd+1,1)
                                if abs(segs1(idd,2) - segs1(idd+1,1)) <= tolerance
                                    m = nanmean([segs1(idd,2), segs1(idd+1,1)]); 
                                    segs1(idd,2) = m;  segs1(idd+1,1) = m;
                                elseif segs1(idd+1,1) - segs1(idd,2) > tolerance
                                    segs1 = [segs1(1:idd,:); segs1(idd,2) segs1(idd+1,1); segs1(idd+1:end,:)];
                                    labs1 = [labs1(1:idd); {''}; labs1(idd+1:end)]; 
                                    idd = idd +1;      
                                elseif  segs1(idd,2) - segs1(idd+1,1) > tolerance
                                    errorFlag = 1; 
                                end
                            end
                            idd = idd + 1;
                        end
                        outTG(cii).labs = labs1; outTG(cii).segs = segs1;
                    end  %for cii = 1:numel(TG)
                end  %CheckTextGridIntegrity
                
        end % WriteTextGrid
        %%
        function foundPath = FindAppInstalledPath(varargin)
            found = 0; foundPath = []; sep = filesep;
            programFilesFolders = {'Program Files', 'Program Files (x86)'};
            appFolders = {'DeepEdge\application', 'Haskins_Laboratories\DeepEdge\application'};  
            exeFN = 'DeepEdge.exe';
            startDrive = 'C'; endDrive = 'Z';   
            for i = double(startDrive):double(endDrive)
                drive = [char(i), ':' sep]; 
                for j = 1:numel(programFilesFolders)
                    programFilesFolder = programFilesFolders{j};  
                    for k = 1:numel(appFolders)
                        appFolder = appFolders{k};
                        pathCandidate = [drive, programFilesFolder, sep, appFolder];
                        candExeFN = fullfile(pathCandidate, exeFN);
                        if exist(candExeFN, 'file'),  found = 1; foundPath = pathCandidate; return;  end  
                    end
                end
            end            
        end %  FindAppInstalledPath(varargin)
        %%
    end %methods (Access = public, Static=true)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.appStartUpTime = datetime('now');
            app.UIFigure.Name = sprintf('%s %s', app.AppName, app.Version);
            if verLessThan('matlab', app.minMatlabVer)
                app.Msg1.Value = {'Failed to start.', ...
                    ['Requires MATLAB ' app.minMatlabVerName ' or newer.']}; return
            end
            [app.appPath, f, e] = fileparts(mfilename('fullpath'));
            % if using compiled app.
            if contains(app.appPath, 'AppData\Local')
                app.appPath = app.FindAppInstalledPath;  
                app.UIFigure.Name = sprintf('%s %s (App)', app.AppName, app.Version);
            end
            initialize(app);
            %% SNAKE parameters:
            app.snakeDefaultPara.Sigma = 5;  app.snakeDefaultPara.Delta = 2; 
            app.snakeDefaultPara.Alpha =0.7;   app.snakeDefaultPara.Lambda1 =0.7; 
            app.snakeDefaultPara.BandPenalty = 2; 
            app.snakePara = app.snakeDefaultPara; 

            defaultmodelname = 'DpEdg_*.mat'; 
            modelFNameMask = fullfile(app.appPath, defaultmodelname);
            fl = getfilelist(modelFNameMask);
            if ~isempty(fl)
                modelFName = fullfile(app.appPath, fl{1});
                if exist(modelFName, 'file'),loadModel(app, modelFName);end
            end
            %% Default video for testing -- delete this part later:
%             myloadvideo(app, 'video01.avi'); dispFrame(app, 1);
            function fl = getfilelist(mask), FLs = dir(mask); fl = {FLs.name}'; end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~app.active, return; end
%             if isBusy(app), return;end
            app.flag_mouseClicked = true; mouseButton = app.UIFigure.SelectionType; 
            % app.Msg1.Value = app.defaultMsg; 
            switch mouseButton
                case 'normal' % left click
                    app.flag_LeftClickDown = true; 
                case 'alt' % right click or control + left click
                    app.flag_RightClickDown = true;
                case 'open' % double click
                case 'extend' % shift+left click, or middle click, or left+right click
            end
            
            axisNum = CurrentCursorWhichAxis(app);%app.Ax0: axisNum=0;   app.Ax1: axisNum=1
            if axisNum<0, return; end % cursor not in any axis

           %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  axisNum == 0
                Proc_WindowButtonDown_Ax0(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax0)
           %% --- IF cursor is within Audio axis (app.Ax1):
            if  axisNum == 1 && ~isempty(app.AUDIO)
                Proc_WindowButtonDown_Ax1(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax1)
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~app.active, return; end 
             app.flag_mouseClicked = false; app.flag_LeftClickDown = false; app.flag_RightClickDown = false;
            axisNum = CurrentCursorWhichAxis(app); %app.Ax0: axisNum=0;   app.Ax1: axisNum=1
            mouseButton = app.UIFigure.SelectionType; 
            if strcmpi(mouseButton, 'normal'), app.flag_LeftClickDown = true; end
            if strcmpi(mouseButton, 'alt'), app.flag_RightClickDown = true; end
           %% Scenarios:
           % 1. Select crop rect is on
           % 2. MPP is on
           % 3. Audio selection is on
           % 4. Ax0: left click to draw/add/move anchors
           % 5. Ax0: right click to delete anchors
            scenarios{1} = 'crop rect';
            scenarios{2} = 'MPP'; 
            scenarios{3} = 'audio';
            scenarios{4} = 'Ax0 left click';
            scenarios{5} = 'Ax0 right click'; 
            scenario = 'empty';
            if app.flag_SelectRectMouseDown, scenario = scenarios{1}; end
            if app.flag_GetMppOn,  scenario = scenarios{2}; end
            if app.flag_AudioSelectDown && ~isempty(app.AUDIO), scenario = scenarios{3}; end
            if  axisNum == 0 && app.flag_LeftClickDown ...
              && ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ~app.flag_AudioSelectDown, scenario = scenarios{4}; end
            if  axisNum == 0 && app.flag_RightClickDown...
              && ~app.flag_SelectRectMouseDown && ~app.flag_GetMppOn && ~app.flag_AudioSelectDown, scenario = scenarios{5}; end
            app.selectedAnchorID = [];
           switch scenario    
               case scenarios{1} 
                 %% If SelectRectWindowButton is Down : 
                    app.Msg1.Value = app.defaultMsg; 
                    app.flag_SelectRectMouseDown = false;
                    app.flag_SelectCropRect = false; hold(app.Ax0,'on');
                    app=do_crop(app); app.Sw_ClickAnchor.Value = app.PreviousClickAnchorValue;
                    app.Bn_CropButton.Value = 0;                     
                    set(app.UIFigure, 'pointer','arrow');  % Reset cursor to arrow                 
               case scenarios{2}
                   %% If Get MPP On : 
                    app.Msg1.Value = app.defaultMsg; 
                    app.flag_GetMppOn = false;
                    do_mpp(app); 
                    hold(app.Ax0,'on');
                    set(app.UIFigure, 'pointer','arrow');  % Reset cursor to arrow
               case scenarios{3}
                    %% --- IF AudioSelect is on:
                    Proc_WindowButtonUp_Ax1(app);
               case scenarios{4}
                   % 4. Ax0: left click to draw/add/move anchors
                    Proc_WindowButtonUp_Ax0(app);
               case scenarios{5}
                   % 5. Ax0: right click to delete anchors
                    Proc_WindowButtonUp_Ax0(app);
           end
          % %% If SelectRectWindowButton is Down : 
          %   if app.flag_SelectRectMouseDown
          %       app.Msg1.Value = app.defaultMsg; 
          %       app.flag_SelectRectMouseDown = false;
          %       app.flag_SelectCropRect = false; hold(app.Ax0,'on');
          %       app=do_crop(app); app.Sw_ClickAnchor.Value = app.PreviousClickAnchorValue;
          %       app.Bn_CropButton.Value = 0;                     
          %       set(app.UIFigure, 'pointer','arrow');  % Reset cursor to arrow
          %       return
          %   end % Crop
          %     %% If Get MPP On : 
          %   if app.flag_GetMppOn
          %       app.Msg1.Value = app.defaultMsg; 
          %       app.flag_GetMppOn = false;
          %       do_mpp(app); 
          %       hold(app.Ax0,'on');
          %       set(app.UIFigure, 'pointer','arrow');  % Reset cursor to arrow
          %       return
          %   end % MPP
          %   %%
          %   axisNum = CurrentCursorWhichAxis(app); %app.Ax0: axisNum=0;   app.Ax1: axisNum=1
          % 
          %   app.selectedAnchorID = [];
          %   %% --- IF cursor is within Ultrasound axis (app.Ax0):
          %   if  axisNum == 0
          %       Proc_WindowButtonUp_Ax0(app);
          %   end %if  app.ifWithinPos(fig_pt, pos_Ax0)
          % 
          %   %% --- IF AudioSelect is on (clicked):
          %   if  app.flag_AudioSelectDown && ~isempty(app.AUDIO)
          %       Proc_WindowButtonUp_Ax1(app);
          %   end 
            if axisNum<0, clearDrawLine(app); return; end % cursor not in any axis
            %% Reset flags:
             app.flag_mouseClicked = false; app.flag_LeftClickDown = false; app.flag_RightClickDown = false; 
             app.flag_AudioSelectDown = false; app.flag_LeftClickDown = false; app.flag_RightClickDown = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if ~app.active, return; end
            axisNum = CurrentCursorWhichAxis(app); %app.Ax0: axisNum=0;   app.Ax1: axisNum=1;  Not in any axis: axisNum=-1;
            if axisNum < 0, return; end % cursor not in any axis

            update_axes_cursor_loc(app); % Update the Ed_CursorLoc.Value

            %% --- IF cursor is within Ultrasound axis (app.Ax0):
            if  axisNum == 0
                Proc_WindowButtonMotion_Ax0(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax0)     
            %% --- IF cursor is within Audio axis (app.Ax1):
            if  axisNum == 1 && ~isempty(app.AUDIO) && app.flag_mouseClicked
                Proc_WindowButtonMotion_Ax1(app);
            end %if  app.ifWithinPos(fig_pt, pos_Ax1)     
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
           if ~app.active, return; end
           if isBusy(app), return; end

            key = event.Key;  
            modifier = event.Modifier; %#ok<NASGU,NASGU>
            switch key
                case 'rightarrow'
                    event.Source.UserData = 1;
                    Bn_ChangeFrameButtonPushed(app, event);
                case 'leftarrow'
                    event.Source.UserData = -1;
                    Bn_ChangeFrameButtonPushed(app, event);
                case 'pagedown'  
                    event.Source.UserData = app.Mn_ScrollPageForward.UserData;
                    Mn_ScrollPageMenuSelected(app, event);
                case 'pageup'
                    event.Source.UserData = app.Mn_ScrollPageBack.UserData;
                    Mn_ScrollPageMenuSelected(app, event);
            end
        end

        % Menu selected function: Mn_LoadVideo, Mn_LoadVideoAndContours, 
        % ...and 2 other components
        function Mn_LoadVideoPushed(app, event)
            % Load video function
            if isBusy(app), return; end

            if verLessThan('matlab', app.minMatlabVer)
                app.Msg1.Value = {'Failed to start.', ...
                    ['Requires MATLAB ' app.minMatlabVerName ' or newer.']};
                return
            end
            if ~isempty(app.vObj)
                closeVideoSuccess = CloseVideoFile(app);
                if ~closeVideoSuccess, return; end % Aborted by user
            end
% app.LoadMode :  'Video', 'Video&Contour', 'Video&TextGrid', 'Video&Contour&TextGrid'.
            loadMode = event.Source.UserData;
            [fl, selectionSuccess, vPath] = SelectVideoFileUI(app);
            if ~selectionSuccess, return; end
            app.LoadMode = loadMode;
            app.FileList = struct('Filename', fl, 'Path', vPath, 'CONTOURS', [], 'NumContours', 0); app.filePath = vPath; 
            if isrow(app.FileList), app.FileList = app.FileList';end
            %% If Load Contours:
            if contains(loadMode, 'Contour','IgnoreCase', true)
                tic
                for ifl = 1:numel(fl)
                    fn = fl{ifl}; vPath = app.FileList(ifl).Path; [~, f, ~]=fileparts(fn); mfn = [f, '.mat']; matFName = fullfile(vPath, mfn);
                    if ~exist(matFName, 'file'), continue;end
                    m = app.load1var(matFName); 
                    if ~app.isContourStruct(m), continue;end
                    app.FileList(ifl).CONTOURS = m;  app.FileList(ifl).NumContours = numel(m);
                end % for ifl = 1:numel(fl)
                elapsedTime = toc;
                fprintf('Read %d .mat files. Took %04f sec(s).\n', numel(fl), elapsedTime);
            end
            % Load first file
            success = LoadOneFileFromFilelist(app, 1);
            if success
                nFiles = numel(fl); 
                if nFiles > 1
                    app.Msg1.Value = [num2str(nFiles) ' file(s) loaded.'];
                end
                cd(vPath); SetStatus(app, 'ready');
            else
                initialize(app); 
            end
        end

        % Callback function: Bn_FirstFrame, Bn_LastFrame, Bn_NextFrame, 
        % ...and 5 other components
        function Bn_ChangeFrameButtonPushed(app, event)
            %% Update NextNFrame and PreviousNFrame
%             app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
            %% Get UserData from calling button handle for +k frames
            if ~app.active, return; end;  if isBusy(app), return; end            
            k = event.Source.UserData;
            iFrame = app.Ed_Frame.Value + k; % 'event.Source.UserData': move forward by how many frames
            if iFrame < 1, iFrame = 1; end
            if iFrame > app.nFrames, iFrame = app.nFrames; end
            if iFrame == app.Ed_Frame.Value, app.flag_busy = false; return; end 
            SetStatus(app, 'busy');
%             try
                dispFrame(app, iFrame); updatePlotAnchors(app);   
                frameTime = app.Ed_Time.Value; xlm = app.Ax1.XLim; 
                app.AudioCursorTime = frameTime; 
                plotUpdateAudioCursor(app, frameTime); 
                if frameTime < xlm(1) || frameTime > xlm(2)
                    RefreshAudioAxis(app);
                end
                 app.audioSelectionFrameIndices = iFrame;
%             catch
                SetStatus(app, 'ready');
%             end
            SetStatus(app, 'ready');
        end

        % Value changed function: Bn_CropButton
        function Bn_SelectCropRectButtonPushed(app, event)
            if ~app.active, app.Bn_CropButton.Value = 0; return; end
            if isBusy(app), return; end

            if ~app.Bn_CropButton.Value, return; end
            if ~isempty(app.handle_rect) && isgraphics(app.handle_rect)
                delete(app.handle_rect); app.handle_rect = []; 
            end
            app=do_uncrop(app); app.flag_SelectCropRect = true;
           set(app.UIFigure, 'pointer','crosshair'); % Set cursor to crosshair
            app.Msg1.Value = 'Click the image and drag to make a rectangle to crop...';
            app.PreviousClickAnchorValue = app.Sw_ClickAnchor.Value;
            app.Sw_ClickAnchor.Value = 'Off'; 
        end

        % Button pushed function: Bn_Uncrop
        function Bn_UncropButtonPushed(app, event)
            if ~app.active, return; end
            if isBusy(app), return; end
            app.Bn_CropButton.Value = 0; app.flag_SelectCropRect = false;
            app.flag_SelectRectMouseDown = false;
            app.Msg1.Value = app.defaultMsg; 
            do_uncrop(app); %app.Sw_ClickAnchor.Value = 'On';
             set(app.UIFigure, 'pointer','arrow'); % Reset cursor to arrow
        end

        % Value changed function: Sw_ClickAnchor
        function Sw_ClickAnchorValueChanged(app, event)
%             value = app.Sw_ClickAnchor.Value;
            if app.flag_SelectCropRect || app.flag_SelectRectMouseDown
                app.Sw_ClickAnchor.Value = 'Off';
            end
        end

        % Value changed function: Ck_ShowAnchorOrder, Slid_AnchorAlpha, 
        % ...and 2 other components
        function Callback_UpdatePlotAnchors(app, event)
             if ~app.active, return;end
            app.anchor_size = app.Slid_AnchorSize.Value;
            app.anchor_alpha = app.Slid_AnchorAlpha.Value/100; %Slider range:0:100
            updatePlotAnchors(app);         
        end

        % Callback function: Bn_DelAnchors, Mn_DeleteAllAnchorsCurrentFrame
        function Bn_DelAnchorsButtonPushed(app, event)
            if ~app.active, return; end
            if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end

            selectedFrameInds = app.audioSelectionFrameIndices;
            if isempty(selectedFrameInds), selectedFrameInds=app.Ed_Frame.Value; end
            for iFrame = selectedFrameInds(1):selectedFrameInds(end)
                if isempty(app.CONTOURS(iFrame).ANCHORS) && ...
                    isempty(app.CONTOURS(iFrame).XY) && ...
                    isempty(app.CONTOURS(iFrame).TRKRES.ENERGY) && ...
                    isempty(app.CONTOURS(iFrame).TRKRES.METHOD) && ...
                    isempty(app.CONTOURS(iFrame).nnMASKS)
                    % Nothing to delete
                    continue
                end
               app.CONTOURS(iFrame).ANCHORS=[]; app.CONTOURS(iFrame).XY=[]; 
               app.CONTOURS(iFrame).TRKRES.ENERGY=[]; app.CONTOURS(iFrame).TRKRES.METHOD=[]; 
               app.CONTOURS(iFrame).nnMASKS=[]; 
               app.flag_ifAnchorModifed = true;
            end
            if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
            if isgraphics(app.handle_anchor_numbers), delete(app.handle_anchor_numbers);end
            updatePlotAnchors(app);  
        end

        % Value changed function: Sw_ifInvert
        function Sw_ifInvertValueChanged(app, event)
            if ~app.active, ChangeBackToOriginalValue(app); return; end
            if isBusy(app), ChangeBackToOriginalValue(app); return; end

            if strcmpi(app.Sw_ifInvert.Value, 'Invert'), app.ifInvert = true; else, app.ifInvert = false; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   

            function ChangeBackToOriginalValue(app)
                if app.ifInvert
                    app.Sw_ifInvert.Value = 'Invert';
                else
                    app.Sw_ifInvert.Value = 'Normal';
                end
            end
        end

        % Callback function: Bn_ApplyCurrentFrame, Mn_ApplyCurrentFrame
        function Bn_ApplyCurrentFramePushed(app, event)
            if ~app.active, return;  end
            if app.flag_GetMppOn, return;end
            if app.flag_SelectCropRect, app.Msg1.Value = 'Click the image and drag to make a rectangle to crop...'; return;end
            SetStatus(app, 'busy');
            iFrame=app.Ed_Frame.Value; 
            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            [status, EndFrame] = TrackThruAllFrames(app, iFrame, iFrame, method);
            SetStatus(app, 'ready');
        end

        % Callback function: Bn_TrackFrames, Mn_Track, Mn_TrackNFrames, 
        % ...and 1 other component
        function StartTracking(app, event)
            app.Bn_StopTrack.Value = false;
            if ~app.active, app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; return; end
            if isBusy(app), app.Bn_TrackFrames.Value = false; return; end
            SetStatus(app, 'busy');  
            
            app.Msg1.Value = app.defaultMsg;

            currentFrame = app.Ed_Frame.Value;
            % IF it is the last frame: 
            if currentFrame == app.nFrames
                app.Msg1.Value = 'This is the last frame.'; 
                app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; SetStatus(app, 'ready'); return; 
            end

            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'
            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
                SetStatus(app, 'ready'); return;
            end

            app.Bn_TrackFrames.Value = true;  app.flag_FrameTrackingOn = true;

            k = event.Source.UserData; % Track next k frames
            StartFrame = currentFrame+1; EndFrame = currentFrame + k; 
            if EndFrame > app.nFrames, EndFrame = app.nFrames;end
              % Start tracking:  
             
%%
             [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method);
             % status = 1; % status = 0 : error;  1: success;  2: user stopped;  3: No non-empty TextGrid labels
             switch status
                 case 0
                    app.Msg1.Value = 'Error!';
                 case 1
                    app.Msg1.Value = sprintf('Tracked %d frame(s)', EndFrame - StartFrame +1);
                 case 2
                     app.Msg1.Value = sprintf('User stopped. Tracked %d frame(s)', EndFrame - StartFrame +1);
             end
%%
            SetStatus(app, 'ready');  
            app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
        end

        % Menu selected function: Mn_ExpImgCrop, Mn_ExpImgRaw
        function Mn_ExpImg(app, event)
                if ~app.active; return; end   
                if isBusy(app), return; end

                videoFName = fullfile(app.vObj.Path, app.vObj.Name); 
               frame = app.Ed_Frame.Value;
               [videoPath, vFn, vExt]= fileparts(videoFName);
               ifCrop = event.Source.UserData.Crop;
               if ifCrop
                  im = imcrop(app.img, app.crop_rect); 
                  fn_suffix = '_Crop_Frame_';
               else
                   im = app.img; fn_suffix = '_Frame_';
               end
               outImgfn = [vFn, '_', strrep(vExt,'.',''), fn_suffix, num2str(frame) '.jpg'];
               outImgFName = fullfile(videoPath, outImgfn);
               imwrite(im, outImgFName);
        end

        % Menu selected function: Mn_Anchors2TSV_Crop, Mn_Anchors2TSV_Ori
        function Mn_Anchors2TSVSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end

            ifCrop = event.Source.UserData.Crop;
            % Write anchors of all frame to a text file. Overwrite existing file. 
            videoFName = app.vObj.Name; videoPath = app.vObj.Path; 
            [~, vFn, ~]= fileparts(videoFName);
            if ifCrop, suffix = '_Crop'; else,  suffix = ''; end
            outAnchorsfn = [vFn, suffix '.tsv']; 
            outAnchorsFName = fullfile(videoPath, outAnchorsfn);
            fid = fopen(outAnchorsFName,'wt'); 
            if fid < 0, uialert(app.UIFigure, ['Error attempting to write ' outAnchorsFName], 'Error'); return; end
            if ifCrop,  fprintf(fid,'Crop_para(Xmin, Ymin, Width, Height) : [%.2f, %.2f, %.2f, %.2f]\n', app.crop_rect(1), app.crop_rect(2), app.crop_rect(3), app.crop_rect(4)); end
            fprintf(fid,'FRAME\tTIME\tNOTE\tPOINT\tX\tY\n'); 
            for iFrame = 1 : numel(app.CONTOURS)
                anchors = app.CONTOURS(iFrame).ANCHORS; % ANCHORS{iFrame} : n x 3 {x, y, isUserInput} 
                if isempty(anchors), continue;end 
                anchors = round(anchors(:,1:2), 2); 
                if ifCrop, anchors = anchors - app.crop_rect(1:2); end
                for ci = 1 : size(anchors,1)
                    x =anchors(ci,1); y=anchors(ci,2);  fprintf(fid,'%d\t%f\t%s\t%d\t%.2f\t%.2f\n', iFrame, (iFrame-1)/app.vObj.FrameRate, '', ci, x, y);
                end
            end
            fclose(fid);  uialert(app.UIFigure, ['Successfully write to ' outAnchorsFName], 'Success','icon', 'success'); 
        end

        % Menu selected function: Mn_QuickSaveContours, Mn_SaveContours
        function Mn_ExportMatMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            silent = event.Source.UserData.Silent; 
            Export2Mat(app, silent);
        end

        % Value changed function: Ck_ShowNNMask
        function Ck_ShowNNMaskValueChanged(app, event)
            if ~app.active, return; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   
        end

        % Menu selected function: Mn_LoadContours
        function Mn_LoadContoursMenuSelected(app, event)
            if ~app.active
                 uialert(app.UIFigure,'Video not loaded yet! Load video first.','Video not loaded yet'); 
                return; 
            end
            if isBusy(app), return; end
            LoadContourStructUI(app);  
            if ~isempty(app.TextGridTier)
                CopyTextGridLabelsToContour(app);
            end
        end

        % Menu selected function: Mn_LoadModel
        function Mn_LoadModelMenuSelected(app, event)
            if isBusy(app), return; end

            % Display uigetfile dialog
            filterspec = {'*.mat'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            loadModel(app, fname);
        end

        % Callback function: Bn_StopTrack, Mn_StopTracking
        function StopTracking(app, event)
            if ~app.active, return; end    
            app.Bn_StopTrack.Value = true; % press StopTrack button
            app.Bn_TrackFrames.Value = false; app.Bn_TrackFiles.Value = false;
            if ~isempty(app.AudPLAYER) && isplaying(app.AudPLAYER)
                stop(app.AudPLAYER);
            end
        end

        % Value changed function: Ed_NextN
        function Ed_NextNValueChanged(app, event)
            %% Update NextNFrame
            app.Bn_NextNFrame.UserData = app.Ed_NextN.Value; 
            app.Mn_TrackNFrames.Text = sprintf('Track next %d frames', app.Ed_NextN.Value);
            app.Mn_TrackNFrames.UserData = app.Ed_NextN.Value;
        end

        % Value changed function: Ed_PreviousN
        function Ed_PreviousNValueChanged(app, event)
            %% Update PreviousNFrame
            app.Bn_PreviousNFrame.UserData = -1*app.Ed_PreviousN.Value;
        end

        % Value changed function: Ed_GoToN
        function Ed_GoToNValueChanged(app, event)
            %% Update Bn_GoTo
            app.Bn_GoTo.UserData = app.Ed_GoToN.Value - app.Ed_Frame.Value;
        end

        % Menu selected function: Mn_LoadTextGrid
        function Mn_LoadTextGridMenuSelected(app, event)
            if ~app.active, return; end
            if isBusy(app), return; end

            if isempty(app.AUDIO),uialert(app.UIFigure,'No audio found.','Read error'); return; end
            % Display uigetfile dialog
            filterspec = {'*.TextGrid'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            success = loadTextGrid(app, fname);
            if success, drawTextGridIntervals(app); CopyTextGridLabelsToContour(app); end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             if app.flag_FrameTrackingOn, return; end
%             if app.flag_GetMppOn, return;end
            ifAutosave = app.Ck_Autosave.Value; 
            msg = '';
            if ifAutosave && app.flag_ifAnchorModifed
                silent = true;
               Export2Mat(app, silent);              
            elseif app.flag_ifAnchorModifed
            % If there are unsaved changes, prompt the user to confirm:
                msg = 'Warning! There are unsaved changes to contours.';
            end

            msg = {msg, sprintf(' Close %s? ', app.AppName)};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            switch selection
                case 'OK', app.delete(); case 'Cancel', return
            end
        end

        % Menu selected function: Mn_VideoInfo
        function Mn_VideoInfoMenuSelected(app, event)
            if isBusy(app), return; end
            v = app.vObj; title = 'Video information';
            origin = app.ORIGIN; mpp = app.MPP;
            if isempty(origin), origin = ''; else, origin = sprintf('[%0.1f, %0.1f]', origin(1), origin(2)); end
            if isempty(mpp), mpp = ''; else, mpp = sprintf('%0.4f', mpp);end
            if isempty(v)
                msg = 'No video loaded.';
            else
                msg = sprintf(...
                ['Directory: \n%s\n' ...
                'File:\n%s\n\n' ...
                'FrameRate: %0.3f\n' ...
                'FramePeriod(msec): %0.3f\n' ...
                'Height: %.0f\nWidth: %.0f\n' ...
                'VideoFormat: %s\n' ...
                'Duration: %0.4f \n' ...
                'Origin: %s\n' ...
                'MPP: %s'], ...
                v.Path, v.Name, v.FrameRate, 1000/v.FrameRate, v.Height, v.Width, v.VideoFormat, v.Duration, origin, mpp);                
            end
            if ~isempty(app.AUDIO)
                audio_msg = sprintf(...
                    ['\nAudio duration (s): %0.5f\n' ...
                    'Audio sampling rate: %0.2f\n' ...
                    'Audio number of samples: %d'], ...
                    app.AUDIO.Dur, app.AUDIO.sr, size(app.AUDIO.s,1));
                msg = [msg, audio_msg];
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info'); 
        end

        % Menu selected function: Mn_ModelInfo
        function Mn_ModelInfoMenuSelected(app, event)
            if isBusy(app), return; end
            m = app.net; title = 'Model information';
            if isempty(m)
                msg = 'No model loaded.';
            else
                msg = sprintf('%s%s\n\n%s\n', ...
                app.modelPath, filesep, app.modelname);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info');             
        end

        % Menu selected function: Mn_AboutDeepEdge
        function Mn_AboutDeepEdgeMenuSelected(app, event)
            if isBusy(app), return; end
            title = ''; sinceYear = 2020;
            currentYear = year(datetime);
            cInfo = sprintf('Copyright (C) %04d-%04d Wei-Rong Chen', sinceYear, currentYear); 
            email = '<wei-rong.chen[AT]yale.edu>';
            licInfo = ['This program is free software under GNU General Public License, version 3.' ...
                'This program is distributed WITHOUT ANY WARRANTY AND SUPPORT. ' ...
                'See the GNU General Public License for more details.'];  
            currentPath = pwd;
            msg = sprintf('%s\n Version: %s \n App Path: %s\n Current Path: %s\n Latest Update: %s \n\n %s \n %s\n %s', ...
            app.AppName, app.Version, app.appPath, currentPath, app.Update, cInfo, email, licInfo);  
            % if app.ffmpegFound,  msg = sprintf('%s \n FFMPEG Path: %s', msg, app.ffmpegFullPathFilename);  end
            uialert(app.UIFigure, msg, title, 'icon', 'info');  
        end

        % Menu selected function: Mn_ExportCropPara
        function Mn_ExportCropParamtersMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, [vFn '_crop_para.mat']);
            SetStatus(app, 'busy'); 
            ExportVariable(app, outFName, app.crop_rect, 'crop_rect', 'cropping parameters');
            SetStatus(app, 'ready'); 
        end

        % Callback function: Bn_PlaySelected, Mn_PlaySelectedAudio
        function Mn_PlaySelectedAudioMenuSelected(app, event)
                 if ~app.active, return;end
                 if isBusy(app), return; end
                 if isempty(app.AUDIO), return; end
                 if isempty(app.handle_AudioSelection), return; end
                 if ~isgraphics(app.handle_AudioSelection), return; end
                 app.Bn_StopTrack.Value = false;
                 SetStatus(app, 'busy');   
                 ifPlayVideo = true;
                 % ifShowAnchors = false; 
                 % ifShowAnchors = true; 
                 ifShowAnchors = app.Ck_ShowEMA.Value;

                 x = app.audioSelectionTimeStamps;
                 d = abs(x(2) - x(1));   t = [min(x), max(x)];    
                 if d < app.minAudioPlayDur, SetStatus(app, 'ready');  return; end
                 if ~ifShowAnchors
                    if ~isempty(app.hg_mask_boundary) && isgraphics(app.hg_mask_boundary), delete(app.hg_mask_boundary);  end %#ok<UNRCH>
                    if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
                    if isgraphics(app.hg_anchor_numbers), delete(app.hg_anchor_numbers);end
                    if isgraphics(app.handle_xy), delete(app.handle_xy);end
                    if isgraphics(app.handle_ema), delete(app.handle_ema);end
                    if isgraphics(app.hg_ema_names), delete(app.hg_ema_names);end
                 end
                fps = app.vObj.FrameRate; startTime = t(1); endTime = t(2);
                startFrame = floor(startTime * fps) + 1;  endFrame = floor(endTime * fps)+1;
                if startFrame < 1, startFrame = 1; end
                if endFrame > app.vObj.NumFrames, endFrame = app.vObj.NumFrames; end
                nFrames = endFrame - startFrame +1;
                frameInds = (1:nFrames)'; frameDur = 1/app.vObj.FrameRate;
                frameElapsedTimeStamps = (frameInds-1) .* frameDur;
                app.Ax0.NextPlot = 'replaceChildren';
                % Play audio:
                try               
                    n = int64(floor(t*app.AUDIO.sr)+1); 
                    if n(1) < 1, n(1) = 1;end
                    if n(2) > size(app.AUDIO.s,1), n(2) = size(app.AUDIO.s,1); end
                    s = app.AUDIO.s(n(1) : n(2),1); sr  = app.AUDIO.sr;
                    app.AudPLAYER = audioplayer(s,sr);
			        play(app.AudPLAYER); ticStart = tic;        
                 catch
                     app.Msg1.Value="Can't play audio!"; SetStatus(app, 'ready');   
                end % try play audio
                % Play video:
                
                if ifPlayVideo         
                    try
                        [status, endFrame] = PlayVideoFrames(app, startFrame, endFrame, ticStart, frameElapsedTimeStamps, startTime, endTime, ifShowAnchors);
                    catch
                        app.Msg1.Value="Can't play video!"; SetStatus(app, 'ready');   
                    end
                end 
                SetStatus(app, 'ready');   
        end

        % Button pushed function: Bn_NextFile, Bn_PreviousFile
        function Bn_BrowseFileButtonPushed(app, event)
            % Browse/Load/Jump to next or previous file
            %% Get UserData from calling button handle for +k file
            if isBusy(app), return; end
            if isempty(app.FileList), return;end
            k = event.Source.UserData;% 'event.Source.UserData': +1 or -1
            fileInd = app.CurrentFileNumber + k; 
            if fileInd < 1 || fileInd > numel(app.FileList), return; end
            closeVideoSuccess = CloseVideoFile(app);
            if ~closeVideoSuccess, return; end % Aborted by user
            success = LoadOneFileFromFilelist(app, fileInd); %#ok<NASGU>
            SetStatus(app, 'ready');
        end

        % Menu selected function: Mn_ReselectTextGridTier
        function Mn_ReselectTextGridTierMenuSelected(app, event)
            if ~app.active || isempty(app.TextGrid) || numel(app.TextGrid) < 2, return; end
            if isBusy(app), return; end
            TierNames = {app.TextGrid.NAME};
             [ind, ifSelected] = listdlg('PromptString',{'Select only one tier ',...
                'in the TextGrid',''},'SelectionMode','single','ListString',TierNames);
            figure(app.UIFigure) % Get focus back on UIFigure
            if ~ifSelected || ind < 1, return; end
            app.TextGridTier = app.TextGrid(ind);
            app.WhichTextGridTierSelected = ind;
            drawTextGridIntervals(app); 
            CopyTextGridLabelsToContour(app);
        end

        % Menu selected function: Mn_ShowSelectFromFileList
        function Mn_ShowSelectFromFileListMenuSelected(app, event)
            if isBusy(app), return; end
            if isempty(app.FileList), app.Msg1.Value = 'FileList is empty. Load video(s) first'; return; end
            fl = {app.FileList.Filename}; NumContours = cell2mat({app.FileList.NumContours}); 
            if isrow(NumContours), NumContours = NumContours';end 
            if isrow(fl), fl = fl';end
            NumContoursStrCell = cellstr(num2str(NumContours)); 
            listString = strcat(fl, '  (', NumContoursStrCell, '  contours)');
            prompt = {'Show/Select from current FileList.'};
            [fileInd, ifSelected] = listdlg('PromptString',prompt,...
                'SelectionMode','single','ListString', listString, 'ListSize', [450, 300], 'InitialValue', app.CurrentFileNumber);
            figure(app.UIFigure) % Get focus back on UIFigure
            if ~ifSelected, return; end
            if fileInd == app.CurrentFileNumber, return;end

            closeVideoSuccess = CloseVideoFile(app);
            if ~closeVideoSuccess, return; end % Aborted by user

            success = LoadOneFileFromFilelist(app, fileInd); %#ok<NASGU>
            SetStatus(app, 'ready');
        end

        % Menu selected function: Mn_ApplySnake, Mn_ZoomToSelection
        function Mn_ApplySnakeMenuSelected(app, event)
             if ~app.active, return; end
             if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end
            %%  If a range of audio is selected, then this function will apply "Zoom to Selection". 
            %% If only a single frame is clicked/selected, then apply SNAKE on the current frame. 
            ifApplySnake = 1; 
            ifZoomToSelection = 0; 
            if ~isempty(app.AUDIO) && ~isempty(app.AudioCursorTime)
                % audioSel = app.handle_AudioSelection.XData(1:2);
                audioSel = app.audioSelectionTimeStamps;
                audioSelDur = audioSel(2) - audioSel(1);
                if audioSelDur > app.minAudioDisplayDur ...
                        && audioSelDur > app.minAudioSelectDur
                    ifZoomToSelection = 1; ifApplySnake =0;
                end
            end

            if ifZoomToSelection
                    xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);  %#ok<NASGU>
                    newDur = audioSelDur; cursorT = mean(audioSel); 
                    left = cursorT - newDur/2; right = cursorT + newDur/2;
                    RefreshAudioAxis(app, [left, right]);    
            end

            if ifApplySnake
                 iFrame=app.Ed_Frame.Value; 
                 method = 'SNAKE'; 
                 im = app.Read1Frame(app.vObj, iFrame);
                [anchors, energy, xy, nnMask, success] = detectOneFrame(app, im, method, app.CONTOURS(iFrame).ANCHORS);
                if isempty(anchors),  return; end
                %%
                app.CONTOURS(iFrame).ANCHORS = anchors; app.CONTOURS(iFrame).XY = xy; 
                app.CONTOURS(iFrame).TRKRES.ENERGY = energy; app.CONTOURS(iFrame).TRKRES.METHOD = method; 
                app.CONTOURS(iFrame).nnMASKS = nnMask;
                updatePlotAnchors(app);        
            end
        end

        % Selection changed function: DetectMethodGroup
        function DetectMethodGroupSelectionChanged(app, event)
            app.DetectMethodGroup.UserData = app.DetectMethodGroup.SelectedObject.Text;
        end

        % Value changed function: Ed_TrackingDelayMs
        function Ed_TrackingDelayMsValueChanged(app, event)
            app.TrackingDelayMs = app.Ed_TrackingDelayMs.Value;
        end

        % Menu selected function: Mn_DeleteAllAnchorsinAllFrames
        function Mn_DeleteAllAnchorsinAllFramesMenuSelected(app, event)
            if ~app.active, return; end
            if app.flag_SelectCropRect, return;end
            if app.flag_GetMppOn, return;end
            msg = {'Delete All Anchors/Contours in ALL frames! ', 'Are you sure? '};
            selection = uiconfirm(app.UIFigure, msg,'Quit', 'Icon','warning');
            if strcmpi(selection, 'Cancel'), return;end
            app= EraseContoursAllFrames(app);
            app.flag_ifAnchorModifed = true;
            if isgraphics(app.handle_anchors), delete(app.handle_anchors);end
            if isgraphics(app.handle_anchor_numbers), delete(app.handle_anchor_numbers);end
            updatePlotAnchors(app);              
        end

        % Value changed function: Ck_Autosave
        function Ck_AutosaveValueChanged(app, event)
            if app.Ck_Autosave.Value
                app.Msg1.Value  = {'Autosave ON', ...
                    'Warning: Existing .mat file will be overwritten!'};
            else 
                app.Msg1.Value = ''; 
            end
        end

        % Button pushed function: Bn_SnakeDefault
        function Bn_SnakeDefaultButtonPushed(app, event)
            app.snakePara = app.snakeDefaultPara; 
            app.Ed_SnakeSigma.Value = app.snakeDefaultPara.Sigma;
            app.Ed_SnakeDelta.Value = app.snakeDefaultPara.Delta;
            app.Slid_SnakeAlpha.Value = app.snakeDefaultPara.Alpha;
            app.Ed_SnakeAlpha.Value = app.snakeDefaultPara.Alpha;
            app.Slid_SnakeLambda1.Value = app.snakeDefaultPara.Lambda1;
            app.Ed_SnakeLambda1.Value = app.snakeDefaultPara.Lambda1;
            app.Ed_SnakeBandPenalty.Value = app.snakeDefaultPara.BandPenalty;
        end

        % Value changed function: Ed_SnakeBandPenalty
        function Ed_SnakeBandPenaltyValueChanged(app, event)
            app.snakePara.BandPenalty = app.Ed_SnakeBandPenalty.Value;
        end

        % Value changed function: Slid_SnakeLambda1
        function Slid_SnakeLambda1ValueChanged(app, event)
            app.snakePara.Lambda1 = app.Slid_SnakeLambda1.Value;  
            app.Ed_SnakeLambda1.Value = app.Slid_SnakeLambda1.Value;  
        end

        % Value changed function: Slid_SnakeAlpha
        function Slid_SnakeAlphaValueChanged(app, event)
            app.snakePara.Alpha = app.Slid_SnakeAlpha.Value;
            app.Ed_SnakeAlpha.Value = app.Slid_SnakeAlpha.Value;
        end

        % Value changed function: Ed_SnakeSigma
        function Ed_SnakeSigmaValueChanged(app, event)
            app.snakePara.Sigma = app.Ed_SnakeSigma.Value;
        end

        % Value changed function: Ed_SnakeDelta
        function Ed_SnakeDeltaValueChanged(app, event)
            app.snakePara.Delta = app.Ed_SnakeDelta.Value;   
        end

        % Menu selected function: Mn_LoadAudio
        function Mn_LoadAudioMenuSelected(app, event)
            if ~app.active, return; end
            if isBusy(app), return; end
            filterspec = {'*.wav;*.flac;*.mp3;*.ogg;*.aiff;*.aifc;*.aif;*.opus;*.mp4;*.m4a','All Audio Files'};
            [file, apath] = uigetfile(filterspec, 'multiselect', 'off');
            figure(app.UIFigure); % Get focus back to main figure
             if isequal(file,0),  return; end 
             FName = fullfile(apath, file);
             success = Read1AudioFile(app, FName);     
             if success && ~isempty(app.TextGridTier) 
                 drawTextGridIntervals(app); CopyTextGridLabelsToContour(app); 
             end
        end

        % Menu selected function: Mn_AddAudioOffset
        function Mn_AddAudioOffsetMenuSelected(app, event)
%             if ~app.active, return; end
            if isBusy(app), return; end

            defaultAnswer = {sprintf('%0.1f', app.audioOffset*1000)};
            answer = inputdlg('Enter audio offset in millisecond:',...
             'Audio Delay in ms', [1 50], defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
             if isempty(answer),  return; end
            answer = answer{1};
            if isempty(answer),  return; end
            delayMs = str2double(answer); 
             if isempty(answer),  return; end
             delay = delayMs /1000; 
             app.audioOffset = delay;
             if isempty(app.AUDIO), return; end
             %% if there is AUDIO:
             if ~isempty(app.AUDIO),  Read1AudioFile(app, app.AUDIO.FileName); end
             if ~isempty(app.TextGrid) && ~isempty(app.TextGridFilename)
                 success = loadTextGrid(app, app.TextGridFilename);
                if success, drawTextGridIntervals(app); end
             end
        end

        % Value changed function: Sw_ContourVisible
        function Sw_ContourVisibleValueChanged(app, event)
            value = app.Sw_ContourVisible.Value; 
            if ~strcmpi(value, app.contour_visible)
                app.contour_visible = app.Sw_ContourVisible.Value;
                updatePlotAnchors(app);
            end
        end

        % Menu selected function: Mn_FindMPP
        function Mn_FindMPPMenuSelected(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end
            app.flag_GetMppOn = true;
            app.Msg1.Value = { ...
                'CLICK & DRAG to select 1 cm interval ', 'to calculate MPP.'};
            set(app.UIFigure, 'pointer','crosshair'); % Set cursor to crosshair
        end

        % Menu selected function: Mn_ZoomIn, Mn_ZoomOut
        function Mn_AudioZoomMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            zoomScale =  event.Source.UserData;
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);  
            newDur = currentDur * zoomScale; 
            center = mean(xlm); 
            left = center - newDur/2; right = center + newDur/2;
            RefreshAudioAxis(app, [left, right]);
        end

        % Menu selected function: Mn_ScrollPageBack, Mn_ScrollPageForward
        function Mn_ScrollPageMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            % Shift auido view to current view duration * scrollRate
            % e.g., if scrollRate = 0.7, shift audio view to the right by
            % 70% of current view duration.
            scrollRate =  event.Source.UserData; 
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);         
            scroll = currentDur * scrollRate; 
            left = xlm(1) + scroll; right = xlm(2) + scroll;
            RefreshAudioAxis(app, [left, right]);
        end

        % Menu selected function: Mn_ZoomToAudioCursor
        function Mn_ZoomToAudioCursorMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return;end
            if isempty(app.AudioCursorTime), return;end
            % audioSel = app.handle_AudioSelection.XData(1:2);
            audioSel = app.audioSelectionTimeStamps;
            audioSelDur = audioSel(2) - audioSel(1);
            xlm = app.Ax1.XLim; currentDur = xlm(2) - xlm(1);  
            if audioSelDur > app.minAudioDisplayDur ...
                    && audioSelDur > app.minAudioSelectDur
                newDur = audioSelDur; cursorT = mean(audioSel); 
            else
                newDur = currentDur/2; cursorT = app.AudioCursorTime; 
            end
            left = cursorT - newDur/2; right = cursorT + newDur/2;
            RefreshAudioAxis(app, [left, right]);          
        end

        % Callback function: Mn_ViewAll, ViewSelectAllAudioButton
        function Mn_ViewAllMenuSelected(app, event)
            if ~app.active, return;end
            if app.flag_busy, return;end
            if isempty(app.AUDIO), return; end 
            audioDur = app.AUDIO.Dur; 
            if ~isequal( app.Ax1.XLim, [0, audioDur])
                RefreshAudioAxis(app, [0, audioDur]);    
            else
                SelectAudioFunc(app, 0, audioDur);
            end
        end

        % Button pushed function: Bn_Diagnose
        function Bn_DiagnoseButtonPushed(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end 
            SetStatus(app, 'busy')
            ANCHORS = {app.CONTOURS.ANCHORS}'; TRKRES = {app.CONTOURS.TRKRES}';
            ENERGY = cellfun(@(x) x.ENERGY, TRKRES, 'uniformoutput', false);
            try
                [app.diagnosticMatrix, app.diagnosticVariables] = ...
                    app.DiagnoseAnchors(ANCHORS, ENERGY); 
                RunDiagnosticFilters(app);
                plotUpdateContouredFramePointer(app);
                plotUpdateDiagnosis(app);
                
            catch
                 SetStatus(app, 'ready'); return
            end
             app.UIBadContourTable.Data = app.BadContourFrameIndex;
            SetStatus(app, 'ready')
        end

        % Button pushed function: Bn_GoTo
        function Bn_GoToButtonPushed(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end 
            iFrame = app.Ed_GoToN.Value; % 'event.Source.UserData': move forward by how many frames
            currentFrame = app.Ed_Frame.Value; 
            k = iFrame - currentFrame; 
            event.Source.UserData = k;
            Bn_ChangeFrameButtonPushed(app, event);
        end

        % Cell selection callback: UIBadContourTable
        function UIBadContourTableCellSelection(app, event)
            if ~app.active, return;end
            if isBusy(app), return; end
            if isempty(app.BadContourFrameIndex), return;end
            ind = event.Indices(1);
            iFrame = app.BadContourFrameIndex(ind);
            currentFrame = app.Ed_Frame.Value; 
            k = iFrame - currentFrame; 
            event.Source.UserData = k;
            Bn_ChangeFrameButtonPushed(app, event);
        end

        % Value changed function: Ck_ShowDiagOnAudio
        function Ck_ShowDiagOnAudioValueChanged(app, event)
            value = app.Ck_ShowDiagOnAudio.Value;
            if value
                app.diagnosis_visible = 'on';
            else
                app.diagnosis_visible = 'off';
            end
            if ~isempty(app.handle_diagnosed_frame_pointer) && isgraphics(app.handle_diagnosed_frame_pointer)
                app.handle_diagnosed_frame_pointer.Visible = app.diagnosis_visible;
            end
            if ~isempty(app.handle_contoured_frame_pointer) && isgraphics(app.handle_contoured_frame_pointer)
                app.handle_contoured_frame_pointer.Visible = app.diagnosis_visible;
            end
        end

        % Value changed function: Ed_savingFreq
        function Ed_savingFreqValueChanged(app, event)
            value = app.Ed_savingFreq.Value;
            tooltip = sprintf('AutoSave contours when tracking every %d frames, or when switching/closing video file.', value);
            app.Ed_savingFreq.Tooltip = tooltip;
            app.Ck_Autosave.Tooltip = tooltip;
        end

        % Selection changed function: BnG_EnhanceContrast
        function BnG_EnhanceContrastSelectionChanged(app, event)
%             contrastMethod = app.DBnG_EnhanceContrast.SelectedObject.Text;
            if ~app.active, return; end
            dispFrame(app, app.Ed_Frame.Value); updatePlotAnchors(app);   
        end

        % Value changed function: Bn_TrackFiles
        function Bn_TrackFilesValueChanged(app, event)
            if ~app.active, app.Bn_TrackFiles.Value = false; app.Bn_StopTrack.Value = false; app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false; app.flag_FileTrackingOn = false; return; end
            if isBusy(app), app.Bn_TrackFiles.Value = false; return; end
            SetStatus(app, 'busy');  
            app.Bn_StopTrack.Value = false;
           
            if isempty(app.FileList)
                app.Msg1.Value = 'No file(s) loaded. Load files first.';
                app.Bn_TrackFiles.Value = false; SetStatus(app, 'ready'); return;
            end
   
            method = app.DetectMethodGroup.UserData; % method: 'NeuralNet', 'SNAKE', 'NN+SNAKE'

            if strcmpi(method, 'SNAKE')
                 app.Msg1.Value = 'SNAKE only tracks on single file. Choose NN or NN+SNAKE.';
                 app.Bn_TrackFiles.Value = false; SetStatus(app, 'ready'); return;               
            end

            if (strcmpi(method, 'NeuralNet') || strcmpi(method, 'NN+SNAKE')) && isempty(app.net)
                app.Msg1.Value = 'No NN model loaded! Use "File"->"Load model".';
                app.Bn_TrackFiles.Value = false; app.Bn_TrackFrames.Value = false; app.flag_FrameTrackingOn = false;
                SetStatus(app, 'ready'); return;
            end 

            startFileInd = app.CurrentFileNumber;   totalFileNumber = numel(app.FileList);
            app.Bn_TrackFiles.Value = true;  app.flag_FileTrackingOn = true;
              % Start tracking:  
            for iFile = startFileInd : totalFileNumber
                if iFile == startFileInd % First (current) file:
                    StartFrame = app.Ed_Frame.Value;               
                else 
                    StartFrame = 1; 
                    success = LoadOneFileFromFilelist(app, iFile);
                    if ~success, continue; end
                end
                SetStatus(app, 'busy');  
                EndFrame = app.nFrames; 
                if StartFrame > EndFrame, continue; end % If it is the last frame, continue to the next file. 
                 [status, EndFrame] = TrackThruAllFrames(app, StartFrame, EndFrame, method);
                 % status = 0: error; 1: success; 2: user stopped.
                 switch status
                     case 0
                        app.Msg1.Value = 'Error!';
                     case 1
                        silent = true; Export2Mat(app, silent);
                         app.Msg1.Value = sprintf('Tracked 1 file. (Saved %d contours/frames)', EndFrame - StartFrame +1);
                     case 2
                         app.Msg1.Value = sprintf('User stopped. Tracked %d file(s). Contours in this file NOT saved yet.', iFile - startFileInd);
                         app.Bn_TrackFiles.Value = false; app.flag_FileTrackingOn = false;    
                         break
                 end
            end %for iFile = startFileInd : totalFileNumber
            app.Msg1.Value = sprintf('Tracked %d file(s).', iFile - startFileInd +1);
            app.Bn_TrackFiles.Value = false; app.flag_FileTrackingOn = false;          
            SetStatus(app, 'ready');
        end

        % Menu selected function: Mn_FitCircleFindOrigin
        function Mn_FitCircleFindOriginMenuSelected(app, event)
             if ~app.active, return;end
            if isBusy(app), return; end      
%             app.Msg1.Value = {'Origin'};  
            iFrame=app.Ed_Frame.Value;  anchors = app.CONTOURS(iFrame).ANCHORS;
            if isempty(anchors) || size(anchors,1) < 3
                app.Msg1.Value = {'Number of anchors must be >= 3.'};
                return
            end
            SetStatus(app, 'busy');
            %%
            try
                Par = app.CircleFitByPratt(anchors(:,1:2)); origin = Par(1:2); radius = Par(3);
            catch
                SetStatus(app, 'ready');  app.Msg1.Value = {'Failed to fit circle to anchors.'};
                return
            end
            SetStatus(app, 'ready'); 
            nPts = 20; arc = GenArc(anchors(:,1:2), origin, radius, nPts); 
            anchors = [arc, zeros(nPts,1)];
            xy = app.interpLine(anchors(:,1:2), app.nXYpoints, 'makima');
            %%
            nnMask = ones(app.height, app.width);  energy = []; method = 'FitCircle';
            %%
            app.CONTOURS(iFrame).ANCHORS = anchors; app.CONTOURS(iFrame).XY = xy; 
            app.CONTOURS(iFrame).TRKRES.ENERGY = energy; app.CONTOURS(iFrame).TRKRES.METHOD = method; 
            app.CONTOURS(iFrame).nnMASKS = nnMask;
            %%
            updatePlotAnchors(app); app.flag_ifAnchorModifed = true;
            app.Msg1.Value = sprintf('Origin = [%0.1f, %0.1f]', origin(1), origin(2)); 
            app.ORIGIN = origin;
            function arc = GenArc(anchors, origin, radius, nPts)
                anchors1 = anchors - origin; x = anchors1(:,1); y = anchors1(:,2);
                [Theta, r] = cart2pol(x,y);  
                minTheta = min(Theta); maxTheta = max(Theta); 
                newTheta = linspace(minTheta, maxTheta, nPts)';
                [x1, y1]= pol2cart(newTheta, repmat(radius, nPts,1));
                arc = [x1,y1] + origin; 
            end
        end

        % Menu selected function: Mn_ExportOrigin
        function Mn_ExportOriginMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            var = app.ORIGIN; outfn = '_origin'; 
            varname = 'origin'; var_disp_name =  'probe origin';
            if isempty(var)
                app.Msg1.Value = sprintf('"%s" does not exist.', var_disp_name);  return; 
            end
            SetStatus(app, 'busy');
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = [fullfile(videoPath, outfn) '_' vFn];
            ExportVariable(app, outFName, var, varname, var_disp_name);  
            SetStatus(app, 'ready'); 
        end

        % Menu selected function: Mn_ExportMPP
        function Mn_ExportMPPMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            var = app.MPP; outfn = '_MPP'; 
            varname = 'MPP'; var_disp_name =  'MPP';
            if isempty(var) 
                app.Msg1.Value = sprintf('"%s" does not exist.', var_disp_name); return; 
            end
            SetStatus(app, 'busy');
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = [fullfile(videoPath, outfn) '_' vFn];
            ExportVariable(app, outFName, var, varname, var_disp_name);     
            SetStatus(app, 'ready'); 
        end

        % Menu selected function: Mn_ExportCurrentAnchors
        function Mn_ExportCurrentAnchorsSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end
            iFrame=app.Ed_Frame.Value; 
            var = app.CONTOURS(iFrame).ANCHORS; outfn = '_anchors.mat'; 
            varname = 'anchors'; var_disp_name =  'anchors';
            if isempty(var)
                app.Msg1.Value = sprintf('"%s" do not exist.', var_disp_name); return; 
            end
            if size(var, 2) > 2, var = var(:,1:2);end
            SetStatus(app, 'busy');
            videoFName = app.vObj.Name; videoPath = app.vObj.Path;  
            [~, vFn, vExt]= fileparts(videoFName); %#ok<*ASGLU> 
            outFName = fullfile(videoPath, outfn);
            ExportVariable(app, outFName, var, varname, var_disp_name);     
            SetStatus(app, 'ready');             
        end

        % Menu selected function: Mn_ExtractSelectedVideo_Uncropped, 
        % ...and 1 other component
        function Mn_ExtractSelectedVideo_MenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end       
            if isempty(app.AUDIO), return; end
            if isempty(app.handle_AudioSelection), return; end
            if ~isgraphics(app.handle_AudioSelection), return; end
            SetStatus(app, 'busy');
            % x = app.handle_AudioSelection.XData(1:2);
            ifCrop = event.Source.UserData.Crop;
            if ifCrop
                rect = app.crop_rect; 
            else 
                rect = [];
            end
            x = app.audioSelectionTimeStamps;
            d = abs(x(2) - x(1));   t = [min(x), max(x)];    
            if d < app.minAudioPlayDur, msg = 'Too less frames selected!';  app.Msg1.Value = msg;  SetStatus(app, 'ready'); return; end   
            UIprogressDialog_handle = uiprogressdlg(app.UIFigure,'Title','Writing video file...', 'Indeterminate','on'); 
            fps = app.vObj.FrameRate; startTime = t(1); endTime = t(2);
            startFrame = floor(startTime * fps) + 1;  endFrame = floor(endTime * fps)+1;
            if startFrame < 1, startFrame = 1; end
            if endFrame > app.vObj.NumFrames, endFrame =  app.vObj.NumFrames; end
            % startTime = (startFrame -1) / fps; endTime = (endFrame -1) / fps;  
            nFrames = endFrame - startFrame +1; 
            if nFrames < 5,  msg = 'Too less frames selected!';  app.Msg1.Value = msg;  SetStatus(app, 'ready');  return;  end
            [success, msg, startTime, endTime, startFrame, endFrame, outputVideoFilename] = ...
            app.ExtractVideoByFrame(app.vObj, startFrame, endFrame, [],1, app.AUDIO, rect);
            [p, f, e]= fileparts(outputVideoFilename); 
            outputContoursFilename = fullfile(p, f);
            Export2Mat(app, 1,  outputContoursFilename, startFrame, endFrame);
            %% TextGrid:
            % app.TextGrid; app.TextGridFilename;
            if ~isempty(app.TextGrid)
                outTGFName = fullfile(p, [f '.TextGrid']);
                outTG = app.sliceTextGrid(app.TextGrid, startTime, endTime);
                app.WriteTextGrid(outTG, outTGFName, (endTime - startTime)); 
            end
            %%
            close(UIprogressDialog_handle); 
            app.Msg1.Value = msg; SetStatus(app, 'ready');   
        end

        % Menu selected function: Mn_SetStatusBusy, Mn_SetStatusReady
        function Mn_SetStatusMenuSelected(app, event)
             toStatus = event.Source.UserData;
             SetStatus(app, toStatus);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key; modifier = event.Modifier; %#ok<NASGU,NASGU>
            if length(app.keyPressBuffer) < app.keyPressBufferLength
                app.keyPressBuffer = [app.keyPressBuffer, key];
            else
                app.keyPressBuffer = [app.keyPressBuffer(2:end), key];
            end
            % fprintf('Key press: %s\n', app.keyPressBuffer)
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            % key = event.Key; modifier = event.Modifier; %#ok<NASGU,NASGU>
            admPwLen = length(app.AdminPassword);
            if length(app.keyPressBuffer) >= admPwLen
                if strcmpi(app.keyPressBuffer(end-admPwLen+1:end), app.AdminPassword)
                    ChangeUserGroup(app, 'admin');
                end
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            % key = event.Key; modifier = event.Modifier; %#ok<NASGU,NASGU>
            % fprintf('WinKeyRelease: %s\n', key)
        end

        % Menu selected function: Mn_ImportCropPara
        function Mn_ImportCropParaMenuSelected(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end       
            if isempty(app.vObj), app.Msg1.Value = 'No video.'; return; end   
            
            % Display uigetfile dialog
            filterspec = {'*.mat'}; [fn, modelpath] = uigetfile(filterspec);
            figure(app.UIFigure); % Get focus back to main figure
            fname = fullfile(modelpath, fn); 
            % Make sure user didn't cancel uigetfile dialog
            if isequal(fn,0),  return; end 
            success = loadCropPara(app, fname);
            if ~success
                uialert(app.UIFigure,'Invalid cropping parameters.','Error loading cropping parameters'); 
                return
            end
        end

        % Menu selected function: Mn_ContourInfo
        function Mn_ContourInfoMenuSelected(app, event)
            if isBusy(app), return; end
            title = 'Contour information';
            contours = app.CONTOURS; 
            if ~isempty(contours)
                contours(cellfun(@isempty,{contours.XY})) = [];% remove empty frames          
            else
                contours = [];
            end
            if isempty(contours)
                msg = 'No contour.';
            else
                NumContours = numel(contours);
                msg = sprintf(...
                ['Number of contours: %d\n' ...
                '\n'], ...
                NumContours);                
            end
            uialert(app.UIFigure, msg, title, 'icon', 'info'); 
        end

        % Value changed function: Ck_FlipY
        function Ck_FlipYValueChanged(app, event)
            if ~app.active; return; end
            if isBusy(app), return; end       
            if isempty(app.vObj), app.Msg1.Value = 'No video.'; return; end   
            %% Image display:
            % YDir = 'reverse' is default normal view; 'normal' is turning upsdie down;
            if app.Ck_FlipY.Value
                app.Ax0.YDir = 'normal';
            else
                app.Ax0.YDir = 'reverse';
            end
            
        end

        % Menu selected function: Mn_EnterCode
        function Mn_EnterCodeMenuSelected(app, event)
            if isBusy(app), return; end

            defaultAnswer = {''};
            answer = inputdlg('Enter registration code:',...
             'Enter code:', [1 50], defaultAnswer);
            figure(app.UIFigure); % Get focus back to main figure
            if strcmp(answer, app.AdminPassword)
                ChangeUserGroup(app, 'admin');
            end
        end

        % Button pushed function: Bn_SelectColorEMA
        function Bn_SelectColorEMAButtonPushed(app, event)
            % if ~app.active, return; end
            if isBusy(app), app.Msg1.Value = "System busy..."; return; end
            c = uisetcolor(app.ema_color,'Select color for EMA sensors');
            figure(app.UIFigure); % Get focus back to main figure
            if ~isempty(c)
                app.ema_color = c; app.Bn_SelectColorEMA.BackgroundColor = c;
                updatePlotEMA(app);
            end
        end

        % Value changed function: Ck_ShowEMA, Ck_ShowEMANAME, Ck_ShowPal, 
        % ...and 1 other component
        function Callback_UpdatePlotEMA(app, event)
            updatePlotEMA(app);
        end

        % Button pushed function: Bn_SelectColorPal
        function Bn_SelectColorPalButtonPushed(app, event)
            if isBusy(app), app.Msg1.Value = "System busy...";return; end
            c = uisetcolor(app.palate_color,'Select color for palatal trace');
            figure(app.UIFigure); % Get focus back to main figure
            if ~isempty(c)
                app.palate_color = c; app.Bn_SelectColorPal.BackgroundColor = c;
                updatePlotEMA(app);
            end            
        end

        % Menu selected function: LoadpalataltraceMenu
        function LoadpalataltraceMenuSelected(app, event)
            if ~app.active
                 uialert(app.UIFigure,'Video not loaded yet! Load video first.','Video not loaded yet'); 
                return; 
            end
            if isBusy(app), app.Msg1.Value = 'System busy...';return; end
            [f, p] = uigetfile('*.mat','Select palatal trace file ...');
            figure(app.UIFigure); % Get focus back to main figure
            % Make sure user didn't cancel uigetfile dialog
            if isequal(f,0),  return; end 
            matFName = fullfile(p, f);              
            pal = app.load1var(matFName);  
            if ~ispal(pal), app.Msg1.Value = 'Invalid palatal trace.'; return;end
            app.palate = pal; app.Msg1.Value = 'Palatal trace loaded.';
            updatePlotEMA(app);
            function yes = ispal(pal)
                if ~isnumeric(pal) || ~ismatrix(pal) || ndims(pal) ~=2, ...
                        yes = false; return;
                end %#ok<*ISMAT>
                [m, n] = size(pal);
                if m < 4 || n ~= 2 || max(pal(:)) < 1, yes = false; return; end
                yes = true;
            end
        end

        % Menu selected function: Mn_AppVariables
        function Mn_AppVariablesMenuSelected(app, event)
            if isBusy(app), return; end
            title = '';
            msg = sprintf('app.Ax0_norm_width=%d\napp.minDist= %0.2f\napp.nXYpoints=%d', ...
            app.Ax0_norm_width, app.minDist, app.nXYpoints);                
            uialert(app.UIFigure, msg, title, 'icon', 'info');          
        end

        % Menu selected function: Mn_EnterCropRect
        function Mn_EnterCropRectMenuSelected(app, event)
            if ~app.active, uialert(app.UIFigure,'Video not loaded yet! Load video first.','Video not loaded yet'); return; end
            if isBusy(app), app.Msg1.Value = 'System busy...';return; end
            % rect = app.crop_rect;         
            dlgTitle = 'Enter cropping parameters in pixels:';
            prompts = {'left (x min)', 'top (y min)', 'width (0 = all)', 'height (0 = all)'};
            defaultAnswers = {'1', '1', '0', '0'};
            dialogWidth = 40;
            fieldsizes = [1 dialogWidth; 1 dialogWidth; 1 dialogWidth; 1 dialogWidth];
            answers = inputdlg(prompts,...
             dlgTitle, fieldsizes, defaultAnswers);
            figure(app.UIFigure); % Get focus back to main figure
             if isempty(answers),  return; end
             rect = str2double(answers);
             if any(isnan(rect)), return;end
             H = app.vObj.Height; W = app.vObj.Width; 
             left = rect(1); top = rect(2); 
             if rect(3) == 0, rect(3) = W - left + 1; end
             if rect(4) == 0, rect(4) = H - top + 1; end
             [valid, msg] = validateCropRect(app, rect);
             if ~valid, app.Msg1.Value = msg; return; end
             app.crop_rect = rect; 
             do_crop(app, rect);
        end

        % Value changed function: Spn_TextGridFontSize
        function Spn_TextGridFontSizeValueChanged(app, event)
            value = app.Spn_TextGridFontSize.Value;
            app.PARAMS.TextGridFontSize = value;
            h = findobj(app.Ax1,'tag', 'TextGridLabel');
            if isempty(h), return;end
            for i = 1:numel(h)
                h(i).FontSize = app.PARAMS.TextGridFontSize;
            end
        end

        % Value changed function: Spn_PalLineWidth
        function Spn_PalLineWidthValueChanged(app, event)
            value = app.Spn_PalLineWidth.Value;
            app.pal_width = value;
            updatePlotEMA(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DeepEdge';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Mn_File = uimenu(app.UIFigure);
            app.Mn_File.Text = 'File';
            app.Mn_LoadModel = uimenu(app.Mn_File);
            app.Mn_LoadModel.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadModelMenuSelected, true);
            app.Mn_LoadModel.Text = 'Load model';
            app.Mn_LoadVideo = uimenu(app.Mn_File);
            app.Mn_LoadVideo.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideo.Text = 'Load video(s)';
            app.Mn_LoadContours = uimenu(app.Mn_File);
            app.Mn_LoadContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadContoursMenuSelected, true);
            app.Mn_LoadContours.Text = 'Load contours';
            app.Mn_LoadTextGrid = uimenu(app.Mn_File);
            app.Mn_LoadTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadTextGridMenuSelected, true);
            app.Mn_LoadTextGrid.Text = 'Load TextGrid';
            app.Mn_LoadVideoAndContours = uimenu(app.Mn_File);
            app.Mn_LoadVideoAndContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndContours.Text = 'Load video(s) + contours';
            app.Mn_LoadVideoAndTextGrid = uimenu(app.Mn_File);
            app.Mn_LoadVideoAndTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndTextGrid.Text = 'Load video(s) + TextGrid';
            app.Mn_LoadVideoAndContoursAndTextGrid = uimenu(app.Mn_File);
            app.Mn_LoadVideoAndContoursAndTextGrid.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadVideoPushed, true);
            app.Mn_LoadVideoAndContoursAndTextGrid.Text = 'Load video(s) + contours + TextGrid';
            app.Mn_LoadAudio = uimenu(app.Mn_File);
            app.Mn_LoadAudio.MenuSelectedFcn = createCallbackFcn(app, @Mn_LoadAudioMenuSelected, true);
            app.Mn_LoadAudio.Text = 'Load audio';
            app.Mn_ReselectTextGridTier = uimenu(app.Mn_File);
            app.Mn_ReselectTextGridTier.MenuSelectedFcn = createCallbackFcn(app, @Mn_ReselectTextGridTierMenuSelected, true);
            app.Mn_ReselectTextGridTier.Text = 'Re-select TextGrid tier';
            app.Mn_ShowSelectFromFileList = uimenu(app.Mn_File);
            app.Mn_ShowSelectFromFileList.MenuSelectedFcn = createCallbackFcn(app, @Mn_ShowSelectFromFileListMenuSelected, true);
            app.Mn_ShowSelectFromFileList.Text = 'Show/Select from loaded FileList';
            app.Mn_ImportCropPara = uimenu(app.Mn_File);
            app.Mn_ImportCropPara.MenuSelectedFcn = createCallbackFcn(app, @Mn_ImportCropParaMenuSelected, true);
            app.Mn_ImportCropPara.Text = 'Import cropping parameters';
            app.LoadpalataltraceMenu = uimenu(app.Mn_File);
            app.LoadpalataltraceMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpalataltraceMenuSelected, true);
            app.LoadpalataltraceMenu.Text = 'Load palatal trace';
            app.Mn_Export = uimenu(app.UIFigure);
            app.Mn_Export.Text = 'Export';
            app.Mn_ExportCurrentFrame = uimenu(app.Mn_Export);
            app.Mn_ExportCurrentFrame.Text = 'Current frame image';
            app.Mn_ExpImgRaw = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgRaw.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExpImg, true);
            app.Mn_ExpImgRaw.Text = 'raw';
            app.Mn_ExpImgCrop = uimenu(app.Mn_ExportCurrentFrame);
            app.Mn_ExpImgCrop.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExpImg, true);
            app.Mn_ExpImgCrop.Text = 'cropped';
            app.Mn_Anchors2TSV = uimenu(app.Mn_Export);
            app.Mn_Anchors2TSV.Text = 'Export anchors to .TSV';
            app.Mn_Anchors2TSV_Ori = uimenu(app.Mn_Anchors2TSV);
            app.Mn_Anchors2TSV_Ori.MenuSelectedFcn = createCallbackFcn(app, @Mn_Anchors2TSVSelected, true);
            app.Mn_Anchors2TSV_Ori.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the original (uncropped) image.'};
            app.Mn_Anchors2TSV_Ori.Text = 'original cartesian';
            app.Mn_Anchors2TSV_Crop = uimenu(app.Mn_Anchors2TSV);
            app.Mn_Anchors2TSV_Crop.MenuSelectedFcn = createCallbackFcn(app, @Mn_Anchors2TSVSelected, true);
            app.Mn_Anchors2TSV_Crop.Tooltip = {'Write anchors to tab-delmited text file (.tsv)  in cartesian coordinates of the cropped mage..'};
            app.Mn_Anchors2TSV_Crop.Text = 'cropped cartesian';
            app.Mn_SaveContours = uimenu(app.Mn_Export);
            app.Mn_SaveContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMatMenuSelected, true);
            app.Mn_SaveContours.Tooltip = {'Export results to MATLAB structure file (.mat)'};
            app.Mn_SaveContours.Text = 'Save contours (.mat file)';
            app.Mn_QuickSaveContours = uimenu(app.Mn_Export);
            app.Mn_QuickSaveContours.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMatMenuSelected, true);
            app.Mn_QuickSaveContours.Tooltip = {'Save & Overwrite existing MAT file'};
            app.Mn_QuickSaveContours.Accelerator = 's';
            app.Mn_QuickSaveContours.Text = 'Quick Save contours (.mat file)';
            app.Mn_ExportCropPara = uimenu(app.Mn_Export);
            app.Mn_ExportCropPara.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportCropParamtersMenuSelected, true);
            app.Mn_ExportCropPara.Tooltip = {'Save cropping parameters (left, top, width, height) to ''{video filename}_crop_rect.mat'''};
            app.Mn_ExportCropPara.Text = 'Export cropping paramters';
            app.Mn_ExportOrigin = uimenu(app.Mn_Export);
            app.Mn_ExportOrigin.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportOriginMenuSelected, true);
            app.Mn_ExportOrigin.Tooltip = {'Save detected probe origin to ''_origin.mat''.'};
            app.Mn_ExportOrigin.Text = 'Export Origin';
            app.Mn_ExportMPP = uimenu(app.Mn_Export);
            app.Mn_ExportMPP.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportMPPMenuSelected, true);
            app.Mn_ExportMPP.Tooltip = {'Save MPP (millimeter per pixel) to ''_MPP.mat''.'};
            app.Mn_ExportMPP.Text = 'Export MPP';
            app.Mn_ExportCurrentAnchors = uimenu(app.Mn_Export);
            app.Mn_ExportCurrentAnchors.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExportCurrentAnchorsSelected, true);
            app.Mn_ExportCurrentAnchors.Tooltip = {'Save anchors of the current frame to ''_anchors.mat'''};
            app.Mn_ExportCurrentAnchors.Text = 'Export current anchors';
            app.Mn_ExtractSelectedVideo_Uncropped = uimenu(app.Mn_Export);
            app.Mn_ExtractSelectedVideo_Uncropped.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExtractSelectedVideo_MenuSelected, true);
            app.Mn_ExtractSelectedVideo_Uncropped.Text = 'Extract selection as new video/audio/mat/TextGrid file (Uncropped)';
            app.Mn_ExtractSelectedVideo_cropped = uimenu(app.Mn_Export);
            app.Mn_ExtractSelectedVideo_cropped.MenuSelectedFcn = createCallbackFcn(app, @Mn_ExtractSelectedVideo_MenuSelected, true);
            app.Mn_ExtractSelectedVideo_cropped.Text = 'Extract selection as new video/audio/mat/TextGrid file (Cropped)';
            app.Mn_Command = uimenu(app.UIFigure);
            app.Mn_Command.Text = 'Command';
            app.Mn_EnterCropRect = uimenu(app.Mn_Command);
            app.Mn_EnterCropRect.MenuSelectedFcn = createCallbackFcn(app, @Mn_EnterCropRectMenuSelected, true);
            app.Mn_EnterCropRect.Text = 'Enter cropping parameters';
            app.Mn_PreviousFrame = uimenu(app.Mn_Command);
            app.Mn_PreviousFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Mn_PreviousFrame.Accelerator = 'B';
            app.Mn_PreviousFrame.Text = 'Previous frame';
            app.Mn_NextFrame = uimenu(app.Mn_Command);
            app.Mn_NextFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Mn_NextFrame.Accelerator = 'F';
            app.Mn_NextFrame.Text = 'Next frame';
            app.Mn_ApplyCurrentFrame = uimenu(app.Mn_Command);
            app.Mn_ApplyCurrentFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Mn_ApplyCurrentFrame.Accelerator = 'M';
            app.Mn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Mn_Track = uimenu(app.Mn_Command);
            app.Mn_Track.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_Track.Accelerator = 'T';
            app.Mn_Track.Text = 'Track continuously';
            app.Mn_StopTracking = uimenu(app.Mn_Command);
            app.Mn_StopTracking.MenuSelectedFcn = createCallbackFcn(app, @StopTracking, true);
            app.Mn_StopTracking.Accelerator = 'X';
            app.Mn_StopTracking.Text = 'Stop tracking/playback';
            app.Mn_DeleteAllAnchorsCurrentFrame = uimenu(app.Mn_Command);
            app.Mn_DeleteAllAnchorsCurrentFrame.MenuSelectedFcn = createCallbackFcn(app, @Bn_DelAnchorsButtonPushed, true);
            app.Mn_DeleteAllAnchorsCurrentFrame.Accelerator = 'Y';
            app.Mn_DeleteAllAnchorsCurrentFrame.Text = 'Delete all anchors @current/selected frame';
            app.Mn_TrackNextFrame = uimenu(app.Mn_Command);
            app.Mn_TrackNextFrame.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_TrackNextFrame.Accelerator = '1';
            app.Mn_TrackNextFrame.Text = 'Track next frame';
            app.Mn_TrackNFrames = uimenu(app.Mn_Command);
            app.Mn_TrackNFrames.MenuSelectedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Mn_TrackNFrames.Accelerator = '2';
            app.Mn_TrackNFrames.Text = 'Track next N frames';
            app.Mn_ApplySnake = uimenu(app.Mn_Command);
            app.Mn_ApplySnake.MenuSelectedFcn = createCallbackFcn(app, @Mn_ApplySnakeMenuSelected, true);
            app.Mn_ApplySnake.Accelerator = 'N';
            app.Mn_ApplySnake.Text = 'Apply Snake here';
            app.Mn_DeleteAllAnchorsinAllFrames = uimenu(app.Mn_Command);
            app.Mn_DeleteAllAnchorsinAllFrames.MenuSelectedFcn = createCallbackFcn(app, @Mn_DeleteAllAnchorsinAllFramesMenuSelected, true);
            app.Mn_DeleteAllAnchorsinAllFrames.Text = 'Reset/Delete All Anchors in All Frames';
            app.Mn_FindMPP = uimenu(app.Mn_Command);
            app.Mn_FindMPP.MenuSelectedFcn = createCallbackFcn(app, @Mn_FindMPPMenuSelected, true);
            app.Mn_FindMPP.Text = 'Measure 1 cm (Find mm/pixel)';
            app.Mn_FitCircleFindOrigin = uimenu(app.Mn_Command);
            app.Mn_FitCircleFindOrigin.MenuSelectedFcn = createCallbackFcn(app, @Mn_FitCircleFindOriginMenuSelected, true);
            app.Mn_FitCircleFindOrigin.Tooltip = {'Fit a circle to current anchors and find the origin of circle.'};
            app.Mn_FitCircleFindOrigin.Text = 'Fit circle / Find origin';
            app.Mn_Audio = uimenu(app.UIFigure);
            app.Mn_Audio.Text = 'Audio';
            app.Mn_AddAudioOffset = uimenu(app.Mn_Audio);
            app.Mn_AddAudioOffset.MenuSelectedFcn = createCallbackFcn(app, @Mn_AddAudioOffsetMenuSelected, true);
            app.Mn_AddAudioOffset.Text = 'Enter/Reset audio offset';
            app.Mn_ViewAll = uimenu(app.Mn_Audio);
            app.Mn_ViewAll.MenuSelectedFcn = createCallbackFcn(app, @Mn_ViewAllMenuSelected, true);
            app.Mn_ViewAll.Tooltip = {'Click once to view all; Click twice to select all. '};
            app.Mn_ViewAll.Accelerator = 'a';
            app.Mn_ViewAll.Text = 'View all / Select all';
            app.Mn_ZoomIn = uimenu(app.Mn_Audio);
            app.Mn_ZoomIn.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioZoomMenuSelected, true);
            app.Mn_ZoomIn.Accelerator = 'i';
            app.Mn_ZoomIn.Text = 'Zoom in';
            app.Mn_ZoomOut = uimenu(app.Mn_Audio);
            app.Mn_ZoomOut.MenuSelectedFcn = createCallbackFcn(app, @Mn_AudioZoomMenuSelected, true);
            app.Mn_ZoomOut.Accelerator = 'o';
            app.Mn_ZoomOut.Text = 'Zoom out';
            app.Mn_ScrollPageBack = uimenu(app.Mn_Audio);
            app.Mn_ScrollPageBack.MenuSelectedFcn = createCallbackFcn(app, @Mn_ScrollPageMenuSelected, true);
            app.Mn_ScrollPageBack.Text = 'Scroll page back (PageUp)';
            app.Mn_ScrollPageForward = uimenu(app.Mn_Audio);
            app.Mn_ScrollPageForward.MenuSelectedFcn = createCallbackFcn(app, @Mn_ScrollPageMenuSelected, true);
            app.Mn_ScrollPageForward.Text = 'Scroll page forward (PageDown)';
            app.Mn_ZoomToAudioCursor = uimenu(app.Mn_Audio);
            app.Mn_ZoomToAudioCursor.MenuSelectedFcn = createCallbackFcn(app, @Mn_ZoomToAudioCursorMenuSelected, true);
            app.Mn_ZoomToAudioCursor.Accelerator = 'c';
            app.Mn_ZoomToAudioCursor.Text = 'Zoom in to audio cursor';
            app.Mn_ZoomToSelection = uimenu(app.Mn_Audio);
            app.Mn_ZoomToSelection.MenuSelectedFcn = createCallbackFcn(app, @Mn_ApplySnakeMenuSelected, true);
            app.Mn_ZoomToSelection.Accelerator = 'N';
            app.Mn_ZoomToSelection.Text = 'Zoom to selection';
            app.Mn_PlaySelectedAudio = uimenu(app.Mn_Audio);
            app.Mn_PlaySelectedAudio.MenuSelectedFcn = createCallbackFcn(app, @Mn_PlaySelectedAudioMenuSelected, true);
            app.Mn_PlaySelectedAudio.Tooltip = {'Play video&audio of the selected duration.'};
            app.Mn_PlaySelectedAudio.Accelerator = 'p';
            app.Mn_PlaySelectedAudio.Text = 'Play selection';
            app.Mn_Info = uimenu(app.UIFigure);
            app.Mn_Info.Text = 'Info';
            app.Mn_VideoInfo = uimenu(app.Mn_Info);
            app.Mn_VideoInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_VideoInfoMenuSelected, true);
            app.Mn_VideoInfo.Text = 'Video Information';
            app.Mn_ModelInfo = uimenu(app.Mn_Info);
            app.Mn_ModelInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_ModelInfoMenuSelected, true);
            app.Mn_ModelInfo.Text = 'Model Information';
            app.Mn_ContourInfo = uimenu(app.Mn_Info);
            app.Mn_ContourInfo.MenuSelectedFcn = createCallbackFcn(app, @Mn_ContourInfoMenuSelected, true);
            app.Mn_ContourInfo.Text = 'Contour Information';
            app.Mn_EnterCode = uimenu(app.Mn_Info);
            app.Mn_EnterCode.MenuSelectedFcn = createCallbackFcn(app, @Mn_EnterCodeMenuSelected, true);
            app.Mn_EnterCode.Text = 'Enter code';
            app.Mn_AboutDeepEdge = uimenu(app.Mn_Info);
            app.Mn_AboutDeepEdge.MenuSelectedFcn = createCallbackFcn(app, @Mn_AboutDeepEdgeMenuSelected, true);
            app.Mn_AboutDeepEdge.Text = 'About DeepEdge for Ultrasound';
            app.AdminMenu = uimenu(app.UIFigure);
            app.AdminMenu.Visible = 'off';
            app.AdminMenu.Text = 'Admin';
            app.Mn_SetStatusReady = uimenu(app.AdminMenu);
            app.Mn_SetStatusReady.MenuSelectedFcn = createCallbackFcn(app, @Mn_SetStatusMenuSelected, true);
            app.Mn_SetStatusReady.Text = 'Set status: Ready';
            app.Mn_SetStatusBusy = uimenu(app.AdminMenu);
            app.Mn_SetStatusBusy.MenuSelectedFcn = createCallbackFcn(app, @Mn_SetStatusMenuSelected, true);
            app.Mn_SetStatusBusy.Text = 'Set status: Busy';
            app.Mn_AppVariables = uimenu(app.AdminMenu);
            app.Mn_AppVariables.MenuSelectedFcn = createCallbackFcn(app, @Mn_AppVariablesMenuSelected, true);
            app.Mn_AppVariables.Text = 'App variables';
            app.Ax0 = uiaxes(app.UIFigure);
            app.Ax0.XLim = [0 1];
            app.Ax0.ZLim = [0 1];
            app.Ax0.XTick = [];
            app.Ax0.XTickLabel = {'[ ]'};
            app.Ax0.YTick = [];
            app.Ax1 = uiaxes(app.UIFigure);
            app.Ax1.XTick = [];
            app.Ax1.XTickLabel = '';
            app.Ax1.YTick = [];
            app.Ax1.ZTick = [];
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File:';
            app.Ed_CurrentFile = uieditfield(app.UIFigure, 'text');
            app.Ed_CurrentFile.Editable = 'off';
            app.Ed_CurrentFile.Tooltip = {'Current filename'};
            app.Ed_FileInd = uieditfield(app.UIFigure, 'text');
            app.Ed_FileInd.Editable = 'off';
            app.Ed_FileInd.Tooltip = {'Index of file in File List'};
            app.Ed_FileInd.Value = '0/0';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame#:';
            app.Ed_Frame = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Frame.Limits = [0 Inf];
            app.Ed_Frame.ValueDisplayFormat = '%d';
            app.Ed_Frame.Editable = 'off';
            app.Ed_TotalFrames = uieditfield(app.UIFigure, 'text');
            app.Ed_TotalFrames.Editable = 'off';
            app.Ed_TotalFrames.Enable = 'off';
            app.Lp_Status = uilamp(app.UIFigure);
            app.Lp_Status.Tooltip = {'"Red": Busy;  "Green": ready; "Black" : not activated.'};
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time (s): ';
            app.Ed_Time = uieditfield(app.UIFigure, 'numeric');
            app.Ed_Time.Limits = [0 Inf];
            app.Ed_Time.ValueDisplayFormat = '%5.4g';
            app.Ed_Time.Editable = 'off';
            app.Ed_TotalTime = uieditfield(app.UIFigure, 'numeric');
            app.Ed_TotalTime.Limits = [0 Inf];
            app.Ed_TotalTime.ValueDisplayFormat = '%5.3g';
            app.Ed_TotalTime.Editable = 'off';
            app.Ed_TotalTime.Enable = 'off';
            app.Tabs = uitabgroup(app.UIFigure);
            app.Tab_Config = uitab(app.Tabs);
            app.Tab_Config.Tooltip = {''};
            app.Tab_Config.Title = 'Config.';
            app.Bn_CropButton = uibutton(app.Tab_Config, 'state');
            app.Bn_CropButton.ValueChangedFcn = createCallbackFcn(app, @Bn_SelectCropRectButtonPushed, true);
            app.Bn_CropButton.Text = 'Crop';
            app.Bn_Uncrop = uibutton(app.Tab_Config, 'push');
            app.Bn_Uncrop.ButtonPushedFcn = createCallbackFcn(app, @Bn_UncropButtonPushed, true);
            app.Bn_Uncrop.Text = 'Uncrop';
            app.Ed_CropRect = uieditfield(app.Tab_Config, 'text');
            app.Ed_CropRect.Editable = 'off';
            app.Ed_CropRect.Enable = 'off';
            app.Ed_CropRect.Tooltip = {'cropping parameters'};
            app.CropParaHelpMsg = uieditfield(app.Tab_Config, 'text');
            app.CropParaHelpMsg.Editable = 'off';
            app.CropParaHelpMsg.Enable = 'off';
            app.CropParaHelpMsg.Value = '(left, top, width, height)';
            app.Sw_ifInvert = uiswitch(app.Tab_Config, 'slider');
            app.Sw_ifInvert.Items = {'Normal', 'Invert'};
            app.Sw_ifInvert.ValueChangedFcn = createCallbackFcn(app, @Sw_ifInvertValueChanged, true);
            app.Sw_ifInvert.Value = 'Normal';
            app.Pn_SnakeParas = uipanel(app.Tab_Config);
            app.Pn_SnakeParas.Title = 'SNAKE parameters:';
            app.SigmaLabel = uilabel(app.Pn_SnakeParas);
            app.SigmaLabel.Text = 'Sigma:';
            app.Ed_SnakeSigma = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeSigma.Limits = [0 50];
            app.Ed_SnakeSigma.ValueDisplayFormat = '%d';
            app.Ed_SnakeSigma.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeSigmaValueChanged, true);
            app.Ed_SnakeSigma.Tooltip = {'Image gradient smoothness'};
            app.Ed_SnakeSigma.Value = 5;
            app.DeltaLabel = uilabel(app.Pn_SnakeParas);
            app.DeltaLabel.Text = 'Delta:';
            app.Ed_SnakeDelta = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeDelta.Limits = [0 50];
            app.Ed_SnakeDelta.ValueDisplayFormat = '%d';
            app.Ed_SnakeDelta.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeDeltaValueChanged, true);
            app.Ed_SnakeDelta.Tooltip = {'Snake local search margin'};
            app.Ed_SnakeDelta.Value = 2;
            app.BandPenaltyLabel = uilabel(app.Pn_SnakeParas);
            app.BandPenaltyLabel.Text = 'Band Penalty:';
            app.Ed_SnakeBandPenalty = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeBandPenalty.Limits = [0 20];
            app.Ed_SnakeBandPenalty.ValueDisplayFormat = '%d';
            app.Ed_SnakeBandPenalty.ValueChangedFcn = createCallbackFcn(app, @Ed_SnakeBandPenaltyValueChanged, true);
            app.Ed_SnakeBandPenalty.Value = 2;
            app.Lambda1Label_2 = uilabel(app.Pn_SnakeParas);
            app.Lambda1Label_2.Text = 'Alpha:';
            app.Slid_SnakeAlpha = uislider(app.Pn_SnakeParas);
            app.Slid_SnakeAlpha.Limits = [0 1];
            app.Slid_SnakeAlpha.MajorTicks = [];
            app.Slid_SnakeAlpha.ValueChangedFcn = createCallbackFcn(app, @Slid_SnakeAlphaValueChanged, true);
            app.Slid_SnakeAlpha.MinorTicks = [];
            app.Slid_SnakeAlpha.Tooltip = {'Internal energy weight'};
            app.Slid_SnakeAlpha.Value = 0.7;
            app.Ed_SnakeAlpha = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeAlpha.Limits = [0 1];
            app.Ed_SnakeAlpha.ValueDisplayFormat = '%0.2g';
            app.Ed_SnakeAlpha.Editable = 'off';
            app.Ed_SnakeAlpha.Enable = 'off';
            app.Ed_SnakeAlpha.Tooltip = {'Internal energy weight.  Beta (external energy weight) = 1 - Alpha'};
            app.Ed_SnakeAlpha.Value = 0.7;
            app.Lambda1Label = uilabel(app.Pn_SnakeParas);
            app.Lambda1Label.Text = 'Lambda1:';
            app.Slid_SnakeLambda1 = uislider(app.Pn_SnakeParas);
            app.Slid_SnakeLambda1.Limits = [0 1];
            app.Slid_SnakeLambda1.MajorTicks = [];
            app.Slid_SnakeLambda1.ValueChangedFcn = createCallbackFcn(app, @Slid_SnakeLambda1ValueChanged, true);
            app.Slid_SnakeLambda1.MinorTicks = [];
            app.Slid_SnakeLambda1.Tooltip = {'Contour roughness weight'};
            app.Slid_SnakeLambda1.Value = 0.7;
            app.Ed_SnakeLambda1 = uieditfield(app.Pn_SnakeParas, 'numeric');
            app.Ed_SnakeLambda1.Limits = [0 1];
            app.Ed_SnakeLambda1.ValueDisplayFormat = '%0.2g';
            app.Ed_SnakeLambda1.Editable = 'off';
            app.Ed_SnakeLambda1.Enable = 'off';
            app.Ed_SnakeLambda1.Tooltip = {'smoothness weight.  Lambda2 (evenness weight) = 1-Lambda1  '};
            app.Ed_SnakeLambda1.Value = 0.7;
            app.Bn_SnakeDefault = uibutton(app.Pn_SnakeParas, 'push');
            app.Bn_SnakeDefault.ButtonPushedFcn = createCallbackFcn(app, @Bn_SnakeDefaultButtonPushed, true);
            app.Bn_SnakeDefault.Tooltip = {'Click to set to default values'};
            app.Bn_SnakeDefault.Text = 'Set SNAKE default';
            app.everyEditFieldLabel = uilabel(app.Tab_Config);
            app.everyEditFieldLabel.Text = 'every';
            app.Ed_savingFreq = uieditfield(app.Tab_Config, 'numeric');
            app.Ed_savingFreq.Limits = [1 100000];
            app.Ed_savingFreq.RoundFractionalValues = 'on';
            app.Ed_savingFreq.ValueDisplayFormat = '%d';
            app.Ed_savingFreq.ValueChangedFcn = createCallbackFcn(app, @Ed_savingFreqValueChanged, true);
            app.Ed_savingFreq.Tooltip = {''};
            app.Ed_savingFreq.Value = 2000;
            app.Ck_Autosave = uicheckbox(app.Tab_Config);
            app.Ck_Autosave.ValueChangedFcn = createCallbackFcn(app, @Ck_AutosaveValueChanged, true);
            app.Ck_Autosave.Tooltip = {''};
            app.Ck_Autosave.Text = 'AutoSave';
            app.Txt_tracked_frames = uilabel(app.Tab_Config);
            app.Txt_tracked_frames.Text = 'tracked frames';
            app.BnG_EnhanceContrast = uibuttongroup(app.Tab_Config);
            app.BnG_EnhanceContrast.SelectionChangedFcn = createCallbackFcn(app, @BnG_EnhanceContrastSelectionChanged, true);
            app.BnG_EnhanceContrast.Title = 'Enhance contrast:';
            app.Bn_NoEnhance = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_NoEnhance.Text = 'None';
            app.Bn_NoEnhance.Value = true;
            app.Bn_Adjust = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_Adjust.Tooltip = {'Saturates top and bottom 1% of all pixels. See ''imadjust.m'''};
            app.Bn_Adjust.Text = 'Adjust';
            app.Bn_HistEq = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_HistEq.Tooltip = {'histogram equalization. See ''histeq.m'''};
            app.Bn_HistEq.Text = 'HistEq';
            app.Bn_AdaptHistEq = uiradiobutton(app.BnG_EnhanceContrast);
            app.Bn_AdaptHistEq.Tooltip = {'contrast-limited adaptive histogram equalization. See ''adapthisteq.m'''};
            app.Bn_AdaptHistEq.Text = 'AdaptHistEq';
            app.Ck_FlipY = uicheckbox(app.Tab_Config);
            app.Ck_FlipY.ValueChangedFcn = createCallbackFcn(app, @Ck_FlipYValueChanged, true);
            app.Ck_FlipY.Text = 'Flip Y axis';
            app.AnchorTab = uitab(app.Tabs);
            app.AnchorTab.Title = 'Anchor';
            app.ClicktoaddanchorsLabel = uilabel(app.AnchorTab);
            app.ClicktoaddanchorsLabel.Text = 'Click to add anchors:';
            app.Sw_ClickAnchor = uiswitch(app.AnchorTab, 'slider');
            app.Sw_ClickAnchor.ValueChangedFcn = createCallbackFcn(app, @Sw_ClickAnchorValueChanged, true);
            app.Sw_ClickAnchor.Value = 'On';
            app.AnchorsizeSliderLabel = uilabel(app.AnchorTab);
            app.AnchorsizeSliderLabel.Text = 'Anchor size:';
            app.Slid_AnchorSize = uislider(app.AnchorTab);
            app.Slid_AnchorSize.Limits = [10 200];
            app.Slid_AnchorSize.MajorTicks = [];
            app.Slid_AnchorSize.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotAnchors, true);
            app.Slid_AnchorSize.MinorTicks = [];
            app.Slid_AnchorSize.Value = 50;
            app.OpacityLabel = uilabel(app.AnchorTab);
            app.OpacityLabel.Text = 'Opacity:';
            app.Slid_AnchorAlpha = uislider(app.AnchorTab);
            app.Slid_AnchorAlpha.Limits = [10 90];
            app.Slid_AnchorAlpha.MajorTicks = [];
            app.Slid_AnchorAlpha.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotAnchors, true);
            app.Slid_AnchorAlpha.MinorTicks = [];
            app.Slid_AnchorAlpha.Value = 30;
            app.Bn_DelAnchors = uibutton(app.AnchorTab, 'push');
            app.Bn_DelAnchors.ButtonPushedFcn = createCallbackFcn(app, @Bn_DelAnchorsButtonPushed, true);
            app.Bn_DelAnchors.Tooltip = {'Delete all anchors in this/selected frame(s)'; '(Ctrl+Y)'};
            app.Bn_DelAnchors.Text = 'Delete all anchors';
            app.ContourvisibleSwitchLabel = uilabel(app.AnchorTab);
            app.ContourvisibleSwitchLabel.Text = 'Contour visible: ';
            app.Sw_ContourVisible = uiswitch(app.AnchorTab, 'slider');
            app.Sw_ContourVisible.ValueChangedFcn = createCallbackFcn(app, @Sw_ContourVisibleValueChanged, true);
            app.Sw_ContourVisible.Value = 'On';
            app.UIAnchorTable = uitable(app.AnchorTab);
            app.UIAnchorTable.ColumnName = {'X'; 'Y'};
            app.UIAnchorTable.SelectionType = 'row';
            app.Ck_ShowAnchorOrder = uicheckbox(app.AnchorTab);
            app.Ck_ShowAnchorOrder.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotAnchors, true);
            app.Ck_ShowAnchorOrder.Tooltip = {'Show anchor order'};
            app.Ck_ShowAnchorOrder.Text = 'Show anchor order number';
            app.Ck_ShowAnchorOrder.Value = true;
            app.AnchororderfontsizeSpinnerLabel = uilabel(app.AnchorTab);
            app.AnchororderfontsizeSpinnerLabel.Text = 'Anchor order font size';
            app.Spn_AnchorOrderFontSize = uispinner(app.AnchorTab);
            app.Spn_AnchorOrderFontSize.Limits = [2 20];
            app.Spn_AnchorOrderFontSize.ValueDisplayFormat = '%01d';
            app.Spn_AnchorOrderFontSize.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotAnchors, true);
            app.Spn_AnchorOrderFontSize.Value = 8;
            app.Ck_ShowEMA = uicheckbox(app.AnchorTab);
            app.Ck_ShowEMA.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotEMA, true);
            app.Ck_ShowEMA.Text = 'Show EMA sensors';
            app.Ck_ShowEMA.Value = true;
            app.Bn_SelectColorEMA = uibutton(app.AnchorTab, 'push');
            app.Bn_SelectColorEMA.ButtonPushedFcn = createCallbackFcn(app, @Bn_SelectColorEMAButtonPushed, true);
            app.Bn_SelectColorEMA.Tooltip = {'Select color for EMA sensors'};
            app.Bn_SelectColorEMA.Text = 'color';
            app.Ck_ShowEMANAME = uicheckbox(app.AnchorTab);
            app.Ck_ShowEMANAME.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotEMA, true);
            app.Ck_ShowEMANAME.Text = 'Show EMA Names';
            app.fontsizeLabel = uilabel(app.AnchorTab);
            app.fontsizeLabel.Text = 'font size=';
            app.Spn_EMANameFontSize = uispinner(app.AnchorTab);
            app.Spn_EMANameFontSize.Limits = [2 20];
            app.Spn_EMANameFontSize.ValueDisplayFormat = '%01d';
            app.Spn_EMANameFontSize.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotEMA, true);
            app.Spn_EMANameFontSize.Value = 8;
            app.Ck_ShowPal = uicheckbox(app.AnchorTab);
            app.Ck_ShowPal.ValueChangedFcn = createCallbackFcn(app, @Callback_UpdatePlotEMA, true);
            app.Ck_ShowPal.Text = 'Show palatal trace';
            app.Bn_SelectColorPal = uibutton(app.AnchorTab, 'push');
            app.Bn_SelectColorPal.ButtonPushedFcn = createCallbackFcn(app, @Bn_SelectColorPalButtonPushed, true);
            app.Bn_SelectColorPal.Tooltip = {'Select color for palatal trace'};
            app.Bn_SelectColorPal.Text = 'color';
            app.EraserwidthLabel = uilabel(app.AnchorTab);
            app.EraserwidthLabel.Text = 'Eraser width: ';
            app.Slid_EraserWidth = uislider(app.AnchorTab);
            app.Slid_EraserWidth.Limits = [1 50];
            app.Slid_EraserWidth.MajorTicks = [];
            app.Slid_EraserWidth.MinorTicks = [];
            app.Slid_EraserWidth.Value = 8;
            app.TextGridfontsizeLabel = uilabel(app.AnchorTab);
            app.TextGridfontsizeLabel.Text = 'TextGrid font size:';
            app.Spn_TextGridFontSize = uispinner(app.AnchorTab);
            app.Spn_TextGridFontSize.Limits = [2 50];
            app.Spn_TextGridFontSize.ValueDisplayFormat = '%d';
            app.Spn_TextGridFontSize.ValueChangedFcn = createCallbackFcn(app, @Spn_TextGridFontSizeValueChanged, true);
            app.Spn_TextGridFontSize.Value = 9;
            app.linewidthSpinnerLabel = uilabel(app.AnchorTab);
            app.linewidthSpinnerLabel.Text = ':line width:';
            app.Spn_PalLineWidth = uispinner(app.AnchorTab);
            app.Spn_PalLineWidth.Limits = [1 20];
            app.Spn_PalLineWidth.ValueDisplayFormat = '%d';
            app.Spn_PalLineWidth.ValueChangedFcn = createCallbackFcn(app, @Spn_PalLineWidthValueChanged, true);
            app.Spn_PalLineWidth.Value = 2;
            app.DeepEdgeTab = uitab(app.Tabs);
            app.DeepEdgeTab.Title = 'DeepEdge';
            app.DetectMethodGroup = uibuttongroup(app.DeepEdgeTab);
            app.DetectMethodGroup.SelectionChangedFcn = createCallbackFcn(app, @DetectMethodGroupSelectionChanged, true);
            app.DetectMethodGroup.Title = 'Select algorithm:';
            app.DetectMethodGroup.Tag = 'DeteckMethodGroup';
            app.Ck_NN = uiradiobutton(app.DetectMethodGroup);
            app.Ck_NN.Tooltip = {'predict contour by Neural Network model'};
            app.Ck_NN.Text = 'NeuralNet';
            app.Ck_Snake = uiradiobutton(app.DetectMethodGroup);
            app.Ck_Snake.Tooltip = {'apply SNAKE using contour of previous frame'};
            app.Ck_Snake.Text = 'SNAKE';
            app.Ck_NNSnake = uiradiobutton(app.DetectMethodGroup);
            app.Ck_NNSnake.Tooltip = {'Predict contour by Neural Network model then refined by SNAKE'};
            app.Ck_NNSnake.Text = 'NN+SNAKE';
            app.Ck_NNSnake.Value = true;
            app.Ck_None = uiradiobutton(app.DetectMethodGroup);
            app.Ck_None.Tooltip = {'Nothing. Tracking will be just playing the video. '};
            app.Ck_None.Text = 'None';
            app.Bn_ApplyCurrentFrame = uibutton(app.DeepEdgeTab, 'push');
            app.Bn_ApplyCurrentFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ApplyCurrentFramePushed, true);
            app.Bn_ApplyCurrentFrame.Tooltip = {'(Ctrl+M) Apply contour detection algorithm on this frame.'};
            app.Bn_ApplyCurrentFrame.Text = 'Apply to current frame';
            app.Ck_ShowNNMask = uicheckbox(app.DeepEdgeTab);
            app.Ck_ShowNNMask.ValueChangedFcn = createCallbackFcn(app, @Ck_ShowNNMaskValueChanged, true);
            app.Ck_ShowNNMask.Text = 'Show NN Mask';
            app.Ck_ShowNNMask.Value = true;
            app.NNProbThreshSpinnerLabel = uilabel(app.DeepEdgeTab);
            app.NNProbThreshSpinnerLabel.Text = 'NN Prob. Thresh.';
            app.Spn_NNProbThresh = uispinner(app.DeepEdgeTab);
            app.Spn_NNProbThresh.Step = 0.05;
            app.Spn_NNProbThresh.Limits = [0.1 0.9];
            app.Spn_NNProbThresh.ValueDisplayFormat = '%0.2g';
            app.Spn_NNProbThresh.Tooltip = {'Threhold of NN output probability (0.1~0.9) Default = 0.4.'};
            app.Spn_NNProbThresh.Value = 0.4;
            app.TrackingPanel = uipanel(app.DeepEdgeTab);
            app.TrackingPanel.Title = 'Tracking:';
            app.Bn_TrackFrames = uibutton(app.TrackingPanel, 'state');
            app.Bn_TrackFrames.ValueChangedFcn = createCallbackFcn(app, @StartTracking, true);
            app.Bn_TrackFrames.Tooltip = {'Ctrl+T'};
            app.Bn_TrackFrames.Text = 'Track thru Frames in this file';
            app.Bn_TrackFiles = uibutton(app.TrackingPanel, 'state');
            app.Bn_TrackFiles.ValueChangedFcn = createCallbackFcn(app, @Bn_TrackFilesValueChanged, true);
            app.Bn_TrackFiles.Tooltip = {'Tracking starts from the next frame to the last frame in the current file.'};
            app.Bn_TrackFiles.Text = 'Track thru Files in list (auto-save on)';
            app.Bn_StopTrack = uibutton(app.TrackingPanel, 'state');
            app.Bn_StopTrack.ValueChangedFcn = createCallbackFcn(app, @StopTracking, true);
            app.Bn_StopTrack.Tooltip = {'Stop tracking/playback (Ctrl+X)'};
            app.Bn_StopTrack.Text = 'STOP';
            app.DelaymsecLabel = uilabel(app.TrackingPanel);
            app.DelaymsecLabel.Tooltip = {'Delay (10 ~ 5000 msec.) tracking between frames'};
            app.DelaymsecLabel.Text = 'Pause between frames (ms):';
            app.Ed_TrackingDelayMs = uieditfield(app.TrackingPanel, 'numeric');
            app.Ed_TrackingDelayMs.Limits = [0 5000];
            app.Ed_TrackingDelayMs.ValueChangedFcn = createCallbackFcn(app, @Ed_TrackingDelayMsValueChanged, true);
            app.Ed_TrackingDelayMs.Tooltip = {'Pause (0 ~ 5000 msec.) between frames.'};
            app.Ed_TrackingDelayMs.Value = 20;
            app.Ck_TrackOnlyNonEmptyTG = uicheckbox(app.TrackingPanel);
            app.Ck_TrackOnlyNonEmptyTG.Tooltip = {'Skip empty labels in TextGrid'};
            app.Ck_TrackOnlyNonEmptyTG.Text = 'Only track non-empty in TextGrid';
            app.Label = uilabel(app.TrackingPanel);
            app.Label.Text = '+ ';
            app.Ed_PlusMsAtEdge = uieditfield(app.TrackingPanel, 'numeric');
            app.Ed_PlusMsAtEdge.Limits = [0 5000];
            app.Ed_PlusMsAtEdge.RoundFractionalValues = 'on';
            app.Ed_PlusMsAtEdge.ValueDisplayFormat = '%d';
            app.Ed_PlusMsAtEdge.Value = 100;
            app.Txt_atEdge = uilabel(app.TrackingPanel);
            app.Txt_atEdge.Text = 'ms at edges';
            app.DiagnosticTab = uitab(app.Tabs);
            app.DiagnosticTab.Title = 'Diagnostic';
            app.Pn_SnakeEngergy = uipanel(app.DiagnosticTab);
            app.Pn_SnakeEngergy.Title = 'Contour Info:';
            app.JointenergyLabel = uilabel(app.Pn_SnakeEngergy);
            app.JointenergyLabel.Text = 'Joint energy:';
            app.MeanEditFieldLabel = uilabel(app.Pn_SnakeEngergy);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.Ed_SnakeEnergyMean = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyMean.Editable = 'off';
            app.MaxEditFieldLabel = uilabel(app.Pn_SnakeEngergy);
            app.MaxEditFieldLabel.Text = 'Max';
            app.Ed_SnakeEnergyMax = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyMax.Editable = 'off';
            app.Q3Label = uilabel(app.Pn_SnakeEngergy);
            app.Q3Label.Text = 'Q3';
            app.Ed_SnakeEnergyQ3 = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_SnakeEnergyQ3.Editable = 'off';
            app.SmoothnessLabel = uilabel(app.Pn_SnakeEngergy);
            app.SmoothnessLabel.Text = ' Roughness:';
            app.Ed_Roughness = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_Roughness.Editable = 'off';
            app.Ed_Roughness.Tooltip = {'Roughness in internal energy'};
            app.SmoothnessLabel_2 = uilabel(app.Pn_SnakeEngergy);
            app.SmoothnessLabel_2.Text = 'Length:';
            app.Ed_Length = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_Length.Editable = 'off';
            app.Ed_Length.Tooltip = {'Current contour length'};
            app.SmoothnessLabel_5 = uilabel(app.Pn_SnakeEngergy);
            app.SmoothnessLabel_5.Text = 'IFDist:';
            app.Ed_IFDist = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_IFDist.Editable = 'off';
            app.Ed_IFDist.Tooltip = {'Inter-frame Distance (IFDist): mean distance between current contour to contours in the adjacent frames. '};
            app.SmoothnessLabel_4 = uilabel(app.Pn_SnakeEngergy);
            app.SmoothnessLabel_4.Text = 'Med.IFDist =';
            app.Ed_MedianIFDist = uieditfield(app.Pn_SnakeEngergy, 'text');
            app.Ed_MedianIFDist.Editable = 'off';
            app.Ed_MedianIFDist.Tooltip = {'Median inter-frame distance all frames.'};
            app.Pn_DiagnoseFilter = uipanel(app.DiagnosticTab);
            app.Pn_DiagnoseFilter.Title = 'Diagnostic filters:';
            app.JointEnergyLabel = uilabel(app.Pn_DiagnoseFilter);
            app.JointEnergyLabel.Text = 'Joint Energy:';
            app.Ck_EnergyMeanFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyMeanFilter.Text = 'Mean > ';
            app.Ed_EnergyMeanFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyMeanFilter.Limits = [0 2];
            app.Ed_EnergyMeanFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyMeanFilter.Tooltip = {'Mean joint energy > this value.'};
            app.Ed_EnergyMeanFilter.Value = 0.35;
            app.Ck_EnergyMaxFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyMaxFilter.Text = 'Max > ';
            app.Ck_EnergyMaxFilter.Value = true;
            app.Ed_EnergyMaxFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyMaxFilter.Limits = [0 2];
            app.Ed_EnergyMaxFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyMaxFilter.Tooltip = {'Max energy of all anchors > this value.'};
            app.Ed_EnergyMaxFilter.Value = 0.6;
            app.Ck_EnergyQ3Filter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_EnergyQ3Filter.Text = 'Q3 > ';
            app.Ed_EnergyQ3Filter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_EnergyQ3Filter.Limits = [0 2];
            app.Ed_EnergyQ3Filter.ValueDisplayFormat = '%0.4g';
            app.Ed_EnergyQ3Filter.Value = 0.7;
            app.RoughnessLabel = uilabel(app.Pn_DiagnoseFilter);
            app.RoughnessLabel.Text = 'Roughness >';
            app.Ck_RoughnessFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_RoughnessFilter.Text = '';
            app.Ck_RoughnessFilter.Value = true;
            app.Ed_RoughnessFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_RoughnessFilter.Limits = [0 1];
            app.Ed_RoughnessFilter.ValueDisplayFormat = '%0.4g';
            app.Ed_RoughnessFilter.Value = 0.04;
            app.Ck_LengthFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_LengthFilter.Tooltip = {'Length filter (active when # of contoured frames > 10)'};
            app.Ck_LengthFilter.Text = '';
            app.Ck_LengthFilter.Value = true;
            app.Txt_Length = uilabel(app.Pn_DiagnoseFilter);
            app.Txt_Length.Text = 'Length <';
            app.PercentLabel = uilabel(app.Pn_DiagnoseFilter);
            app.PercentLabel.Text = '%, or > ';
            app.Ed_LengthMaxFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_LengthMaxFilter.Limits = [101 200];
            app.Ed_LengthMaxFilter.Tooltip = {'Contour length > ?% of median contour length'};
            app.Ed_LengthMaxFilter.Value = 150;
            app.PercentLabel_2 = uilabel(app.Pn_DiagnoseFilter);
            app.PercentLabel_2.Text = '%';
            app.Bn_Diagnose = uibutton(app.Pn_DiagnoseFilter, 'push');
            app.Bn_Diagnose.ButtonPushedFcn = createCallbackFcn(app, @Bn_DiagnoseButtonPushed, true);
            app.Bn_Diagnose.Tooltip = {'Click to run diagnostics'};
            app.Bn_Diagnose.Text = 'Run Diagnostics';
            app.Ed_LengthMinFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_LengthMinFilter.Limits = [1 99];
            app.Ed_LengthMinFilter.Tooltip = {'Contour length < ?% of median contour length'};
            app.Ed_LengthMinFilter.Value = 70;
            app.PercentLabel_3 = uilabel(app.Pn_DiagnoseFilter);
            app.PercentLabel_3.Tooltip = {'Inter-frame distance (IFDist) filter (active when # of contoured frames > 10)'};
            app.PercentLabel_3.Text = '%';
            app.Ed_IFDistFilter = uieditfield(app.Pn_DiagnoseFilter, 'numeric');
            app.Ed_IFDistFilter.Limits = [101 200];
            app.Ed_IFDistFilter.Tooltip = {'Inter-frame distance (IFDist) filter (active when # of contoured frames > 10)'};
            app.Ed_IFDistFilter.Value = 150;
            app.Txt_IFDist = uilabel(app.Pn_DiagnoseFilter);
            app.Txt_IFDist.Tooltip = {'Inter-frame distance (IFDist) filter (active when # of contoured frames > 10)'};
            app.Txt_IFDist.Text = 'Inter-frame Dist. >';
            app.Ck_IFDistFilter = uicheckbox(app.Pn_DiagnoseFilter);
            app.Ck_IFDistFilter.Tooltip = {'Inter-frame distance (IFDist) filter (active when # of contoured frames > 10)'};
            app.Ck_IFDistFilter.Text = '';
            app.Ck_IFDistFilter.Value = true;
            app.UIBadContourTable = uitable(app.DiagnosticTab);
            app.UIBadContourTable.ColumnName = {'Frame'};
            app.UIBadContourTable.SelectionType = 'row';
            app.UIBadContourTable.CellSelectionCallback = createCallbackFcn(app, @UIBadContourTableCellSelection, true);
            app.UIBadContourTable.Multiselect = 'off';
            app.Ck_ShowDiagOnAudio = uicheckbox(app.DiagnosticTab);
            app.Ck_ShowDiagOnAudio.ValueChangedFcn = createCallbackFcn(app, @Ck_ShowDiagOnAudioValueChanged, true);
            app.Ck_ShowDiagOnAudio.Text = 'Show on audio';
            app.Ck_ShowDiagOnAudio.Value = true;
            app.SmoothnessLabel_3 = uilabel(app.DiagnosticTab);
            app.SmoothnessLabel_3.Text = 'Median length =';
            app.Ed_MedianLength = uieditfield(app.DiagnosticTab, 'text');
            app.Ed_MedianLength.Editable = 'off';
            app.Ed_MedianLength.Tooltip = {'Median contour length across all frames.'};
            app.Bn_PreviousFile = uibutton(app.UIFigure, 'push');
            app.Bn_PreviousFile.ButtonPushedFcn = createCallbackFcn(app, @Bn_BrowseFileButtonPushed, true);
            app.Bn_PreviousFile.Tooltip = {'Previous File'};
            app.Bn_PreviousFile.Text = '< File';
            app.Pn_FrameControl = uipanel(app.UIFigure);
            app.Bn_FirstFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_FirstFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_FirstFrame.Tooltip = {'First frame'};
            app.Bn_FirstFrame.Text = '|<';
            app.Ed_PreviousN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_PreviousN.Limits = [1 Inf];
            app.Ed_PreviousN.ValueDisplayFormat = '%.0f';
            app.Ed_PreviousN.ValueChangedFcn = createCallbackFcn(app, @Ed_PreviousNValueChanged, true);
            app.Ed_PreviousN.Tooltip = {'Jump to previous N frame'};
            app.Ed_PreviousN.Value = 3;
            app.Bn_PreviousNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousNFrame.Tooltip = {'Jump to previous N frame'};
            app.Bn_PreviousNFrame.Text = '<<';
            app.Bn_PreviousFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_PreviousFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_PreviousFrame.Tooltip = {'Previous frame  (hot key: left arrow)'};
            app.Bn_PreviousFrame.Text = '<';
            app.Bn_NextFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextFrame.Tooltip = {'Next Frame (hot key: right arrow)'};
            app.Bn_NextFrame.Text = '>';
            app.Bn_NextNFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_NextNFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_NextNFrame.Tooltip = {'Jump to next N frame'};
            app.Bn_NextNFrame.Text = '>>';
            app.Ed_NextN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_NextN.Limits = [1 Inf];
            app.Ed_NextN.ValueDisplayFormat = '%.0f';
            app.Ed_NextN.ValueChangedFcn = createCallbackFcn(app, @Ed_NextNValueChanged, true);
            app.Ed_NextN.Tooltip = {'Jump to next N frame'};
            app.Ed_NextN.Value = 3;
            app.Bn_LastFrame = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_LastFrame.ButtonPushedFcn = createCallbackFcn(app, @Bn_ChangeFrameButtonPushed, true);
            app.Bn_LastFrame.Tooltip = {'Last frame'};
            app.Bn_LastFrame.Text = '>|';
            app.Bn_GoTo = uibutton(app.Pn_FrameControl, 'push');
            app.Bn_GoTo.ButtonPushedFcn = createCallbackFcn(app, @Bn_GoToButtonPushed, true);
            app.Bn_GoTo.Tooltip = {'Jump to the Nth frame'};
            app.Bn_GoTo.Text = 'Go to';
            app.Ed_GoToN = uieditfield(app.Pn_FrameControl, 'numeric');
            app.Ed_GoToN.Limits = [1 Inf];
            app.Ed_GoToN.ValueDisplayFormat = '%d';
            app.Ed_GoToN.ValueChangedFcn = createCallbackFcn(app, @Ed_GoToNValueChanged, true);
            app.Ed_GoToN.Tooltip = {'Jump to the Nth frame'};
            app.Ed_GoToN.Value = 1;
            app.Bn_NextFile = uibutton(app.UIFigure, 'push');
            app.Bn_NextFile.ButtonPushedFcn = createCallbackFcn(app, @Bn_BrowseFileButtonPushed, true);
            app.Bn_NextFile.Tooltip = {'Next File'};
            app.Bn_NextFile.Text = 'File >';
            app.Ed_CursorLoc = uieditfield(app.UIFigure, 'text');
            app.Ed_CursorLoc.Editable = 'off';
            app.Ed_CursorLoc.Enable = 'off';
            app.Ed_CursorLoc.Value = '0,0';
            app.Msg1 = uitextarea(app.UIFigure);
            app.Msg1.Editable = 'off';
            app.ViewSelectAllAudioButton = uibutton(app.UIFigure, 'push');
            app.ViewSelectAllAudioButton.ButtonPushedFcn = createCallbackFcn(app, @Mn_ViewAllMenuSelected, true);
            app.ViewSelectAllAudioButton.Tooltip = {'(Ctrl+A) Zoom out to the whole audio duration'};
            app.ViewSelectAllAudioButton.Text = 'View/Select All Audio';
            app.Bn_PlaySelected = uibutton(app.UIFigure, 'push');
            app.Bn_PlaySelected.ButtonPushedFcn = createCallbackFcn(app, @Mn_PlaySelectedAudioMenuSelected, true);
            app.Bn_PlaySelected.Tooltip = {'(Ctrl+P) Play selected audio'};
            app.Bn_PlaySelected.Text = 'Play selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepEdge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end